classdef DecodingSumAVGsTrialsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        CloseButton  matlab.ui.control.Button
        UITable      matlab.ui.control.Table
    end


    properties (Access = public)
        ALLBEST % Description
        FinishButton
        ChannelsSelected
        decoding_times_index % Description
        BEST_working % Description
        output % Description
        ALLBEST_reset
        indexBEST
        subfoldmethods % Description
    end

    properties (Access = private)
        labeldata % Description
        resample_limit % Description
        epoch_window % Description
        def % Description
        cbesti
        Decode_every_Npoint % Description
        validfloor % Description
        trialsbymethod % Description
    end

    methods (Access = private)


        function setall(app)

            ALLBEST = app.ALLBEST;
            %load defaults
            defs = app.def;
            nBlock = defs{1};
            equalize_trials = defs{2};
            floorvalue = defs{3};
            decodeClasses = defs{4};
            if length(ALLBEST)==1
                BEST1 = ALLBEST;
            else
                BEST1 = ALLBEST(1);
            end
            app.BEST_working = BEST1; %indexed whenever timing is changed
            if length(ALLBEST) == 1
                %must convert to string if only 1 BEST file
                filename = convertCharsToStrings(BEST1.bestname);
            else
                filename = {ALLBEST.bestname};
            end


            %Labels & Number Trials Per Bin (per file)
            labeldata = {};
            rowInd = 0;
            %rowcolors = zeros(1, numel(ALLBEST)*nbin);
            S1 = uistyle('BackgroundColor', 'white');
            S2 = uistyle('BackgroundColor', [0.8 0.8 0.8]); %light grey
            S3 = uistyle;
            S3.HorizontalAlignment = 'left';

            for f = 1:numel(ALLBEST)
                if f == 1
                    rowcolor  = 0;
                    colswitch = 0;
                end
                nbin=  ALLBEST(f).nbin;
                for b = 1:nbin
                    rowInd = rowInd +1;
                    labeldata(rowInd,1) = {filename{f}};
                    labeldata(rowInd,2) = num2cell(b);
                    labeldata(rowInd,3) = ALLBEST(f).bindesc(b);
                    labeldata(rowInd,4) = num2cell(ALLBEST(f).n_trials_per_bin(b));
                    % N_trial_per_bin_per_bock analysis
                    nPerBinBlock = floor(ALLBEST(f).n_trials_per_bin/nBlock); %subject's n_trial_per_bin
                    labeldata(rowInd,6) = num2cell(nPerBinBlock(b));
                    labeldata(rowInd,5) = num2cell(nBlock);
                    %hightlight per file
                    app.UITable.Data = labeldata;

                    if rowcolor == 0
                        addStyle(app.UITable,S1,'row', rowInd);
                    else
                        addStyle(app.UITable,S2,'row', rowInd);
                    end
                    if b == nbin
                        if colswitch == 0
                            rowcolor = 1;
                            colswitch = 1;
                        else
                            rowcolor = 0;
                            colswitch = 0;
                        end
                    end
                end
            end
            % styling gui: left align "trials" columns
            addStyle(app.UITable,S3,'column',[1 2 3 4 5 6]);
            %unbold table
            S4 = uistyle('FontWeight','normal');
            addStyle(app.UITable,S4);

            %save in app (always configued first with 5 columns!)
            app.labeldata = labeldata;

            %set classes field
            if equalize_trials == 4 %across Bins
                AcrossBinsCheckBoxValueChanged(app);
            elseif equalize_trials == 5%across BESTsets
                if numel(app.indexBEST) == 1
                    AcrossBinsCheckBoxValueChanged(app);
                else
                    AcrossBESTsetsCheckBoxValueChanged(app);
                end
            elseif equalize_trials == 6%%common Floor
                FloorValueEditFieldValueChanged(app);
            elseif equalize_trials == 1 %%Equal Trials, Max AVGs across classes
                %do not equalize

                resetequatedtrials(app);
            elseif  equalize_trials == 2 %%Equal Trials, Max AVGs across bestes
                if numel(app.indexBEST) == 1
                    resetTrials(app);
                else
                    acrossbestEqualMax(app);
                end
            elseif  equalize_trials == 3 %%Equal Trials, Max AVGs Floor
                floorEqualMax(app);
            else %%Max Trials, Euqal AVGs
                resetTrials(app);
            end
            redrawClasses(app, decodeClasses);
            return
        end


        function setoff(app,issue)
            %in the case the user loads incompatilbe BESTsets

            if issue == 1
                % currently loaded BESTsets do not agree with each other in
                % in terms of bins and/or channels
                msgboxText = {'Welcome to the ERPLAB decoding GUI',
                    'ERPLAB detected that the currently loaded BESTsets as specified in the BESTset menu',
                    'do not contain the same number of bins and/or channels, or do not contain at least two classes/bins.',
                    ' ',
                    ' ',
                    'Please use BESTsets that match in terms of bins and channels!',
                    'You can:',
                    '1: Select the indicies of the BESTsets that match in terms of bins and channels in the "From BESTset Menu" option',
                    '2: Exit this GUI, load BESTsets into the BESTset menu, and restart GUI.'};

            elseif issue == 2
                % Chosen BESTsets do not agree with each other in terms of
                % bins and/or channels.

                if isempty(app.ALLBEST_reset)

                    msgboxText = {'Welcome to the ERPLAB decoding GUI',
                        'ERPLAB detected that the selected BESTsets  ',
                        'do not contain the same number of bins and/or channels, or do not contain at least two classes/bins.',
                        ' ',
                        'Please select BESTsets that match in terms of bins and channels!',
                        'You can: ',
                        '1: Exit this GUI, load BESTsets into the BESTset menu, and restart GUI.'};
                else
                    msgboxText = {'Welcome to the ERPLAB decoding GUI',
                        'ERPLAB detected that the selected BESTsets  ',
                        'do not contain the same number of bins and/or channels, or do not contain at least two classes/bins.',
                        ' ',
                        'Please select BESTsets that match in terms of bins and channels!',
                        'You can: ',
                        '1: Input the indicies of the BESTsets that mach in terms of bins/channels into "From BESTset Menu" option in GUI.',
                        '2: Exit this GUI, load BESTsets into the BESTset menu, and restart GUI.'};
                end


            elseif issue == 3
                % No currently loaded BESTsets

                msgboxText = {'Welcome to the ERPLAB decoding GUI',
                    'ERPLAB did not detect any BESTsets',
                    'Please select BESTsets that match in terms of bins and channels!',
                    ' ',
                    ' ',
                    'You can: ',
                    '1: Exit this GUI, load BESTsets into the BESTset menu, and restart GUI.'};

            end

            app.UITable.Data =msgboxText;
            S1 = uistyle('BackgroundColor', 'white');
            S2 = uistyle('FontWeight','bold');
            addStyle(app.UITable,S2);
            % set(app.LoadBESTWizard,'Enable','on');

            app.ALLBEST= [];
            app.indexBEST = 0;
            addStyle(app.UITable,S1); %add to table
            addStyle(app.UITable,S2);

        end

        function resetTrials(app)
            ALLBEST = app.ALLBEST;
            nbins = ALLBEST.nbin;
            nsubs = numel(ALLBEST);
            nbin = ALLBEST(1).nbin; %the same across all files
            trialsbymethod= app.def{5};
            nBlock = app.def{1};

            %grab original data
            tmpdata = app.labeldata;
            S3 = uistyle;
            S3.FontColor = 'black';
            S3.FontWeight = 'normal';
            S4 = uistyle;
            S4.FontColor = 'red';
            S4.FontWeight = 'bold';
            redcells = [];
            blackcells = [];
            nblockcells = [];
            ntrialscells = [];
            decodeTrials = app.def{4};

            if trialsbymethod == 1 || trialsbymethod == 2 %svm or LDA
                rowInd = 0;
                nPerBin = app.labeldata(:,4);
                for f = 1:numel(ALLBEST)
                    for b = 1:nbin
                        rowInd = rowInd +1;
                        % N_trial_per_bin_per_bock analysis
                        if ismember(b,decodeTrials)
                            nPerBinBlock = floor(ALLBEST(f).n_trials_per_bin/nBlock); %subject's n_trial_per_bin
                            nPerBin{rowInd} = (nPerBinBlock(b));
                            blackcells(rowInd) = rowInd;
                            nblockcells{rowInd} = nBlock;
                            ntrialscells{rowInd} = ALLBEST(f).n_trials_per_bin(b);
                        else
                            nPerBin{rowInd} = 'NOT USED';
                            redcells(rowInd) = rowInd;
                            nblockcells{rowInd} = 'NOT USED';
                            ntrialscells{rowInd} = 'NOT USED';
                        end
                    end
                end
                tmpdata(:,4) =  ntrialscells;
                tmpdata(:,6) = nPerBin;
                tmpdata(:,5) = nblockcells;
            elseif trialsbymethod == 3 %crossnobis
                %crossnobis only cares about N(trials)
                rowInd = 0;
                nPerBin = app.labeldata(:,4);
                for f = 1:numel(ALLBEST)
                    for b = 1:nbin
                        rowInd = rowInd +1;
                        if ismember(b,decodeTrials)
                            nPerBinBlock = ALLBEST(f).n_trials_per_bin; %subject's n_trial_per_bin
                            nPerBin{rowInd} = nPerBinBlock(b);
                            blackcells(rowInd) = rowInd;
                        else
                            nPerBin{rowInd} = 'NOT USED';
                            redcells(rowInd) = rowInd;
                        end
                    end
                end
                tmpdata(:,4) = nPerBin;
                tmpdata(:,6) = [];
                tmpdata(:,5) = [];
            end

            %redraw table
            app.UITable.Data = tmpdata;
            redcells2 = redcells(redcells~=0)';
            blackcells2 = blackcells(blackcells~=0)';
            if ~isempty(redcells2)
                if trialsbymethod == 1 || trialsbymethod == 2
                    redcells2(:,2) = 5;
                    reblackcells = redcells2;
                    reblackcells(:,2) = 4;
                    addStyle(app.UITable,S3,'cell', [reblackcells]);
                    addStyle(app.UITable,S4,'cell', [redcells2]);
                    redcells2(:,2) = 6;
                    addStyle(app.UITable,S4,'cell', [redcells2]);
                elseif trialsbymethod == 3
                    redcells2(:,2) = 4;
                    addStyle(app.UITable,S4,'cell', [redcells2]);
                end
            end

            if ~isempty(blackcells2)
                if trialsbymethod == 1 || trialsbymethod == 2
                    blackcells2(:,2) = 5;
                    addStyle(app.UITable,S3,'cell', [blackcells2]);
                    blackcells2(:,2) = 6;
                    addStyle(app.UITable,S3,'cell', [blackcells2]);
                elseif trialsbymethod == 3
                    blackcells2(:,2) = 4;
                    addStyle(app.UITable,S3,'cell', [blackcells2]);
                end
            end
        end


        function redrawClasses(app,decodeClasses)
            ALLBEST = app.ALLBEST;
            nbin = ALLBEST(1).nbin; %the original class amount

            if numel(ALLBEST) == 1
                %must convert to string if only 1 BEST file
                filename = convertCharsToStrings(ALLBEST.bestname);
            else
                filename = {ALLBEST.bestname};
            end

            nBlock = app.def{1};
            trialsbymethod= app.def{5};
            %Labels & Number Trials Per Bin (per file)
            % labeldata = app.UITable.Data;
            rowInd = 0;
            S3 = uistyle;
            if numel(decodeClasses) == nbin
                S3.FontColor = 'black';
                S3.FontWeight = 'normal';
            else
                S3.FontColor = 'black';
                S3.FontWeight = 'bold';
            end
            S4 = uistyle;
            S4.FontColor = 'red';
            S4.FontWeight = 'bold';

            for f = 1:numel(ALLBEST)
                for b = 1:nbin
                    rowInd = rowInd +1;
                    if ismember(b,decodeClasses)
                        useClass = 1;
                        app.labeldata(rowInd,2) = num2cell(b);
                    else
                        useClass = 0;
                        app.labeldata(rowInd,2) = {'NOT USED'};
                    end

                    if trialsbymethod==3
                        tmpdata = app.labeldata;
                        tmpdata(:,[6 5]) = [];
                        app.UITable.Data = tmpdata;
                    else
                        app.UITable.Data(:,1:3) = app.labeldata(:,1:3);
                    end
                    if useClass == 1
                        addStyle(app.UITable,S3,'cell', [rowInd,2]);
                    else
                        addStyle(app.UITable,S4,'cell', [rowInd,2]);
                    end

                end
            end

            %app.labeldata = app.UITable.Data;

            % update trials equalization parameters based on new classes
            event = [];
            % EqualizeTrialsCheckBoxValueChanged(app,event);

            %equalize across bins across files
            S3 = uistyle;
            S3.FontColor = 'black';
            S3.FontWeight = 'normal';

            S4 = uistyle;
            S4.FontColor = 'red';
            S4.FontWeight = 'bold';
            redcells = [];
            blackcells = [];
            tmpdata = app.UITable.Data;
            if trialsbymethod == 1 || trialsbymethod == 2 % svm
                for jjj = 1:size(tmpdata,1)
                    if ischar(tmpdata{jjj,2}) &&  strcmpi(tmpdata{jjj,2},'NOT USED')
                        tmpdata{jjj,5} = 'NOT USED';
                        redcells(jjj) = jjj;
                    else
                        blackcells(jjj) = jjj;
                    end
                end
            elseif trialsbymethod == 3 % crossnobis
                for jjj = 1:size(tmpdata,1)
                    if ischar(tmpdata{jjj,2}) &&  strcmpi(tmpdata{jjj,2},'NOT USED')
                        tmpdata{jjj,4} = 'NOT USED';
                        redcells(jjj) = jjj;
                    else
                        blackcells(jjj) = jjj;
                    end
                end
            end

            %redraw table
            app.UITable.Data = tmpdata;
            app.validfloor = 1;
            %color
            redcells2 = redcells(redcells~=0)';
            blackcells2 = blackcells(blackcells~=0)';
            if ~isempty(redcells2)
                if trialsbymethod == 1 || trialsbymethod == 2
                    redcells2(:,2) = 5;
                    reblackcells = redcells2;
                    reblackcells(:,2) = 4;
                    addStyle(app.UITable,S3,'cell', [reblackcells]);
                    addStyle(app.UITable,S4,'cell', [redcells2]);
                    redcells2(:,2) = 6;
                    addStyle(app.UITable,S4,'cell', [redcells2]);
                elseif trialsbymethod == 3
                    redcells2(:,2) = 4;
                    addStyle(app.UITable,S4,'cell', [redcells2]);
                end
            end

            if ~isempty(blackcells2)
                if trialsbymethod == 1 || trialsbymethod == 2
                    blackcells2(:,2) = 5;
                    addStyle(app.UITable,S3,'cell', [blackcells2]);
                    blackcells2(:,2) = 6;
                    addStyle(app.UITable,S3,'cell', [blackcells2]);
                elseif trialsbymethod == 3
                    blackcells2(:,2) = 4;
                    addStyle(app.UITable,S3,'cell', [blackcells2]);
                end
            end
        end


        function  resetequatedtrials(app) %%GH August 2025
            %reset trials for Equated-trialsresetequatedtrials(app);
            %redo original N(per ERP) column
            ALLBEST = app.ALLBEST;
            nbins = ALLBEST.nbin;
            nsubs = numel(ALLBEST);

            nbin = ALLBEST(1).nbin; %the same across all files
            nBlock = app.def{1};
            %grab original data
            tmpdata = app.labeldata;
            S3 = uistyle;
            S3.FontColor = 'black';
            S3.FontWeight = 'normal';

            S4 = uistyle;
            S4.FontColor = 'red';
            S4.FontWeight = 'bold';
            redcells = [];
            blackcells = [];
            nblockCells = [];
            ntrialscell = [];
            decodeTrials = app.def{4};

            % if app.trialsbymethod == 1 || app.trialsbymethod == 2 %svm or LDA
            rowInd = 0;
            nPerBin = app.labeldata(:,4);
            for f = 1:numel(ALLBEST)
                for b = 1:nbin
                    rowInd = rowInd +1;
                    if ~isempty(decodeTrials)
                        n_trials_per_bin=  ALLBEST(f).n_trials_per_bin;
                        n_trials_per_bin_used = n_trials_per_bin(decodeTrials);
                        n_trials_subfold = floor(min(n_trials_per_bin_used(:))/nBlock);
                    end
                    if ismember(b,decodeTrials)
                        % nPerBinBlock = n_trials_subfold; %subject's n_trial_per_bin
                        nPerBin{rowInd} = n_trials_subfold;%%GH 2025 August
                        blackcells(rowInd) = rowInd;
                        nblockCells{rowInd} = floor(n_trials_per_bin(b)/n_trials_subfold);
                        ntrialscell{rowInd} = n_trials_per_bin(b);
                    else
                        nPerBin{rowInd} = 'NOT USED';
                        redcells(rowInd) = rowInd;
                        nblockCells{rowInd} = 'NOT USED';
                        ntrialscell{rowInd} = 'NOT USED';
                    end
                end

            end
            tmpdata(:,4) =(ntrialscell);
            tmpdata(:,6) = nPerBin;
            tmpdata(:,5) = nblockCells;


            %redraw table
            trialsbymethod = app.def{5};
            app.UITable.Data = tmpdata;

            redcells2 = redcells(redcells~=0)';
            blackcells2 = blackcells(blackcells~=0)';
            if ~isempty(redcells2)
                if trialsbymethod == 1 || trialsbymethod == 2
                    redcells2(:,2) = 5;
                    reblackcells = redcells2;
                    reblackcells(:,2) = 4;
                    addStyle(app.UITable,S3,'cell', [reblackcells]);
                    addStyle(app.UITable,S4,'cell', [redcells2]);
                    redcells2(:,2) = 6;
                    addStyle(app.UITable,S4,'cell', [redcells2]);
                elseif trialsbymethod == 3
                    redcells2(:,2) = 4;
                    addStyle(app.UITable,S4,'cell', [redcells2]);
                end
            end

            if ~isempty(blackcells2)
                if trialsbymethod == 1 || trialsbymethod == 2
                    blackcells2(:,2) = 5;
                    addStyle(app.UITable,S3,'cell', [blackcells2]);
                    blackcells2(:,2) = 6;
                    addStyle(app.UITable,S3,'cell', [blackcells2]);
                elseif trialsbymethod == 3
                    blackcells2(:,2) = 4;
                    addStyle(app.UITable,S3,'cell', [blackcells2]);
                end
            end
        end


        function  FloorValueEditFieldValueChanged(app)

            ALLBEST = app.ALLBEST;
            nbins = ALLBEST.nbin;
            nsubs = numel(ALLBEST);
            nPerBin = [app.labeldata{:,4}];
            nBlocks = app.def{1};
            trialsbymethods = app.def{5};
            decodeTrials = app.def{4};
            floorvalue = app.def{3};

            if ~isempty(floorvalue)

                if trialsbymethods==1 || trialsbymethods==2
                    nPerBin = [app.labeldata{:,4}]; %obtain original values
                    count_bin = 0;
                    for s = 1:nsubs
                        subj_ntrials = ALLBEST(s).n_trials_per_bin; %all trials per subject
                        decodeTrials = app.def{4};
                        minCnt = min(subj_ntrials(decodeTrials));

                        % max # of trials such that # of trials for all bins ...
                        % can be equated within each block
                        nPerBinBlock(s) = floor(minCnt/nBlocks);
                        for binnums = 1:ALLBEST(s).nbin
                            count_bin = count_bin+1;
                            ntrialscells{count_bin} = ALLBEST(s).n_trials_per_bin(binnums);
                        end
                    end
                    value_check = min(nPerBinBlock);

                else
                    %crossnobis check

                    for s = 1:nsubs
                        subj_ntrials = ALLBEST(s).n_trials_per_bin; %all trials per subject
                        decodeTrials = app.def{4};
                        nPerBinBlock(s) = min(subj_ntrials(decodeTrials));
                    end
                    value_check = min(nPerBinBlock);

                end

            else
                if trialsbymethods==1
                    value_check = floor(min(nPerBin)/nBlocks);

                else
                    %crossnobis check
                    value_check = min(nPerBin) ;
                end

            end

            if floorvalue > value_check
                msgboxTest = sprintf(['You selected an invalid floor %i. The value exceeds the max the number of trials within cross-validation blocks (SVM) ' ...
                    'or the max number of trials in any class (Crossnobis)'],value);
                title = 'ERPLAB Studio: Summary of AVGs and Trials';
                errorfound(msgboxTest, title);
                app.validfloor = 0;
                return
            end


            if floorvalue < 1
                msgboxTest = sprintf('You selected an invalid floor %i. You cannot go less than 1 trial',value);
                title = 'ERPLAB Studio: Summary of AVGs and Trials';
                errorfound(msgboxTest, title);
                app.validfloor = 0;
                return

            end

            %copy original data with 5 columns
            tmpdata = app.labeldata;

            %apply floor to data
            indx = 0 ;
            for s = 1:nsubs
                for b = 1:nbins
                    indx = indx +1;
                    nPerBin(indx) = floorvalue ;
                end
            end

            %

            %equalize across bins across files
            S3 = uistyle;
            S3.FontColor = 'black';
            S3.FontWeight = 'normal';


            S4 = uistyle;
            S4.FontColor = 'red';
            S4.FontWeight = 'bold';
            redcells = [];
            blackcells = [];

            if trialsbymethods == 1 || trialsbymethods == 2 % svm
                %replace N(ERP) column
                tmpdata(:,6) = num2cell(nPerBin);
                for jjj = 1:size(tmpdata,1)
                    if ischar(tmpdata{jjj,2}) &&  strcmpi(tmpdata{jjj,2},'NOT USED')
                        tmpdata{jjj,6} = 'NOT USED';
                        tmpdata{jjj,5} = 'NOT USED';
                        redcells(jjj) = jjj;
                    else
                        blackcells(jjj) = jjj;
                    end
                end
                ntrialscells = [];
                count_bin = 0;
                for s = 1:nsubs
                    subj_ntrials = ALLBEST(s).n_trials_per_bin; %all trials per subject

                    minCnt = min(subj_ntrials(decodeTrials));

                    % max # of trials such that # of trials for all bins ...
                    % can be equated within each block
                    nPerBinBlock(s) = floor(minCnt/nBlocks);
                    for binnums = 1:ALLBEST(s).nbin
                        count_bin = count_bin+1;
                        ntrialscells{count_bin} = ALLBEST(s).n_trials_per_bin(binnums);
                    end
                end
                tmpdata(:,4) = (ntrialscells);
            elseif trialsbymethods == 3 % crossnobis
                %replace N(trial) column
                tmpdata(:,4) = num2cell(nPerBin);
                %delete N(ERP)column
                tmpdata(:,6) = [];
                tmpdata(:,5) = [];
                for jjj = 1:size(tmpdata,1)
                    if ischar(tmpdata{jjj,2}) &&  strcmpi(tmpdata{jjj,2},'NOT USED')
                        tmpdata{jjj,4} = 'NOT USED';
                        redcells(jjj) = jjj;
                    else
                        blackcells(jjj) = jjj;

                    end

                end

            end

            %redraw table
            app.UITable.Data = tmpdata;
            app.validfloor = 1;
            %color
            redcells2 = redcells(redcells~=0)';
            blackcells2 = blackcells(blackcells~=0)';
            if ~isempty(redcells2)
                if trialsbymethods == 1 || trialsbymethods == 2
                    redcells2(:,2) = 5;
                    reblackcells = redcells2;
                    reblackcells(:,2) = 4;
                    addStyle(app.UITable,S3,'cell', [reblackcells]);
                    addStyle(app.UITable,S4,'cell', [redcells2]);
                    redcells2(:,2) = 6;
                    addStyle(app.UITable,S4,'cell', [redcells2]);
                elseif trialsbymethods == 3
                    redcells2(:,2) = 4;
                    addStyle(app.UITable,S4,'cell', [redcells2]);
                end
            end

            if ~isempty(blackcells2)
                if trialsbymethods== 1 || trialsbymethods == 2
                    blackcells2(:,2) = 5;
                    addStyle(app.UITable,S3,'cell', [blackcells2]);
                    blackcells2(:,2) = 6;
                    addStyle(app.UITable,S3,'cell', [blackcells2]);
                elseif trialsbymethods == 3
                    blackcells2(:,2) = 4;
                    addStyle(app.UITable,S3,'cell', [blackcells2]);
                end
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%------across bestsets for equal trials,max AVGs GH Dec. 2025------
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function acrossbestEqualMax(app)
            %reset trials for Equated-trialsresetequatedtrials(app);
            %redo original N(per ERP) column
            ALLBEST = app.ALLBEST;
            nbins = ALLBEST.nbin;
            nsubs = numel(ALLBEST);

            nbin = ALLBEST(1).nbin; %the same across all files
            nBlock = app.def{1};
            trialsbymethods = app.def{5};
            floorvalue = app.def{3};
            %grab original data
            tmpdata = app.labeldata;


            S3 = uistyle;
            S3.FontColor = 'black';
            S3.FontWeight = 'normal';


            S4 = uistyle;
            S4.FontColor = 'red';
            S4.FontWeight = 'bold';
            redcells = [];
            blackcells = [];
            nblockCells = [];
            ntrialscell = [];
            decodeClasses = app.def{4};
            if isempty(decodeClasses) ||  any(decodeClasses(:)>nbins)%%check the defined classes that will be used for further decoding
                decodeClasses = [1:nbins];
            end
            %%determine the minimal number of trials for each class across
            %%bestsets
            Classes_acrossbest = [];
            for f = 1:numel(ALLBEST)
                n_trials_per_bin=  ALLBEST(f).n_trials_per_bin;
                n_trials_per_bin_used = n_trials_per_bin(decodeClasses);
                Classes_acrossbest = [Classes_acrossbest;n_trials_per_bin_used];
            end
            Classes_acrossbest_min = min(Classes_acrossbest,[],1);


            % if app.trialsbymethod == 1 || app.trialsbymethod == 2 %svm or LDA
            rowInd = 0;
            nPerBin = app.labeldata(:,4);
            for f = 1:numel(ALLBEST)
                count_class_used = 0;
                for b = 1:nbin

                    rowInd = rowInd +1;
                    % if ~isempty(decodeClasses)
                    n_trials_per_bin=  ALLBEST(f).n_trials_per_bin;
                    % n_trials_per_bin_used = n_trials_per_bin(decodeClasses);
                    n_trials_subfold = floor(min(Classes_acrossbest_min(:))/nBlock);
                    % N_trial_per_bin_per_bock analysis
                    if ismember(b,decodeClasses)
                        count_class_used = count_class_used+1;
                        % nPerBinBlock = n_trials_subfold; %subject's n_trial_per_bin
                        nPerBin{rowInd} = n_trials_subfold;%%GH 2025 August
                        blackcells(rowInd) = rowInd;
                        nblockCells{rowInd} = floor(Classes_acrossbest_min(count_class_used)/n_trials_subfold);
                        ntrialscell{rowInd} = n_trials_per_bin(b);
                    else
                        nPerBin{rowInd} = 'NOT USED';
                        redcells(rowInd) = rowInd;
                        nblockCells{rowInd} = 'NOT USED';
                        ntrialscell{rowInd} = 'NOT USED';
                    end
                end
            end
            tmpdata(:,4) =(ntrialscell);
            tmpdata(:,6) = nPerBin;
            tmpdata(:,5) = nblockCells;

            %redraw table
            app.UITable.Data = tmpdata;

            redcells2 = redcells(redcells~=0)';
            blackcells2 = blackcells(blackcells~=0)';
            if ~isempty(redcells2)
                if trialsbymethods == 1 || trialsbymethods == 2
                    redcells2(:,2) = 5;
                    reblackcells = redcells2;
                    reblackcells(:,2) = 4;
                    addStyle(app.UITable,S3,'cell', [reblackcells]);
                    addStyle(app.UITable,S4,'cell', [redcells2]);
                    redcells2(:,2) = 6;
                    addStyle(app.UITable,S4,'cell', [redcells2]);
                elseif app.trialsbymethod == 3
                    redcells2(:,2) = 4;
                    addStyle(app.UITable,S4,'cell', [redcells2]);
                end
            end

            if ~isempty(blackcells2)
                if trialsbymethods == 1 || trialsbymethods == 2
                    blackcells2(:,2) = 5;
                    addStyle(app.UITable,S3,'cell', [blackcells2]);
                    blackcells2(:,2) = 6;
                    addStyle(app.UITable,S3,'cell', [blackcells2]);
                elseif app.trialsbymethod == 3
                    blackcells2(:,2) = 4;
                    addStyle(app.UITable,S3,'cell', [blackcells2]);
                end
            end

        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%--------Floor for equal trials,max AVGs GH Dec. 2025-------------
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function floorEqualMax(app)
            %reset trials for Equated-trialsresetequatedtrials(app);
            %redo original N(per ERP) column
            ALLBEST = app.ALLBEST;
            nbins = ALLBEST.nbin;
            nsubs = numel(ALLBEST);

            nbin = ALLBEST(1).nbin; %the same across all file
            defs = app.def;
            nBlock = defs{1};
            equalize_trials = defs{2};
            Floor_value = defs{3};
            decodeClasses = defs{4};
            if length(ALLBEST)==1
                BEST1 = ALLBEST;
            else
                BEST1 = ALLBEST(1);
            end
            %grab original data
            tmpdata = app.labeldata;


            S3 = uistyle;
            S3.FontColor = 'black';
            S3.FontWeight = 'normal';


            S4 = uistyle;
            S4.FontColor = 'red';
            S4.FontWeight = 'bold';
            redcells = [];
            blackcells = [];
            nblockCells = [];
            ntrialscell = [];

            if isempty(decodeClasses) ||  any(decodeClasses(:)>nbins)%%check the defined classes that will be used for further decoding
                decodeClasses = [1:nbins];
            end
            %%determine the minimal number of trials for each class
            Classes_acrossbest = [];
            for f = 1:numel(ALLBEST)
                n_trials_per_bin=  ALLBEST(f).n_trials_per_bin;
                n_trials_per_bin_used = n_trials_per_bin(decodeClasses);
                Classes_acrossbest = [Classes_acrossbest;n_trials_per_bin_used];
            end
            Classes_acrossbest_min = min(Classes_acrossbest,[],1);

            n_trials_subfold = floor(min(Classes_acrossbest_min(:))/nBlock);


            if isempty(Floor_value) || numel(Floor_value)~=1 || any(Floor_value(:)>n_trials_subfold) || any(Floor_value(:)<1)
                msgboxTest = sprintf(['You selected an invalid floor %i. The value exceeds the max the number of trials within cross-validation blocks (SVM) ' ...
                    'or the max number of trials in any class (Crossnobis)'],Floor_value);
                title = 'ERPLAB: Summary of AVGs and Trials';
                errorfound(msgboxTest, title);

                Floor_value = n_trials_subfold;
                % app.FloorValueEditField.Value = n_trials_subfold;
            end
            % if app.trialsbymethod == 1 || app.trialsbymethod == 2 %svm or LDA
            rowInd = 0;
            nPerBin = app.labeldata(:,4);
            for f = 1:numel(ALLBEST)
                count_class_used = 0;
                for b = 1:nbin

                    rowInd = rowInd +1;
                    % if ~isempty(decodeClasses)
                    n_trials_per_bin=  ALLBEST(f).n_trials_per_bin;
                    % n_trials_per_bin_used = n_trials_per_bin(decodeClasses);

                    % N_trial_per_bin_per_bock analysis
                    if ismember(b,decodeClasses)
                        count_class_used = count_class_used+1;
                        % nPerBinBlock = n_trials_subfold; %subject's n_trial_per_bin
                        nPerBin{rowInd} = Floor_value;%%GH 2025 August
                        blackcells(rowInd) = rowInd;
                        nblockCells{rowInd} = floor(Classes_acrossbest_min(count_class_used)/n_trials_subfold);
                        ntrialscell{rowInd} = n_trials_per_bin(b);
                    else
                        nPerBin{rowInd} = 'NOT USED';
                        redcells(rowInd) = rowInd;
                        nblockCells{rowInd} = 'NOT USED';
                        ntrialscell{rowInd} = 'NOT USED';
                    end
                end

            end
            tmpdata(:,4) =(ntrialscell);
            tmpdata(:,6) = nPerBin;
            tmpdata(:,5) = nblockCells;

            %redraw table
            app.UITable.Data = tmpdata;

            redcells2 = redcells(redcells~=0)';
            blackcells2 = blackcells(blackcells~=0)';
            if ~isempty(redcells2)
                if equalize_trials<7
                    redcells2(:,2) = 5;
                    reblackcells = redcells2;
                    reblackcells(:,2) = 4;
                    addStyle(app.UITable,S3,'cell', [reblackcells]);
                    addStyle(app.UITable,S4,'cell', [redcells2]);
                    redcells2(:,2) = 6;
                    addStyle(app.UITable,S4,'cell', [redcells2]);
                elseif app.trialsbymethod == 3
                    redcells2(:,2) = 4;
                    addStyle(app.UITable,S4,'cell', [redcells2]);
                end
            end

            if ~isempty(blackcells2)
                if equalize_trials<7
                    blackcells2(:,2) = 5;
                    addStyle(app.UITable,S3,'cell', [blackcells2]);
                    blackcells2(:,2) = 6;
                    addStyle(app.UITable,S3,'cell', [blackcells2]);
                elseif app.trialsbymethod == 3
                    blackcells2(:,2) = 4;
                    addStyle(app.UITable,S3,'cell', [blackcells2]);
                end
            end
        end
    end

    methods (Access = public)

        function  AcrossBinsCheckBoxValueChanged(app)

            ALLBEST = app.ALLBEST;
            nbins = ALLBEST.nbin;
            nsubs = numel(ALLBEST);
            tmpdata = app.labeldata;
            S3 = uistyle;
            S3.FontColor = 'black';
            S3.FontWeight = 'normal';
            decodeTrials = app.def{4};
            S4 = uistyle;
            S4.FontColor = 'red';
            S4.FontWeight = 'bold';
            redcells = [];
            blackcells = [];
            nBlocks = app.def{1};
            trialsbymethod = app.def{5};
            if trialsbymethod == 1 || trialsbymethod == 2 %svm
                %equalize across bins within files
                nPerBin = app.labeldata(:,4); %obtain original values
                for s = 1:nsubs
                    subj_ntrials = ALLBEST(s).n_trials_per_bin; %all trials per subject
                    minCnt = min(subj_ntrials(decodeTrials));
                    nPerBinBlock(s) = floor(minCnt/nBlocks);
                end

                %apply nPerBinBlock to nPerBin values
                indx = 0 ;
                for s = 1:nsubs
                    subj_ntrials = ALLBEST(s).n_trials_per_bin; %all trials per subject
                    nbins=  ALLBEST(s).nbin;
                    for b = 1:nbins
                        indx = indx +1;
                        if ismember(b,decodeTrials)
                            nPerBin{indx} = nPerBinBlock(s);
                            ntrailPerbin{indx}  = subj_ntrials(b);
                            blackcells(indx) = indx;
                            nBlockcells{indx} = nBlocks;
                        else
                            nPerBin{indx} = 'NOT USED';
                            redcells(indx) = indx;
                            nBlockcells{indx} = 'NOT USED';
                            ntrailPerbin{indx}  = 'NOT USED';
                        end
                    end
                end
                tmpdata(:,4) = (ntrailPerbin);
                tmpdata(:,6) = (nPerBin);
                tmpdata(:,5) = (nBlockcells);
            elseif trialsbymethod == 3 %crossnobis
                nPerBin = app.labeldata(:,4); %obtain original values
                for s = 1:nsubs
                    subj_ntrials = ALLBEST(s).n_trials_per_bin; %all trials per subject
                    minCnt = min(subj_ntrials(decodeTrials));
                    nPerBinBlock(s) = minCnt;
                end

                %apply nPerBinBlock to nPerBin values
                indx = 0 ;
                for s = 1:nsubs
                    nbins=  ALLBEST(s).nbin;
                    for b = 1:nbins
                        indx = indx +1;

                        if ismember(b,decodeTrials)
                            nPerBin{indx} = nPerBinBlock(s);
                            blackcells(indx) = indx;
                        else
                            nPerBin{indx} = 'NOT USED';
                            redcells(indx) = indx;
                        end
                    end
                end
                tmpdata(:,4) = (nPerBin);
                tmpdata(:,6) = [];
                tmpdata(:,5) = [];
            end

            app.labeldata(:,4) = nPerBin;
            %redraw table
            app.UITable.Data = tmpdata;
            redcells2 = redcells(redcells~=0)';
            blackcells2 = blackcells(blackcells~=0)';
            if ~isempty(redcells2)
                if trialsbymethod == 1
                    redcells2(:,2) = 5;
                    % redcells2(:,3) = 6;
                    reblackcells = redcells2;
                    reblackcells(:,2) = 4;
                    addStyle(app.UITable,S3,'cell', [reblackcells]);
                    addStyle(app.UITable,S4,'cell', [redcells2]);
                    redcells2(:,2) = 6;
                    addStyle(app.UITable,S4,'cell', [redcells2]);
                elseif trialsbymethod == 2
                    redcells2(:,2) = 4;
                    addStyle(app.UITable,S4,'cell', [redcells2]);
                end
            end

            if ~isempty(blackcells2)
                if trialsbymethod == 1
                    blackcells2(:,2) = 5;
                    addStyle(app.UITable,S3,'cell', [blackcells2]);
                    blackcells2(:,2) = 6;
                    addStyle(app.UITable,S3,'cell', [blackcells2]);
                elseif trialsbymethod == 2
                    blackcells2(:,2) = 4;
                    addStyle(app.UITable,S3,'cell', [blackcells2]);
                end
            end
        end

        function  AcrossBESTsetsCheckBoxValueChanged(app)

            ALLBEST = app.ALLBEST;
            nbins = ALLBEST.nbin;
            nsubs = numel(ALLBEST);

            tmpdata = app.labeldata;
            %equalize across bins across files
            S3 = uistyle;
            S3.FontColor = 'black';
            S3.FontWeight = 'normal';


            S4 = uistyle;
            S4.FontColor = 'red';
            S4.FontWeight = 'bold';
            redcells = [];
            blackcells = [];
            nblockcells = [];
            ntrialscells = [];
            trialsbymethod = app.def{5};
            nBlocks = app.def{1};
            decodeTrials = app.def{4};
            if trialsbymethod == 1 || trialsbymethod == 2 % svm
                nPerBin = app.labeldata(:,4); %obtain original values
                for s = 1:nsubs
                    subj_ntrials = ALLBEST(s).n_trials_per_bin; %all trials per subject
                    minCnt = min(subj_ntrials(decodeTrials));
                    nPerBinBlock(s) = floor(minCnt/nBlocks);
                end

                %apply nPerBinBlock to nPerBin values
                indx = 0 ;
                for s = 1:nsubs
                    subj_ntrials = ALLBEST(s).n_trials_per_bin;
                    for b = 1:nbins
                        indx = indx +1;
                        if ismember(b,decodeTrials)
                            nPerBin{indx} = min(nPerBinBlock);
                            blackcells(indx) = indx;
                            nblockcells{indx} = nBlocks;
                            ntrialscells{indx} = subj_ntrials(b);
                        else
                            nPerBin{indx} = 'NOT USED';
                            redcells(indx) = indx;
                            nblockcells{indx} = 'NOT USED';
                            ntrialscells{indx} = 'NOT USED';
                        end

                    end
                end
                %replace N(ERP) column
                tmpdata(:,4) = (ntrialscells);
                tmpdata(:,5) = (nblockcells);
                tmpdata(:,6) = (nPerBin);
            elseif trialsbymethod == 3 % crossnobis
                nPerBin = app.labeldata(:,4); %obtain original values
                for s = 1:nsubs
                    subj_ntrials = ALLBEST(s).n_trials_per_bin; %all trials per subject
                    minCnt = min(subj_ntrials(decodeTrials));
                    nPerBinBlock(s) = minCnt;
                end
                %apply nPerBinBlock to nPerBin values
                indx = 0 ;
                for s = 1:nsubs
                    for b = 1:nbins
                        indx = indx +1;
                        if ismember(b,decodeTrials)
                            nPerBin{indx} = min(nPerBinBlock);
                            blackcells(indx) = indx;
                        else
                            nPerBin{indx} = 'NOT USED';
                            redcells(indx) = indx;
                        end
                    end
                end
                tmpdata(:,4) = (nPerBin);
                tmpdata(:,6) = [];
                tmpdata(:,5) = [];
            end

            %redraw table
            app.UITable.Data = tmpdata;
            %color
            redcells2 = redcells(redcells~=0)';
            blackcells2 = blackcells(blackcells~=0)';
            if ~isempty(redcells2)
                if trialsbymethod == 1 || trialsbymethod == 2
                    redcells2(:,2) = 5;
                    reblackcells = redcells2;
                    reblackcells(:,2) = 4;
                    addStyle(app.UITable,S3,'cell', [reblackcells]);
                    addStyle(app.UITable,S4,'cell', [redcells2]);
                    redcells2(:,2) = 6;
                    addStyle(app.UITable,S4,'cell', [redcells2]);
                elseif trialsbymethod == 3
                    redcells2(:,2) = 4;
                    addStyle(app.UITable,S4,'cell', [redcells2]);
                end
            end
            if ~isempty(blackcells2)
                if trialsbymethod == 1 || trialsbymethod == 2
                    blackcells2(:,2) = 5;
                    addStyle(app.UITable,S3,'cell', [blackcells2]);
                    blackcells2(:,2) = 6;
                    addStyle(app.UITable,S3,'cell', [blackcells2]);
                elseif trialsbymethod == 3
                    blackcells2(:,2) = 4;
                    addStyle(app.UITable,S3,'cell', [blackcells2]);
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)

            %paint GUI
            %painterplabapp(app);
            %inputs
            ALLBEST = varargin{1};
            %filename = (varargin{2}'); %filename
            %filepath = varargin{3}; % list of paths
            def = varargin{2};
            cbesti = varargin{3}';

            %save defaults and currently loaded bestset index
            app.def = def;
            app.cbesti = cbesti;
            app.ALLBEST_reset = ALLBEST; %from GUI input
            app.ALLBEST= ALLBEST; %from GUI input
            if isempty(ALLBEST)
                setoff(app,3);
            else
                checking = checkmultiBEST(ALLBEST);

                if ~checking
                    setoff(app,2); %selected choices do not work
                else
                    setall(app);
                end
            end
            %paint GUI
            painterplabapp(app);
            version = geterplabversion;
            set(app.UIFigure,'Name', ['ERPLAB Studio' version '   -   Summary of AVGs and Trials ']);
            %to close out gui
            ready = 0;
            if ready == 1
                app.FinishButton = 1;
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.FinishButton = 1;
        end

        % Callback function
        function UIFigureCloseRequest(app, event)
            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'BEST File'; 'Class ID'; 'Class/Label'; 'N(trials)'; 'B(ERPs)'; 'N(per ERP)'};
            app.UITable.RowName = {};
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DecodingSumAVGsTrialsGUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end