classdef binselectorGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UITable                     matlab.ui.control.Table
        ChoosewhichbinstodecodeacrossLabel  matlab.ui.control.Label
        CreateBESTsetbestButton     matlab.ui.control.Button
        UITable2                    matlab.ui.control.Table
        Label                       matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        OptionalFrequencyTransformPanel  matlab.ui.container.Panel
        Label_3                     matlab.ui.control.Label
        TFCheckBox                  matlab.ui.control.CheckBox
        LowEdgeFrequencyinPassBandEditFieldLabel  matlab.ui.control.Label
        LowEdgeFrequencyinPassBandEditField  matlab.ui.control.EditField
        HighEdgeFrequencyinPassBandEditFieldLabel  matlab.ui.control.Label
        HighEdgeFrequencyinPassBandEditField  matlab.ui.control.EditField
        CurrentSamplingRateHzLabel  matlab.ui.control.Label
        EpochstoIncludeintoBESTsetButtonGroup  matlab.ui.container.ButtonGroup
        include_allepochs           matlab.ui.control.RadioButton
        exclude_ad                  matlab.ui.control.RadioButton
        include_ad_only             matlab.ui.control.RadioButton
        exclude_be                  matlab.ui.control.CheckBox
        ApplySelectionButton        matlab.ui.control.Button
        ApplyDeselectionButton      matlab.ui.control.Button
        DeselectAllButton           matlab.ui.control.Button
    end

    
    properties (Access = public)
        SelectedCells; % Description
        output; 
        FinishButton; % Description
        LoadedBins; % Description
        FS; % Description
        ChangeFS; % Description
        ApplyBP; % Description
        BP_Freq; % Description
        EEG % Description
        time_limit % Description
        times % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            %inputs 
            ALLEEG = varargin{1}; %EEGstruct
            currset = varargin{2}; %current Set
            binsel = varargin{3}{1}'; % bins (working memory)
            cmk_bp = varargin{3}{2}; %apply bandpass
            BPfreq = varargin{3}{3}; %band pass frequencies
            artcrit = varargin{3}{4}; 
            excludeBoundary = varargin{3}{5}; 
           
            %% Obtain valid epoched eegsets
            %% only one at a time
            app.EEG = ALLEEG(currset); 
            n_eeg = numel(ALLEEG);
            valid_eeg = zeros(n_eeg,1); %index
            valid_eeg_logical = zeros(n_eeg,1); %for GUI             
         
            for i = 1:n_eeg
                    
                if ~isempty(ALLEEG(i).epoch)
                    
                    valid_eeg(i) = i;
                    valid_eeg_logical(i) = 1; 
                    
                else
                    valid_eeg_logical(i) = 0; 
                    
                end
                
            end
            
            
            %add to table component (list ACTIVE ONLY)
            EEGnames = {ALLEEG(currset).setname};
            
            %cnames = table(EEGnames, valid_eeg_logical,bin_check);
            app.UITable2.Data = EEGnames; % OneSet
            
            % Update table component 
            newdata = ALLEEG(currset).EVENTLIST.bdf;
            binlist = {newdata.description}';
            binind = {newdata.namebin}';
            combine_bins = strcat(binind,' ---  ',binlist);
            
            
            %select bins
         %   highlightbins = reshape(binsel,[1 length(binsel)]); %for highlighting
            checked_bins = false(size(combine_bins,1),1);
            if length(binsel) > length(checked_bins) %In case working memory was different
                checked_bins(binsel(1:length(checked_bins))) = true; 
            else
                checked_bins(binsel) = true; %selection based on working memory
            end
            
            app.LoadedBins = 1:(ALLEEG(currset).EVENTLIST.nbin); %all bins


%             try             
%                 % select the currently loaded & valid eegset
%                 highlightbins = reshape(binsel,[1 length(binsel)]);
%                 
%                 checked_bins = false(size(combine_bins,1),1); 
%                 checked_bins(binsel) = true;
% 
%                 set(app.UITable,'Selection',highlightbins); %ONE EEGSET loaded
%                 app.LoadedBins = binsel; 
%                 
%             catch % if working memeory can't find bins, default to first bin only
%                 
%                 checked_bins = false(size(combine_bins,1),1);
%                 checked_bins(:) = true;
% 
%                 set(app.UITable,'Selection',1); %ONE EEGSET loaded
%                 app.LoadedBins = binsel; 
%                 
%             end
            
            
            %create table
            tdata = table(checked_bins, combine_bins); 
       %     tdata(:,2) = table(combine_bins) %bins
            
            app.UITable.Data = tdata;
            set(app.UITable,'SelectionType','row');
            set(app.UITable,'Multiselect','on');
           % set(app.UITable,'Selection',highlightbins); %ONE EEGSET loaded
                
            % set current sampling rate  window
            FS = ALLEEG(currset).srate; 
%             set(app.CurrentSamplingRateHzEditField,'Editable','off');
%             set(app.CurrentSamplingRateHzEditField,'Enable','on');
            time_sample = (1/FS); %in ms
          %  set(app.whichequalsEditField,'Value',time_sample*1000);
%             set(app.whichequalsEditField,'Editable','off');
%             set(app.whichequalsEditField,'Enable','on');
            FS_str = sprintf(['Current Sampling Rate is %i (Hz) which equals' ...
                ' %.4f ms per sample point'], FS, time_sample*1000); 
            set(app.CurrentSamplingRateHzLabel,'Text', FS_str);


            %set frequncy transform
            app.ApplyBP = cmk_bp;
            app.BP_Freq = BPfreq; 
            
            
            set(app.LowEdgeFrequencyinPassBandEditField,'Value', num2str(BPfreq{1}));
            set(app.HighEdgeFrequencyinPassBandEditField,'Value', num2str(BPfreq{2}));
            
            if cmk_bp == 1
                set(app.TFCheckBox,'Value',1); 
                
                set(app.LowEdgeFrequencyinPassBandEditField,'Editable','on');
                set(app.HighEdgeFrequencyinPassBandEditField,'Editable','on');
                set(app.LowEdgeFrequencyinPassBandEditField,'Enable','on');
                set(app.HighEdgeFrequencyinPassBandEditField,'Enable','on');
                
            else
                set(app.TFCheckBox,'Value',0);
                
                set(app.LowEdgeFrequencyinPassBandEditField,'Editable','off');
                set(app.HighEdgeFrequencyinPassBandEditField,'Editable','off');
                set(app.LowEdgeFrequencyinPassBandEditField,'Enable','off');
                set(app.HighEdgeFrequencyinPassBandEditField,'Enable','off');                
            end
            
            %artcrit criteria
            if artcrit == 0
                set(app.include_allepochs,'Value',1); 
                
            elseif artcrit == 1
                set(app.exclude_ad,'Value',1);
                
            elseif artcrit == 2
                set(app.include_ad_only,'Value',1);
                
            end
            
            %exclude boundaries
            
            if excludeBoundary == 1
                set(app.exclude_be,'Value',1);
            else
                set(app.exclude_be,'Value',0); 
            end


             %paint GUI
            painterplabapp(app); 
            
                        %
            % Name & version
            %
            version = geterplabversion;
            set(app.UIFigure,'Name', ['ERPLAB ' version '   -   EXTRACT BESTSET GUI']);


        end

        % Callback function
        function UITableCellEdit(app, event)
            %indices = event.Indices;
            
        end

        % Cell selection callback: UITable, UITable2
        function UITableCellSelection(app, event)

            app.SelectedCells = event.Indices;
    
        end

        % Button pushed function: CreateBESTsetbestButton
        function CreateBESTsetbestButtonPushed(app, event)
            
            % for multiple bins across file feature
            
%             if ~isempty(app.SelectedCells)
%                 binsel_final = app.SelectedCells(:,1);
%                 %app.SelectedBinsEditField.Value = vect2colon(binsel, 'Delimiter', 'off');
%                 
%                 
%             else
%                 binsel_final = app.LoadedBins;
%                 %msgbox('No Bins Selected!', 'Whoops!'); %better to put output error
%                 
%             end


            selections = table2array(app.UITable.Data(:,1)); %selections
            binsel_final = app.LoadedBins(selections);                                      %convert to index values 
            
            if size(binsel_final,1) > 1
                binsel_final = binsel_final'; 
            end
            
            %output resampling 
%             RS_final_ms = app.TPspinner.Value/1000; %steps to resample (ms)
%             fs = app.FS; %original FS
%             samp_ms = 1/fs; %sample in ms 
%             RS_final = RS_final_ms/samp_ms; %resample factor (sample points)
%             %therefore, resample with every RS_final as the steps 
%             change_RS_final = app.ChangeFS;
            
            %output band pass
            bandpass_cmk = app.ApplyBP; 
            bandpass = app.BP_Freq; %cell contains either numerics (apply it) or empty str (don't apply)
            
            if bandpass_cmk == 1
                test_bp = cellfun(@isempty,bandpass); 
                             
                if test_bp(1) == 1
                    msgboxText = 'Cannot leave Low Edge Frequency field empty';
                    title_msg  = 'ERPLAB: pop_binselector() error:';
                    errorfound(msgboxText, title_msg);
                    return
                elseif test_bp(2) == 1
                    msgboxText = 'Cannot leave High Edge Frequency field empty';
                    title_msg  = 'ERPLAB: pop_binselector() error:';
                    errorfound(msgboxText, title_msg);
                    return
                end

            end
            
            artcrite = get(app.EpochstoIncludeintoBESTsetButtonGroup.SelectedObject);
            
            switch artcrite.Text
                case 'Include ALL epochs (ignore artifact detections)'
                    artcrit = 0;
                case 'Exclude epochs marked during artifact detection (highly recommended)'
                    artcrit = 1; 
                case 'Include ONLY epochs marked with artifact detection (be cautious)'
                    artcrit = 2;
            end
            
            exclude_be = app.exclude_be.Value; 

            
            %outputs = {binsel_final,change_RS_final,RS_final, bandpass_cmk, bandpass};
            outputs = {binsel_final, bandpass_cmk, bandpass, artcrit, exclude_be};
            
            app.output = outputs; 
            app.FinishButton = 1;
   
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Callback function
        function PerformEpochReSamplingCheckBoxValueChanged(app, event)
%             value = app.PerformEpochReSamplingCheckBox.Value;
%             time_limit = app.time_limit;
%             
%             if value == 1
%                 display('Resampling selected ')
%                 set(app.SamplingRateNew,'Editable','off');
%                 set(app.SamplingRateNew,'Enable','on'); 
%                 set(app.SamplingRateNew,'Value', app.FS)
%                 set(app.SamplingRateNew,'BackgroundColor',[0.5 0.5 0.5]); 
%                 set(app.TPspinner,'Editable','on');
%                 set(app.TPspinner,'Enable','on');
%                 set(app.TPspinner,'Limits',[time_limit]);
%                 set(app.TPspinner,'Value',time_limit(1)); 
%                 set(app.TPspinner,'Limits',time_limit);
%                 set(app.TPspinner,'Step', time_limit(1)); 
%                 
%             else
%                 display('Resampling not selected ')
%                 set(app.SamplingRateNew,'Editable','off');
%                 set(app.SamplingRateNew,'Enable','off');
%                 set(app.SamplingRateNew,'Value', app.FS);
%                 set(app.SamplingRateNew,'BackgroundColor',[0 0 0]); 
%                 set(app.TPspinner,'Value',time_limit(1)); 
%                 set(app.TPspinner,'Editable','off');
%                 set(app.TPspinner,'Enable','off');
%             end
%             
%             app.ChangeFS = value; 
            
            
        end

        % Callback function
        function SamplingRateNewValueChanged(app, event)
%             value = app.TPspinner.Value; %new resample value 
%             
%             %check value if valid
%             times = app.times;
%             time_limit = app.time_limit;
%             
%             if ~ismember(value,times)
%                 msgboxTest = sprintf('Selecting time-points: must be in units of %i ms. Will round!',time_limit(1));
%                 title = 'ERPLAB: wrong time-units';
%                 errorfound(msgboxTest, title);
%                 
%                 %find closest value rounded down
%                 [~,ind] = min(abs(times-value));
%                 value = times(ind);
%                 set(app.TPspinner,'Value',value);
%                 app.TPspinner.Value = value;
%             end
%             
%             npnts_old = app.EEG.pnts; 
%             fs = app.FS; 
%             samps = 1/fs; %granularity of data sample in ms 
%             epochtime = (samps*npnts_old) * 1000 ; %ms 
%             
%             npnts = epochtime/value; 
%             
%             new_FS = (npnts/epochtime * 1000);
%             
%             %save changes
%             set(app.SamplingRateNew, 'Value', new_FS); 
            
        end

        % Value changed function: TFCheckBox
        function TFCheckBoxValueChanged(app, event)
            value = app.TFCheckBox.Value;
            if value == 1
                set(app.LowEdgeFrequencyinPassBandEditField,'Editable','on');
                set(app.LowEdgeFrequencyinPassBandEditField,'Enable','on');
                set(app.HighEdgeFrequencyinPassBandEditField,'Editable','on');
                set(app.HighEdgeFrequencyinPassBandEditField,'Enable','on');
                
            else
                set(app.LowEdgeFrequencyinPassBandEditField,'Editable','off');
                set(app.LowEdgeFrequencyinPassBandEditField,'Enable','off');
                set(app.HighEdgeFrequencyinPassBandEditField,'Editable','off');
                set(app.HighEdgeFrequencyinPassBandEditField,'Enable','off');
            end
            
            app.ApplyBP = value;
        end

        % Value changed function: 
        % LowEdgeFrequencyinPassBandEditField
        function LowEdgeFrequencyinPassBandEditFieldValueChanged(app, event)
            value = app.LowEdgeFrequencyinPassBandEditField.Value;
            
            low_bp = str2num(value);
            
            if isempty(low_bp)
                msgboxText = 'Cannot leave Low Edge field empty';
                title_msg  = 'ERPLAB: pop_binselector() error:';
                errorfound(msgboxText, title_msg);
                return
            else
                app.BP_Freq{1} = low_bp; 
            end


        end

        % Value changed function: 
        % HighEdgeFrequencyinPassBandEditField
        function HighEdgeFrequencyinPassBandEditFieldValueChanged(app, event)
            value = app.HighEdgeFrequencyinPassBandEditField.Value;
            
            high_bp = str2num(value);
            
            if isempty(high_bp)
                msgboxText = 'Cannot leave High Edge field empty';
                title_msg  = 'ERPLAB: pop_binselector() error:';
                errorfound(msgboxText, title_msg);
                return
            else
                app.BP_Freq{2} = high_bp; 
            end
            
            
        end

        % Callback function
        function SRsliderValueChanged(app, event)
            value = app.SRslider.Value; 
            
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit2(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            if newData == 1
                set(app.UITable,'Selection',indices); %ONE EEGSET loaded
                %app.UITable.Selection 
            else
                set(app.UITable,'Selection',[]); %ONE EEGSET loaded
            end


        end

        % Button pushed function: ApplySelectionButton
        function ApplySelectionButtonPushed(app, event)
                        
            if ~isempty(app.SelectedCells)
                update_cell = unique(app.SelectedCells(:,1)');
                %app.SelectedBinsEditField.Value = vect2colon(binsel, 'Delimiter', 'off');
                
                app.UITable.Data(update_cell,1) = {true}; 
                
            end

        end

        % Button pushed function: ApplyDeselectionButton
        function ApplyDeselectionButtonPushed(app, event)
            
            if ~isempty(app.SelectedCells)
                update_cell = unique(app.SelectedCells(:,1)');
                %app.SelectedBinsEditField.Value = vect2colon(binsel, 'Delimiter', 'off');
                
                app.UITable.Data(update_cell,1) = {false};
                
            end
            
        end

        % Button pushed function: DeselectAllButton
        function DeselectAllButtonPushed(app, event)

               app.UITable.Data(:,1) = {false};


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Bin Selector';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Select'; 'Bin Index --- Bin Description'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit2, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.ChoosewhichbinstodecodeacrossLabel = uilabel(app.UIFigure);
            app.ChoosewhichbinstodecodeacrossLabel.Text = {'Select which bins to include using checkboxes.'; 'If selecting multiple bins, hold down "SHIFT" key, then click "Apply Selection"'};
            app.CreateBESTsetbestButton = uibutton(app.UIFigure, 'push');
            app.CreateBESTsetbestButton.ButtonPushedFcn = createCallbackFcn(app, @CreateBESTsetbestButtonPushed, true);
            app.CreateBESTsetbestButton.Text = 'Create BESTset (.best)';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Loaded EEGset File'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = [false false true];
            app.UITable2.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Create bin-epoched single trial (BEST) files for single trial & multivariate pattern classification.';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Click to create and save bin-epoched single-trial (BEST) data file.';
            app.OptionalFrequencyTransformPanel = uipanel(app.UIFigure);
            app.OptionalFrequencyTransformPanel.Title = 'Optional: Frequency Transform';
            app.Label_3 = uilabel(app.OptionalFrequencyTransformPanel);
            app.Label_3.Text = 'Transform trials into phase-independent frequency-band EEG power ';
            app.TFCheckBox = uicheckbox(app.OptionalFrequencyTransformPanel);
            app.TFCheckBox.ValueChangedFcn = createCallbackFcn(app, @TFCheckBoxValueChanged, true);
            app.TFCheckBox.Text = '';
            app.LowEdgeFrequencyinPassBandEditFieldLabel = uilabel(app.OptionalFrequencyTransformPanel);
            app.LowEdgeFrequencyinPassBandEditFieldLabel.Text = 'Low Edge Frequency in Pass Band';
            app.LowEdgeFrequencyinPassBandEditField = uieditfield(app.OptionalFrequencyTransformPanel, 'text');
            app.LowEdgeFrequencyinPassBandEditField.ValueChangedFcn = createCallbackFcn(app, @LowEdgeFrequencyinPassBandEditFieldValueChanged, true);
            app.HighEdgeFrequencyinPassBandEditFieldLabel = uilabel(app.OptionalFrequencyTransformPanel);
            app.HighEdgeFrequencyinPassBandEditFieldLabel.Text = 'High Edge Frequency in Pass Band';
            app.HighEdgeFrequencyinPassBandEditField = uieditfield(app.OptionalFrequencyTransformPanel, 'text');
            app.HighEdgeFrequencyinPassBandEditField.ValueChangedFcn = createCallbackFcn(app, @HighEdgeFrequencyinPassBandEditFieldValueChanged, true);
            app.CurrentSamplingRateHzLabel = uilabel(app.UIFigure);
            app.CurrentSamplingRateHzLabel.Text = 'Current Sampling Rate (Hz)';
            app.EpochstoIncludeintoBESTsetButtonGroup = uibuttongroup(app.UIFigure);
            app.EpochstoIncludeintoBESTsetButtonGroup.Title = 'Epochs to Include into BESTset';
            app.include_allepochs = uiradiobutton(app.EpochstoIncludeintoBESTsetButtonGroup);
            app.include_allepochs.Text = 'Include ALL epochs (ignore artifact detections)';
            app.include_allepochs.Value = true;
            app.exclude_ad = uiradiobutton(app.EpochstoIncludeintoBESTsetButtonGroup);
            app.exclude_ad.Text = 'Exclude epochs marked during artifact detection (highly recommended)';
            app.include_ad_only = uiradiobutton(app.EpochstoIncludeintoBESTsetButtonGroup);
            app.include_ad_only.Text = 'Include ONLY epochs marked with artifact detection (be cautious)';
            app.exclude_be = uicheckbox(app.EpochstoIncludeintoBESTsetButtonGroup);
            app.exclude_be.Text = 'Exclude epcohs that contain either "boundary" or invalid events (highly recommended)';
            app.ApplySelectionButton = uibutton(app.UIFigure, 'push');
            app.ApplySelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ApplySelectionButtonPushed, true);
            app.ApplySelectionButton.Text = 'Apply Selection';
            app.ApplyDeselectionButton = uibutton(app.UIFigure, 'push');
            app.ApplyDeselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyDeselectionButtonPushed, true);
            app.ApplyDeselectionButton.Text = 'Apply Deselection';
            app.DeselectAllButton = uibutton(app.UIFigure, 'push');
            app.DeselectAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllButtonPushed, true);
            app.DeselectAllButton.Text = 'Deselect All';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = binselectorGUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end