classdef estudio_epoch2contn_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TextArea                   matlab.ui.control.TextArea
        YesButton                  matlab.ui.control.Button
        NoButton                   matlab.ui.control.Button
        RestoreeventcodesCheckBox  matlab.ui.control.CheckBox
    end


    properties (Access = public)
        output % Description
        Finishbutton % Description
    end

    methods (Access = private)


    end

    methods (Access = public)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            painterplabapp(app);
            setfonterplab(app);
            % Name & version
            %
            version = geterplabversion;
            set(app.UIFigure,'Name', ['ERPLAB ' version '   -   EStudio convert epoched EEG to Continuous EEG GUI']);
            try
                resetparas = varargin{1};
            catch
                resetparas = [1];
            end
            if isempty(resetparas) || numel(resetparas)~=1
                resetparas = [1];
            end
            
            app.RestoreeventcodesCheckBox.Value = resetparas(1);
            app.TextArea.Value = ['This tool converts an epoched dataset into a continuous one by concatenating all its epochs using boundary events.'...
                    'Would you like to proceed?'];
            
        end

        % Value changed function: RestoreeventcodesCheckBox
        function clear_allerp(app, event)
            value = app.RestoreeventcodesCheckBox.Value;

        end

        % Button pushed function: NoButton
        function cancel_button(app, event)
            app.output =[];
            app.Finishbutton = 1;
        end

        % Button pushed function: YesButton
        function button_continue(app, event)

            resetparas(1)=app.RestoreeventcodesCheckBox.Value;
            app.output =resetparas;
            app.Finishbutton = 1;
        end

        % Value changed function: TextArea
        function text_messag(app, event)
            value = app.TextArea.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RestoreeventcodesCheckBox = uicheckbox(app.UIFigure);
            app.RestoreeventcodesCheckBox.ValueChangedFcn = createCallbackFcn(app, @clear_allerp, true);
            app.RestoreeventcodesCheckBox.Text = 'Restore event codes';
            app.NoButton = uibutton(app.UIFigure, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @cancel_button, true);
            app.NoButton.Text = 'No';
            app.YesButton = uibutton(app.UIFigure, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @button_continue, true);
            app.YesButton.Text = 'Yes';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @text_messag, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = estudio_epoch2contn_gui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end