classdef mvpc2textGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PrecisionPanel                matlab.ui.container.Panel
        DecimalDropDown               matlab.ui.control.DropDown
        DecimalDropDownLabel          matlab.ui.control.Label
        SaveOutputasEditFieldLabel_3  matlab.ui.control.Label
        MVPCvaluetoexportDropDown     matlab.ui.control.DropDown
        SaveOutputasEditFieldLabel_2  matlab.ui.control.Label
        ExportButton                  matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        TransposeDataButtonGroup      matlab.ui.container.ButtonGroup
        rowspoints                    matlab.ui.control.RadioButton
        rowsvalue                     matlab.ui.control.RadioButton
        TimePanel                     matlab.ui.container.Panel
        TimeunitsDropDown             matlab.ui.control.DropDown
        TimeunitsDropDownLabel        matlab.ui.control.Label
        IncludetimevaluesCheckBox     matlab.ui.control.CheckBox
        Panel                         matlab.ui.container.Panel
        BrowseButton                  matlab.ui.control.Button
        SaveOutputasEditField         matlab.ui.control.EditField
        SaveOutputasEditFieldLabel    matlab.ui.control.Label
    end


    properties (Access = public)
        FinishButton % Description
        output % Description
    end

    properties (Access = private)
        def
        ALLMVPC % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            %paint GUI
            painterplabapp(app);

            %
            % Name & version
            %
            version = geterplabversion;
            set(app.UIFigure,'Name', ['ERPLAB ' version '   -   EXPORT MVPC GUI']);

            %inputs
            try ALLMVPC = varargin{1}; catch ALLMVPC = []; end
            %filename = (varargin{2}'); %filename
            %filepath = varargin{3}; % list of paths
            try def = varargin{2}; catch def = {1,1E-3, 0,'','proportion correct'}; end

            %save defaults and currently loaded bestset index
            app.def = def;
            app.ALLMVPC = ALLMVPC; %from GUI input


            try istime = def{1};catch istime=1;  end
            try tunits = def{2};catch tunits = 1E-3; end
            try trdata = def{3};catch  trdata=0;end
            % prec = def{4};
            try fname = def{4}; catch fname = [pwd,filesep,'MVPCValue.txt'];end
            if isempty(fname)
                fname = [pwd,filesep,'MVPCValue.txt'];
            end

            try MVPCValue = def{5};catch MVPCValue = 'proportion correct'; end
            if isempty(MVPCValue) || ~ischar(MVPCValue)
                MVPCValue = 'proportion correct';
            end


            if ~isempty(ALLMVPC)
                MVPCValueItem = '';
                for Numofmvpc = 1:length(ALLMVPC)
                    Item_One = ALLMVPC(Numofmvpc).DecodingUnit;
                    [C,IA] = ismember_bc2({Item_One}, MVPCValueItem);
                    if IA==0
                        if isempty(MVPCValueItem)
                            MVPCValueItem{1,1} = Item_One;
                        else
                            MVPCValueItem{1,length(MVPCValueItem)+1} = Item_One;
                        end
                    end
                end
            else
                MVPCValueItem = {'proportion correct'};
            end

            [C,IA] = ismember_bc2({MVPCValue}, MVPCValueItem);
            if IA ==0
                MVPCValue = MVPCValueItem{1};
            else
                MVPCValue = MVPCValueItem{IA};
            end


            %apply defaults
            set(app.SaveOutputasEditField,'Value',fname);%outputas
            try app.MVPCvaluetoexportDropDown.Items = MVPCValueItem;catch app.MVPCvaluetoexportDropDown.Items = 'proportion correct'; end
            try
                app.MVPCvaluetoexportDropDown.Value = MVPCValue;
            catch;
            end;
            app.MVPCvaluetoexportDropDown.BackgroundColor = [1 1 1];

            app.TimeunitsDropDown.Items = {'milliseconds','seconds'};

            if istime == 1 %includetime
                set(app.IncludetimevaluesCheckBox,'Value',1);
                set(app.TimeunitsDropDown,'Enable','on');
                if tunits == 1E-3
                    set(app.TimeunitsDropDown,'Value','milliseconds')
                else
                    set(app.TimeunitsDropDown,'Value','seconds')
                end

            else
                set(app.IncludetimevaluesCheckBox,'Value',0);
                set(app.TimeunitsDropDown,'Enable','off');
            end
            app.TimeunitsDropDown.BackgroundColor = [1 1 1];

            if trdata == 1
                set(app.rowspoints,'Value',1);
            else
                set(app.rowsvalue,'Value',1);
            end
            %%precision
            precisiontype = {'1','2','3','4','5','6'};
            app.DecimalDropDown.Items = precisiontype;
            app.DecimalDropDown.BackgroundColor = [1 1 1];
            try precisionvalue =  def{6}; catch precisionvalue=3; end
            if isempty(precisionvalue) || numel(precisionvalue)~=1 || any(precisionvalue(:)<1) ||any(precisionvalue(:)>6)
                precisionvalue=3;
            end
            app.DecimalDropDown.Value = precisiontype{precisionvalue};

            %to close out gui
            ready = 0;
            if ready == 1
                app.FinishButton = 1;
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            namef = app.SaveOutputasEditField.Value;

            [pathstr, namedef, ext] = fileparts(char(namef));
            [textfilename, textpathname, indxs] = uiputfile({'*.txt','(*.txt)'}, ...
                'Save as',...
                namedef);

            if isequal(textfilename,0)
                app.UIFigure.Visible = 'off';
                app.UIFigure.Visible = 'on';
                return
            end

            [pathstr, textfilename, ext] = fileparts(textfilename) ;
            ext = '.txt';

            filenamex = fullfile(textpathname, [textfilename ext]);

            set(app.SaveOutputasEditField,'Value',filenamex);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: IncludetimevaluesCheckBox
        function IncludetimevaluesCheckBoxValueChanged(app, event)
            value = app.IncludetimevaluesCheckBox.Value;

            if value == 1
                set(app.TimeunitsDropDown,'Enable','on')
            else
                set(app.TimeunitsDropDown,'Enable','off')
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)

            %generate output

            fname = app.SaveOutputasEditField.Value;

            istime =  app.IncludetimevaluesCheckBox.Value;
            if istime == 1
                tval = app.TimeunitsDropDown;
                switch  tval.Value
                    case 'milliseconds'
                        choiceT = 1E-3;
                    case 'seconds'
                        choiceT = 1;
                end
            else
                choiceT = 1;
            end

            if app.rowspoints.Value ==1
                trdata = 1 ;
            else
                trdata = 0;
            end
            MVPCValue =  app.MVPCvaluetoexportDropDown.Value;

            Decimal = str2num(app.DecimalDropDown.Value);
            app.output = {istime, choiceT, trdata, fname,MVPCValue,Decimal};
            app.FinishButton = 1;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SaveOutputasEditFieldLabel = uilabel(app.UIFigure);
            app.SaveOutputasEditFieldLabel.Text = 'Save Output as';
            app.SaveOutputasEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.TimePanel = uipanel(app.UIFigure);
            app.TimePanel.Title = 'Time';
            app.IncludetimevaluesCheckBox = uicheckbox(app.TimePanel);
            app.IncludetimevaluesCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludetimevaluesCheckBoxValueChanged, true);
            app.IncludetimevaluesCheckBox.Text = 'Include time values';
            app.TimeunitsDropDownLabel = uilabel(app.TimePanel);
            app.TimeunitsDropDownLabel.Text = 'Time units';
            app.TimeunitsDropDown = uidropdown(app.TimePanel);
            app.TransposeDataButtonGroup = uibuttongroup(app.UIFigure);
            app.TransposeDataButtonGroup.Title = 'Transpose Data ';
            app.rowsvalue = uiradiobutton(app.TransposeDataButtonGroup);
            app.rowsvalue.Text = 'Each column is a time point';
            app.rowsvalue.Value = true;
            app.rowspoints = uiradiobutton(app.TransposeDataButtonGroup);
            app.rowspoints.Text = 'Each row is a time point';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.SaveOutputasEditFieldLabel_2 = uilabel(app.UIFigure);
            app.SaveOutputasEditFieldLabel_2.Text = 'All selected MVPCsets will be exported to a single .txt file ';
            app.MVPCvaluetoexportDropDown = uidropdown(app.UIFigure);
            app.SaveOutputasEditFieldLabel_3 = uilabel(app.UIFigure);
            app.SaveOutputasEditFieldLabel_3.Text = 'MVPC value to export';
            app.PrecisionPanel = uipanel(app.UIFigure);
            app.PrecisionPanel.Title = 'Precision';
            app.DecimalDropDownLabel = uilabel(app.PrecisionPanel);
            app.DecimalDropDownLabel.Text = 'Decimal';
            app.DecimalDropDown = uidropdown(app.PrecisionPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mvpc2textGUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end