classdef plotConfusionGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DecimalDropDown                 matlab.ui.control.DropDown
        DecimalDropDownLabel            matlab.ui.control.Label
        PrecisionPanel                  matlab.ui.container.Panel
        PathFileNamePanel               matlab.ui.container.Panel
        BrowseButton                    matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        Savethedataintoafile            matlab.ui.control.CheckBox
        SaveCheckbox                    matlab.ui.control.CheckBox
        SaveFormatDropDown              matlab.ui.control.DropDown
        SaveFormatDropDownLabel         matlab.ui.control.Label
        BrowseDirectoryforSavingButton  matlab.ui.control.Button
        BrowseField                     matlab.ui.control.EditField
        OkayButton                      matlab.ui.control.Button
        PlotextrasPanel                 matlab.ui.container.Panel
        ColorPlotDropDown               matlab.ui.control.DropDown
        ColorPlotDropDownLabel          matlab.ui.control.Label
        LatencyiestoplotPanel           matlab.ui.container.Panel
        etcLabel                        matlab.ui.control.Label
        EpochRange                      matlab.ui.control.EditField
        ValuetoplotDropDown             matlab.ui.control.DropDown
        ValuetoplotDropDownLabel        matlab.ui.control.Label
    end


    properties (Access = public)
        FinishButton % Description
        output % Description
    end

    properties (Access = private)
        plot_menu % Description
        plot_cmap % Description
        cmaps
        ptype
        MVPC % Description
        plot_epoch % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)

            set(app.UIFigure,'Name','ERPLAB -- Plot Confusion Matrix');

            ALLMVPC = varargin{1};
            currdata = varargin{2};
            plot_menu = varargin{3}{1};
            clrmap = varargin{3}{2};
            frmt = varargin{3}{3};
            tm = varargin{3}{4};
            savec = varargin{3}{5};

            try savecfile = varargin{3}{6}; catch  savecfile = 0; end
            try file_PathNames= varargin{3}{7}; catch  file_PathNames = [pwd,filesep,'confusion_matrix.txt']; end


            plot_type = {'Timepoint Confusion Matrix', 'Average Confusion Matrix between two latencies'};

            app.ValuetoplotDropDown.Items = plot_type;
            app.ValuetoplotDropDown.Value = plot_type{plot_menu};

            if plot_menu == 1
                text_instruct = 'e.g., 300 to plot confusion matrix at 300ms or or 100:50:350 to plot at 100, 150, 200,...,350 ms' ;
                app.etcLabel.Text = text_instruct;

            elseif plot_menu == 2
                text_instruct = 'e.g., 200 250 to plot average confusion matrix across 200 to 250 ms';
                app.etcLabel.Text = text_instruct;
            end

            cmaps = {'default','viridis','gray','parula','cool', 'jet','hsv', 'hot' };;
            app.ColorPlotDropDown.Items = cmaps;
            app.ColorPlotDropDown.Value = cmaps{clrmap};

            set(app.BrowseField,'Value',pwd); %default to present working directory
            set(app.BrowseField,'Editable','Off');


            precisiontype = {'1','2','3','4','5','6'};
            app.DecimalDropDown.Items = precisiontype;


            %save format

            formats = {'fig','png','pdf','svg'};
            app.SaveFormatDropDown.Items = formats;
            app.SaveFormatDropDown.Value = formats{frmt};


            %save into app
            app.plot_menu = plot_menu;
            app.plot_cmap = clrmap;
            app.cmaps = cmaps;
            app.ptype = plot_type;
            app.MVPC = ALLMVPC(currdata);

            %set times
            if ~isempty(tm)
                times = mat2colon(tm);
                set(app.EpochRange,'Value',times);
                event = [];
                EpochRangeValueChanged(app,event);
            end

            if savec == 1
                set(app.SaveCheckbox,'Value',1);
                set(app.SaveFormatDropDown,'Enable','On')
                set(app.BrowseDirectoryforSavingButton,'Enable','On');
            else
                set(app.SaveFormatDropDown,'Enable','Off');
                set(app.BrowseDirectoryforSavingButton,'Enable','Off');
            end

            %%save file
            if savecfile==1
                set(app.Savethedataintoafile,'Value',1);
                set(app.EditField,'Enable','On');
                set(app.BrowseButton,'Enable','On');
                set(app.DecimalDropDown,'Enable','On');
            else
                set(app.Savethedataintoafile,'Value',0);
                set(app.EditField,'Enable','Off');
                set(app.BrowseButton,'Enable','Off');
                set(app.DecimalDropDown,'Enable','Off');
            end

            if isempty(file_PathNames)
                file_PathNames = [pwd,filesep,'confusion_matrix.txt'];
            end
            [pathstr, erpfilename, ext] = fileparts(file_PathNames) ;
            if isempty(erpfilename)
                erpfilename = 'confusion_matrix.txt';
            end
            if isempty(pathstr)
                pathstr = pwd;
            end
            erpFilename = char(strcat(pathstr,filesep,erpfilename));
            app.EditField.Value = erpFilename;

            try precisionvalue = varargin{3}{8}; catch precisionvalue=3; end
            if isempty(precisionvalue) || numel(precisionvalue)~=1 || any(precisionvalue(:)<1) ||any(precisionvalue(:)>6)
                precisionvalue=3;
            end
            app.DecimalDropDown.Value = precisiontype{precisionvalue};


            %paint GUI
            painterplabapp(app);

            %
            % Name & version
            %
            version = geterplabversion;
            set(app.UIFigure,'Name', ['ERPLAB ' version '   -   PLOT CONFUSION MATRIX GUI']);

            %to close out gui
            ready = 0;
            if ready == 1
                app.FinishButton = 1;
            end

        end

        % Value changed function: ValuetoplotDropDown
        function ValuetoplotDropDownValueChanged(app, event)
            value = app.ValuetoplotDropDown.Value;

            plot_types = app.ptype;
            idx = find(strcmp(plot_types, value));


            if idx == 1
                text_instruct = 'e.g., 300 to plot confusion matrix at 300ms or or 100:50:350 to plot at 100, 150, 200,...,350 ms' ;
                app.etcLabel.Text = text_instruct;

            elseif idx == 2
                text_instruct = 'e.g., 200 250 to plot average confusion matrix across 200 to 250 ms';
                app.etcLabel.Text = text_instruct;
            end

            app.plot_menu = idx;

        end

        % Value changed function: EpochRange
        function EpochRangeValueChanged(app, event)
            value = app.EpochRange.Value;


            kktime = 1000; %change from s to ms

            %reset working bestfile
            MVPC = app.MVPC;
            orig_times = MVPC.times;
            epoch_window = [orig_times(1) orig_times(end)];
            %             app.BEST_working.xmin = MVPC.xmin;
            %             app.BEST_working.xmax = MVPC.xmax;
            %             app.BEST_working.srate = MVPC.srate;
            %             app.BEST_working.pnts = MVPC.pnts;
            %             app.BEST_working.times = orig_times;
            fs = MVPC.srate;

            xxlim = str2num(value);
            %xxlim = app.epoch_window;


            if app.plot_menu ~= 1
                if isempty(xxlim) | length(xxlim) <2
                    msgboxText =  'You have not specified a time range';
                    title = 'ERPLAB: GUI latencies input';
                    errorfound(msgboxText, title);
                    %                 set(handles.radiobutton_yauto, 'Value',0)
                    %                 drawnow
                    return
                end
            end

            if app.plot_menu == 1 %single timepoint(s)

                if numel(xxlim) == 1
                    %only one timepoint
                    if xxlim < round(epoch_window(1),2)
                        %                 msgboxText = 'You have input an invalid start time; default to earliest start time'
                        %                 title = 'ERPLAB: GUI input';
                        %                 errorfound(msgboxText, title);

                        % Revert to default time range
                        tscale = epoch_window(1); %change from s to ms
                        %tscale(2) = MVPC.xmax * 1000; %change from s to ms
                        edit_time_range = sprintf('%.2f', tscale(1));
                        app.EpochRange.Value = edit_time_range;
                        %                 set(handles.radiobutton_yauto, 'Value',0)
                        %
                        aux_xxlim = tscale;

                        %aux_xxlim(1) = round(MVPC.xmin*kktime);
                    elseif ~ismember(xxlim, round(orig_times,2))
                        %check that start time is actually within the new
                        %resampling period
                        %                 msgboxTest = sprintf('Invalid start time: Not a valid time-sample. Will round to nearest time-sample!');
                        %                 title = 'ERPLAB: wrong time-units';
                        %                 errorfound(msgboxTest, title);

                        %find closest value
                        %[~,ind] = min(abs(orig_times-xxlim(1)));
                        %value = orig_times(ind);
                        [value,ind] = closest(orig_times,xxlim);

                        %                 set(app.TPspinner,'Value',value);
                        %                 app.TPspinner.Value = value;
                        tscale = value ; %change from s to ms
                        edit_time_range = sprintf('%.0f', tscale);
                        app.EpochRange.Value = edit_time_range;
                        %  epoch_window = [tscale(1) tscale(2)];
                        aux_xxlim = value;


                    else
                        aux_xxlim = xxlim;
                    end


                else
                    %range of timepoints are input
                    if xxlim(1) < round(epoch_window(1),2)
                        %                 msgboxText = 'You have input an invalid start time; default to earliest start time'
                        %                 title = 'ERPLAB: GUI input';
                        %                 errorfound(msgboxText, title);

                        % Revert to default time range
                        xxlim(1) = epoch_window(1); %change from s to ms
                        %tscale(2) = MVPC.xmax * 1000; %change from s to ms
                        edit_time_range = sprintf('%.2f', xxlim(1));
                        app.EpochRange.Value = edit_time_range;


                    end


                    check_vals = ismember(xxlim, round(orig_times,2));
                    z = nnz(~check_vals);

                    if z > 0
                        %check that start time is actually within the new
                        %resampling period
                        %                 msgboxTest = sprintf('Invalid start time: Not a valid time-sample. Will round to nearest time-sample!');
                        %                 title = 'ERPLAB: wrong time-units';
                        %                 errorfound(msgboxTest, title);

                        %find closest value
                        %[~,ind] = min(abs(orig_times-xxlim(1)));
                        %value = orig_times(ind);
                        [value,ind] = closest(orig_times,xxlim);

                        %                 set(app.TPspinner,'Value',value);
                        %                 app.TPspinner.Value = value;
                        tscale = mat2colon(value,'delimiter','off');  ; %change from s to ms
                        edit_time_range = sprintf('%s', tscale);
                        app.EpochRange.Value = edit_time_range;
                        %  epoch_window = [tscale(1) tscale(2)];
                        aux_xxlim = value;


                    else
                        aux_xxlim = xxlim;
                    end


                end


            else
                %aveerage across two timepoints
                if xxlim(1) < round(epoch_window(1),2)
                    %                 msgboxText = 'You have input an invalid start time; default to earliest start time'
                    %                 title = 'ERPLAB: GUI input';
                    %                 errorfound(msgboxText, title);

                    % Revert to default time range
                    tscale(1) = epoch_window(1); %change from s to ms
                    %tscale(2) = MVPC.xmax * 1000; %change from s to ms
                    edit_time_range = sprintf('%.2f %.2f', tscale(1), xxlim(2));
                    app.EpochRange.Value = edit_time_range;
                    %                 set(handles.radiobutton_yauto, 'Value',0)
                    %
                    aux_xxlim(1) = tscale(1);

                    %aux_xxlim(1) = round(MVPC.xmin*kktime);
                elseif ~ismember(xxlim(1), round(orig_times,2))
                    %check that start time is actually within the new
                    %resampling period
                    %                 msgboxTest = sprintf('Invalid start time: Not a valid time-sample. Will round to nearest time-sample!');
                    %                 title = 'ERPLAB: wrong time-units';
                    %                 errorfound(msgboxTest, title);

                    %find closest value
                    %[~,ind] = min(abs(orig_times-xxlim(1)));
                    %value = orig_times(ind);
                    [value,ind] = closest(orig_times,xxlim(1));

                    %                 set(app.TPspinner,'Value',value);
                    %                 app.TPspinner.Value = value;
                    tscale(1) = value ;
                    tscale(2) = xxlim(2); %change from s to ms
                    edit_time_range = sprintf('%.2f %.2f', tscale(1), tscale(2));
                    app.EpochRange.Value = edit_time_range;
                    epoch_window = [tscale(1) tscale(2)];
                    aux_xxlim(1) = value;


                else
                    aux_xxlim(1) = xxlim(1);
                end


                if xxlim(2)> round(epoch_window(end),2)
                    %                     msgboxText = 'You have input an invalid end time; default to lateset end time'
                    %                     title = 'ERPLAB: GUI input';
                    %                     errorfound(msgboxText, title);
                    % Revert to default time range
                    %tscale(1) = MVPC(1).xmin * 1000; %change from s to ms
                    tscale(2) = epoch_window(end); %change from s to ms
                    edit_time_range = sprintf('%.2f %.2f', aux_xxlim(1), tscale(2));
                    app.EpochRange.Value = edit_time_range;

                    aux_xxlim(2)=  tscale(2);

                elseif ~ismember(xxlim(2), round(orig_times,2))
                    %check that start time is actually within the new
                    %resampling period
                    %                     msgboxTest = sprintf('Invalid end time: Not a valid time-sample. Will round!');
                    %                     title = 'ERPLAB: wrong time-units';
                    %                     errorfound(msgboxTest, title);

                    %find closest value
                    %[~,ind] = min(abs(orig_times-xxlim(2)));
                    %value = orig_times(ind);
                    [value,ind] = closest(orig_times,xxlim(2));
                    tscale(1) = aux_xxlim(1) ;
                    tscale(2) = value ; %change from s to ms
                    edit_time_range = sprintf('%.2f %.2f', tscale(1), tscale(2));
                    app.EpochRange.Value = edit_time_range;
                    epoch_window = [tscale(1) tscale(2)];
                    aux_xxlim(2) = value;

                else
                    aux_xxlim(2) = xxlim(2);
                end
            end


            if app.plot_menu ~= 1
                [xp1, xp2, checkw] = window2sample(MVPC, aux_xxlim(1:2) , fs, 'relaxed');

                if checkw==1
                    msgboxText =  'Time window cannot be larger than epoch.';
                    title = 'ERPLAB';
                    errorfound(msgboxText, title);
                    app.EpochRange.Value = 'Input Custom Range';
                    %set(handles.radiobutton_yauto, 'Value',0)
                    %drawnow
                    return
                elseif checkw==2
                    msgboxText =  'Too narrow time window (are the start and end times reversed?)';
                    title = 'ERPLAB';
                    errorfound(msgboxText, title);
                    app.EpochRange.Value = 'Input Custom Range';
                    %set(handles.radiobutton_yauto, 'Value',0)
                    %drawnow
                    return
                end
            end
            %saveoutput %display
            if numel(aux_xxlim)>2
                tscale = mat2colon(aux_xxlim,'delimiter','off');  ; %change from s to ms
                edit_time_range = sprintf('%s', tscale);
                app.EpochRange.Value = edit_time_range;
            else
                app.EpochRange.Value = num2str(aux_xxlim);
            end


            app.plot_epoch = aux_xxlim;

        end

        % Button pushed function: OkayButton
        function OkayButtonPushed(app, event)

            %get output
            meas = app.plot_menu;
            epoch_times  = app.plot_epoch;

            color_map = find(strcmp(app.cmaps,app.ColorPlotDropDown.Value));
            pname = app.BrowseField.Value;

            if strcmpi('fig',app.SaveFormatDropDown.Value)
                frmt = 1;
            elseif strcmpi('png',app.SaveFormatDropDown.Value)
                frmt = 2;
            elseif strcmpi('pdf',app.SaveFormatDropDown.Value)
                frmt = 3;
            else
                frmt = 4;
            end

            savec = app.SaveCheckbox.Value;

            savefiles = app.Savethedataintoafile.Value;
            if savefiles==1
                PathNames =  app.EditField.Value;
                [pathstr, erpfilename, ext] = fileparts(PathNames) ;
                if isempty(pathstr)
                    pathstr = pwd;
                end
                if isempty(erpfilename)
                    erpfilename = 'confusion_matrix';
                end
                if ~strcmpi(ext,'.txt') && ~strcmpi(ext,'.xls') && ~strcmpi(ext,'.xlsx')
                    ext='.txt';
                end

                erpFilename = char(strcat(pathstr,filesep,erpfilename,ext));
                app.EditField.Value = erpFilename;
            else

                erpFilename = [];
            end

            Decimal = str2num(app.DecimalDropDown.Value);

            res = {meas,color_map, epoch_times,pname,frmt,savec,savefiles,erpFilename,Decimal};

            app.output =res;
            app.FinishButton = 1;


        end

        % Button pushed function: BrowseDirectoryforSavingButton
        function BrowseDirectoryforSavingButtonPushed(app, event)

            fndefault = cd;
            dname = uigetdir(fndefault, 'Select path for MVPC files');


            %set(handles.edit_saveas,'String', fullfile(pathname, fname));
            if dname == 0
                dname = pwd;
                disp(['To save confusion matrix plot(s), user selected current directory'])
            else
                disp(['To save confusion matrix plot(s), user selected path: ',dname]);
            end


            set(app.BrowseField,'Value',dname);

            
            %
            %             Selected_ERP_label = handles.Selected_ERP_label;
            %             for Numofselectederp = 1:numel(Selected_ERP_label)
            %                 %reset the filepath for saving to CD
            %                 handles.ALLERP(Selected_ERP_label(Numofselectederp)).filepath = dname;
            %             end

        end

        % Value changed function: ColorPlotDropDown
        function ColorPlotDropDownValueChanged(app, event)
            value = app.ColorPlotDropDown.Value;

        end

        % Value changed function: SaveCheckbox
        function SaveCheckboxValueChanged(app, event)
            value = app.SaveCheckbox.Value;

            if value == 1
                set(app.SaveCheckbox,'Value',1);
                set(app.SaveFormatDropDown,'Enable','On')
                set(app.BrowseDirectoryforSavingButton,'Enable','On');
            else
                set(app.SaveFormatDropDown,'Enable','Off');
                set(app.BrowseDirectoryforSavingButton,'Enable','Off');
            end


        end

        % Button pushed function: BrowseButton
        function Browse_path(app, event)
            PathNames =  app.EditField.Value;

            [pathstr, erpfilename, ext] = fileparts(PathNames) ;
            if isempty(erpfilename)
                erpfilename = 'confusion_matrix';
            end
            if isempty(pathstr)
                pathstr = pwd;
            end
            erpFilename = char(strcat(pathstr,filesep,erpfilename));
            formatname = {'*.txt';'*.xls';'*.xlsx'};

            [erpfilename, erppathname, indxs] = uiputfile(formatname, ...
                ['Export confusion matrix'],...
                erpFilename);
            if isequal(erpfilename,0)
                return
            end
            if isequal(erpfilename,0)
                return
            end

            [pathstr, erpfilename, ext] = fileparts(erpfilename) ;
            if ~strcmpi(ext,'.txt') && ~strcmpi(ext,'.xls') && ~strcmpi(ext,'.xlsx')
                ext='.txt';
            end

            erpFilename = char(strcat(erppathname,erpfilename,ext));
            app.EditField.Value = erpFilename;

        end

        % Value changed function: Savethedataintoafile
        function Save_files_CheckboxValueChanged(app, event)
            savecfile = app.Savethedataintoafile.Value;
            if savecfile==1
                set(app.Savethedataintoafile,'Value',1);
                set(app.EditField,'Enable','On');
                set(app.BrowseButton,'Enable','On');
                set(app.DecimalDropDown,'Enable','On');
            else
                set(app.Savethedataintoafile,'Value',0);
                set(app.EditField,'Enable','Off');
                set(app.BrowseButton,'Enable','Off');
                set(app.DecimalDropDown,'Enable','Off');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.ValuetoplotDropDownLabel = uilabel(app.UIFigure);
            app.ValuetoplotDropDownLabel.Text = 'Value to plot';
            app.ValuetoplotDropDown = uidropdown(app.UIFigure);
            app.ValuetoplotDropDown.ValueChangedFcn = createCallbackFcn(app, @ValuetoplotDropDownValueChanged, true);
            app.LatencyiestoplotPanel = uipanel(app.UIFigure);
            app.LatencyiestoplotPanel.Title = 'Latency(ies) to plot';
            app.EpochRange = uieditfield(app.LatencyiestoplotPanel, 'text');
            app.EpochRange.ValueChangedFcn = createCallbackFcn(app, @EpochRangeValueChanged, true);
            app.etcLabel = uilabel(app.LatencyiestoplotPanel);
            app.etcLabel.Text = 'etc';
            app.PlotextrasPanel = uipanel(app.UIFigure);
            app.PlotextrasPanel.Title = 'Plot extras';
            app.ColorPlotDropDownLabel = uilabel(app.PlotextrasPanel);
            app.ColorPlotDropDownLabel.Text = 'Color Plot';
            app.ColorPlotDropDown = uidropdown(app.PlotextrasPanel);
            app.ColorPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorPlotDropDownValueChanged, true);
            app.OkayButton = uibutton(app.UIFigure, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @OkayButtonPushed, true);
            app.OkayButton.Text = 'Okay';
            app.BrowseField = uieditfield(app.UIFigure, 'text');
            app.BrowseDirectoryforSavingButton = uibutton(app.UIFigure, 'push');
            app.BrowseDirectoryforSavingButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseDirectoryforSavingButtonPushed, true);
            app.BrowseDirectoryforSavingButton.Text = 'Browse Directory for Saving';
            app.SaveFormatDropDownLabel = uilabel(app.UIFigure);
            app.SaveFormatDropDownLabel.Text = 'Save Format';
            app.SaveFormatDropDown = uidropdown(app.UIFigure);
            app.SaveCheckbox = uicheckbox(app.UIFigure);
            app.SaveCheckbox.ValueChangedFcn = createCallbackFcn(app, @SaveCheckboxValueChanged, true);
            app.SaveCheckbox.Text = 'Save Each Plot into Directory: ';
            app.Savethedataintoafile = uicheckbox(app.UIFigure);
            app.Savethedataintoafile.ValueChangedFcn = createCallbackFcn(app, @Save_files_CheckboxValueChanged, true);
            app.Savethedataintoafile.Text = 'Save the data into a file';
            app.PathFileNamePanel = uipanel(app.UIFigure);
            app.PathFileNamePanel.Title = 'Path & File Name';
            app.EditField = uieditfield(app.PathFileNamePanel, 'text');
            app.BrowseButton = uibutton(app.PathFileNamePanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @Browse_path, true);
            app.BrowseButton.Text = 'Browse';
            app.PrecisionPanel = uipanel(app.UIFigure);
            app.PrecisionPanel.Title = 'Precision';
            app.DecimalDropDownLabel = uilabel(app.UIFigure);
            app.DecimalDropDownLabel.Text = 'Decimal';
            app.DecimalDropDown = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotConfusionGUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end