classdef plotTempGMGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TimePanel                       matlab.ui.container.Panel
        TimeunitsDropDown               matlab.ui.control.DropDown
        TimeunitsDropDownLabel          matlab.ui.control.Label
        IncludetimevaluesCheckBox       matlab.ui.control.CheckBox
        DecimalPlacesDropDown           matlab.ui.control.DropDown
        DecimalPlacesDropDownLabel      matlab.ui.control.Label
        NumericprecisionPanel           matlab.ui.container.Panel
        PathFileNamePanel               matlab.ui.container.Panel
        BrowseButton                    matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        Savethedataintoafile            matlab.ui.control.CheckBox
        SaveCheckbox                    matlab.ui.control.CheckBox
        SaveFormatDropDown              matlab.ui.control.DropDown
        SaveFormatDropDownLabel         matlab.ui.control.Label
        BrowseDirectoryforSavingButton  matlab.ui.control.Button
        BrowseField                     matlab.ui.control.EditField
        OkayButton                      matlab.ui.control.Button
        PlotextrasPanel                 matlab.ui.container.Panel
        ColorPlotDropDown               matlab.ui.control.DropDown
        ColorPlotDropDownLabel          matlab.ui.control.Label
        LatencyiestoplotPanel           matlab.ui.container.Panel
        etcLabel                        matlab.ui.control.Label
        EpochRange                      matlab.ui.control.EditField
    end


    properties (Access = public)
        FinishButton % Description
        output % Description
    end

    properties (Access = private)
        plot_menu % Description
        plot_cmap % Description
        cmaps
        ptype
        MVPC % Description
        plot_epoch % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)

            set(app.UIFigure,'Name','ERPLAB -- Plot Temporal Generalization Matrix');

            ALLMVPC = varargin{1};
            currdata = varargin{2};
            clrmap = varargin{3}{1};
            frmt = varargin{3}{2};
            tm = varargin{3}{3};
            savec = varargin{3}{4};


            try savecfile = varargin{3}{5}; catch  savecfile = 0; end
            try file_PathNames= varargin{3}{6}; catch  file_PathNames = [pwd,filesep,'Temporal_Generalization_matrix.txt']; end
            try precisionvalue = varargin{3}{7}; catch precisionvalue=3; end
            try timeSelc = varargin{3}{8};catch  timeSelc = 0; end
            try  timeunit = varargin{3}{9};catch  timeunit = 1; end


            text_instruct = 'e.g.,-200 800 to plot Temporal Generalization Matrix between -200 and 800 ms';
            app.etcLabel.Text = text_instruct;


            cmaps = {'default','viridis','gray','parula','cool', 'jet','hsv', 'hot' };;
            app.ColorPlotDropDown.Items = cmaps;
            app.ColorPlotDropDown.Value = cmaps{clrmap};

            set(app.BrowseField,'Value',pwd); %default to present working directory
            set(app.BrowseField,'Editable','Off');

            %save format
            formats = {'fig','png','pdf','svg'};
            app.SaveFormatDropDown.Items = formats;
            app.SaveFormatDropDown.Value = formats{frmt};


            %save into app
            app.plot_cmap = clrmap;
            app.cmaps = cmaps;
            % app.ptype = plot_type;
            app.MVPC = ALLMVPC(currdata);

            %set times
            if ~isempty(tm)
                times = mat2colon(tm);
                set(app.EpochRange,'Value',times);
                event = [];
                EpochRangeValueChanged(app,event);
            end

            if savec == 1
                set(app.SaveCheckbox,'Value',1);
                set(app.SaveFormatDropDown,'Enable','On')
                set(app.BrowseDirectoryforSavingButton,'Enable','On');
            else
                set(app.SaveFormatDropDown,'Enable','Off');
                set(app.BrowseDirectoryforSavingButton,'Enable','Off');
            end


            precisiontype = {'1','2','3','4','5','6'};
            app.DecimalPlacesDropDown.Items = precisiontype;

            %%save file
            if savecfile==1
                set(app.Savethedataintoafile,'Value',1);
                set(app.EditField,'Enable','On');
                set(app.BrowseButton,'Enable','On');
                set(app.DecimalPlacesDropDown,'Enable','On');
                set(app.TimePanel,'Enable','On');
            else
                set(app.Savethedataintoafile,'Value',0);
                set(app.EditField,'Enable','Off');
                set(app.BrowseButton,'Enable','Off');
                set(app.DecimalPlacesDropDown,'Enable','Off');
                set(app.TimePanel,'Enable','Off');
            end

            if isempty(file_PathNames)
                file_PathNames = [pwd,filesep,'Temporal_Generalization_matrix.txt'];
            end
            [pathstr, erpfilename, ext] = fileparts(file_PathNames) ;
            if isempty(erpfilename)
                erpfilename = 'Temporal_Generalization_matrix.txt';
            end
            if isempty(pathstr)
                pathstr = pwd;
            end
            erpFilename = char(strcat(pathstr,filesep,erpfilename));
            app.EditField.Value = erpFilename;

            
            if isempty(precisionvalue) || numel(precisionvalue)~=1 || any(precisionvalue(:)<1) ||any(precisionvalue(:)>6)
                precisionvalue=3;
            end
            app.DecimalPlacesDropDown.Value = precisiontype{precisionvalue};


            if isempty(timeSelc) || (timeSelc~=0 && timeSelc~=1)
                timeSelc = 0;
            end
            app.IncludetimevaluesCheckBox.Value = timeSelc;
            if isempty(timeunit) || (timeunit~=2 && timeunit~=1)
                timeunit = 1;
            end
            if timeunit==1
                app.TimeunitsDropDown.Value = 'Milliseconds';
            else
                app.TimeunitsDropDown.Value = 'Seconds';
            end
            if timeSelc==0
                app.TimeunitsDropDown.Enable = 'off';
            else
                app.TimeunitsDropDown.Enable = 'on';
            end

            %paint GUI
            painterplabapp(app);
            %
            % Name & version
            %
            version = geterplabversion;
            set(app.UIFigure,'Name', ['ERPLAB ' version '   -   Plot Temporal Generalization Matrix GUI']);

            %to close out gui
            ready = 0;
            if ready == 1
                app.FinishButton = 1;
            end

        end

        % Callback function
        function ValuetoplotDropDownValueChanged(app, event)

            app.ptype;
        end

        % Value changed function: EpochRange
        function EpochRangeValueChanged(app, event)
            value = app.EpochRange.Value;
            kktime = 1000; %change from s to ms

            %reset working bestfile
            MVPC = app.MVPC;
            orig_times = MVPC.times;
            epoch_window = [orig_times(1) orig_times(end)];
            fs = MVPC.srate;

            xxlim = str2num(value);
            %xxlim = app.epoch_window;

            if isempty(xxlim) | length(xxlim) <2
                msgboxText =  'You have not specified a time range';
                title = 'ERPLAB: GUI latencies input';
                errorfound(msgboxText, title);
                %                 set(handles.radiobutton_yauto, 'Value',0)
                %                 drawnow
                return
            end


            %aveerage across two timepoints
            if xxlim(1) < round(epoch_window(1),2)
                %                 msgboxText = 'You have input an invalid start time; default to earliest start time'
                %                 title = 'ERPLAB: GUI input';
                %                 errorfound(msgboxText, title);

                % Revert to default time range
                tscale(1) = epoch_window(1); %change from s to ms
                %tscale(2) = MVPC.xmax * 1000; %change from s to ms
                edit_time_range = sprintf('%.2f %.2f', tscale(1), xxlim(2));
                app.EpochRange.Value = edit_time_range;
                %                 set(handles.radiobutton_yauto, 'Value',0)
                %
                aux_xxlim(1) = tscale(1);

                %aux_xxlim(1) = round(MVPC.xmin*kktime);
            elseif ~ismember(xxlim(1), round(orig_times,2))
                %check that start time is actually within the new
                %resampling period
                %                 msgboxTest = sprintf('Invalid start time: Not a valid time-sample. Will round to nearest time-sample!');
                %                 title = 'ERPLAB: wrong time-units';
                %                 errorfound(msgboxTest, title);

                %find closest value
                %[~,ind] = min(abs(orig_times-xxlim(1)));
                %value = orig_times(ind);
                [value,ind] = closest(orig_times,xxlim(1));

                %                 set(app.TPspinner,'Value',value);
                %                 app.TPspinner.Value = value;
                tscale(1) = value ;
                tscale(2) = xxlim(2); %change from s to ms
                edit_time_range = sprintf('%.2f %.2f', tscale(1), tscale(2));
                app.EpochRange.Value = edit_time_range;
                epoch_window = [tscale(1) tscale(2)];
                aux_xxlim(1) = value;


            else
                aux_xxlim(1) = xxlim(1);
            end


            if xxlim(2)> round(epoch_window(end),2)
                %                     msgboxText = 'You have input an invalid end time; default to lateset end time'
                %                     title = 'ERPLAB: GUI input';
                %                     errorfound(msgboxText, title);
                % Revert to default time range
                %tscale(1) = MVPC(1).xmin * 1000; %change from s to ms
                tscale(2) = epoch_window(end); %change from s to ms
                edit_time_range = sprintf('%.2f %.2f', aux_xxlim(1), tscale(2));
                app.EpochRange.Value = edit_time_range;

                aux_xxlim(2)=  tscale(2);

            elseif ~ismember(xxlim(2), round(orig_times,2))
                %check that start time is actually within the new
                %resampling period
                %                     msgboxTest = sprintf('Invalid end time: Not a valid time-sample. Will round!');
                %                     title = 'ERPLAB: wrong time-units';
                %                     errorfound(msgboxTest, title);

                %find closest value
                %[~,ind] = min(abs(orig_times-xxlim(2)));
                %value = orig_times(ind);
                [value,ind] = closest(orig_times,xxlim(2));
                tscale(1) = aux_xxlim(1) ;
                tscale(2) = value ; %change from s to ms
                edit_time_range = sprintf('%.2f %.2f', tscale(1), tscale(2));
                app.EpochRange.Value = edit_time_range;
                epoch_window = [tscale(1) tscale(2)];
                aux_xxlim(2) = value;

            else
                aux_xxlim(2) = xxlim(2);
            end

            [xp1, xp2, checkw] = window2sample(MVPC, aux_xxlim(1:2) , fs, 'relaxed');

            if checkw==1
                msgboxText =  'Time window cannot be larger than epoch.';
                title = 'ERPLAB';
                errorfound(msgboxText, title);
                app.EpochRange.Value = 'Input Custom Range';
                %set(handles.radiobutton_yauto, 'Value',0)
                %drawnow
                return
            elseif checkw==2
                msgboxText =  'Too narrow time window (are the start and end times reversed?)';
                title = 'ERPLAB';
                errorfound(msgboxText, title);
                app.EpochRange.Value = 'Input Custom Range';
                %set(handles.radiobutton_yauto, 'Value',0)
                %drawnow
                return
            end
            %saveoutput %display
            if numel(aux_xxlim)>2
                tscale = mat2colon(aux_xxlim,'delimiter','off');  ; %change from s to ms
                edit_time_range = sprintf('%s', tscale);
                app.EpochRange.Value = edit_time_range;
            else
                app.EpochRange.Value = num2str(aux_xxlim);
            end
            app.plot_epoch = aux_xxlim;
        end

        % Button pushed function: OkayButton
        function OkayButtonPushed(app, event)

            %get output

            epoch_times  = app.plot_epoch;

            color_map = find(strcmp(app.cmaps,app.ColorPlotDropDown.Value));
            pname = app.BrowseField.Value;

            if strcmpi('fig',app.SaveFormatDropDown.Value)
                frmt = 1;
            elseif strcmpi('png',app.SaveFormatDropDown.Value)
                frmt = 2;
            elseif strcmpi('pdf',app.SaveFormatDropDown.Value)
                frmt = 3;
            else
                frmt = 4;
            end

            savec = app.SaveCheckbox.Value;


            savefiles = app.Savethedataintoafile.Value;
            if savefiles==1
                PathNames =  app.EditField.Value;
                [pathstr, erpfilename, ext] = fileparts(PathNames) ;
                if isempty(pathstr)
                    pathstr = pwd;
                end
                if isempty(erpfilename)
                    erpfilename = 'Temporal_Generalization_matrix';
                end
                if ~strcmpi(ext,'.txt') && ~strcmpi(ext,'.xls') && ~strcmpi(ext,'.xlsx')
                    ext='.txt';
                end

                erpFilename = char(strcat(pathstr,filesep,erpfilename,ext));
                app.EditField.Value = erpFilename;
            else

                erpFilename = '';
            end

            Decimal = str2num(app.DecimalPlacesDropDown.Value);


            timeSelc= app.IncludetimevaluesCheckBox.Value;
            if strcmpi(app.TimeunitsDropDown.Value,'Milliseconds')
                timeunit=1;
            else
                timeunit=2;
            end

            res = {color_map, epoch_times,pname,frmt,savec,savefiles,erpFilename,Decimal,timeSelc,timeunit};
            app.output =res;
            app.FinishButton = 1;


        end

        % Button pushed function: BrowseDirectoryforSavingButton
        function BrowseDirectoryforSavingButtonPushed(app, event)

            fndefault = cd;
            dname = uigetdir(fndefault, 'Select path for MVPC files');


            %set(handles.edit_saveas,'String', fullfile(pathname, fname));
            if dname == 0
                dname = pwd;
                disp(['To save confusion matrix plot(s), user selected current directory'])
            else
                disp(['To save confusion matrix plot(s), user selected path: ',dname]);
            end


            set(app.BrowseField,'Value',dname);
            %
            %             Selected_ERP_label = handles.Selected_ERP_label;
            %             for Numofselectederp = 1:numel(Selected_ERP_label)
            %                 %reset the filepath for saving to CD
            %                 handles.ALLERP(Selected_ERP_label(Numofselectederp)).filepath = dname;
            %             end

        end

        % Value changed function: ColorPlotDropDown
        function ColorPlotDropDownValueChanged(app, event)
            value = app.ColorPlotDropDown.Value;

        end

        % Value changed function: SaveCheckbox
        function SaveCheckboxValueChanged(app, event)
            value = app.SaveCheckbox.Value;

            if value == 1
                set(app.SaveCheckbox,'Value',1);
                set(app.SaveFormatDropDown,'Enable','On')
                set(app.BrowseDirectoryforSavingButton,'Enable','On');
            else
                set(app.SaveFormatDropDown,'Enable','Off');
                set(app.BrowseDirectoryforSavingButton,'Enable','Off');
            end


        end

        % Value changed function: Savethedataintoafile
        function Save_files_CheckboxValueChanged(app, event)

            savecfile = app.Savethedataintoafile.Value;
            if savecfile==1
                set(app.Savethedataintoafile,'Value',1);
                set(app.EditField,'Enable','On');
                set(app.BrowseButton,'Enable','On');
                set(app.DecimalPlacesDropDown,'Enable','On');
                set(app.TimePanel,'Enable','On');
            else
                set(app.Savethedataintoafile,'Value',0);
                set(app.EditField,'Enable','Off');
                set(app.BrowseButton,'Enable','Off');
                set(app.DecimalPlacesDropDown,'Enable','Off');
                set(app.TimePanel,'Enable','Off');
            end
        end

        % Button pushed function: BrowseButton
        function Browse_path(app, event)
            PathNames =  app.EditField.Value;

            [pathstr, erpfilename, ext] = fileparts(PathNames) ;
            if isempty(erpfilename)
                erpfilename = 'Temporal_Generalization_matrix';
            end
            if isempty(pathstr)
                pathstr = pwd;
            end
            erpFilename = char(strcat(pathstr,filesep,erpfilename));
            formatname = {'*.txt';'*.xls';'*.xlsx'};

            [erpfilename, erppathname, indxs] = uiputfile(formatname, ...
                ['Export Temporal Generalization Matrix'],...
                erpFilename);
            if isequal(erpfilename,0)
                return
            end
            if isequal(erpfilename,0)
                return
            end

            [pathstr, erpfilename, ext] = fileparts(erpfilename) ;
            if ~strcmpi(ext,'.txt') && ~strcmpi(ext,'.xls') && ~strcmpi(ext,'.xlsx')
                ext='.txt';
            end

            erpFilename = char(strcat(erppathname,erpfilename,ext));
            app.EditField.Value = erpFilename;
        end

        % Value changed function: IncludetimevaluesCheckBox
        function f_time_value_checkbox(app, event)
            timeSelc = app.IncludetimevaluesCheckBox.Value;
              if timeSelc==0
                app.TimeunitsDropDown.Enable = 'off';
            else
                app.TimeunitsDropDown.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.LatencyiestoplotPanel = uipanel(app.UIFigure);
            app.LatencyiestoplotPanel.Title = 'Latency(ies) to plot';
            app.EpochRange = uieditfield(app.LatencyiestoplotPanel, 'text');
            app.EpochRange.ValueChangedFcn = createCallbackFcn(app, @EpochRangeValueChanged, true);
            app.etcLabel = uilabel(app.LatencyiestoplotPanel);
            app.etcLabel.Text = 'etc';
            app.PlotextrasPanel = uipanel(app.UIFigure);
            app.PlotextrasPanel.Title = 'Plot extras';
            app.ColorPlotDropDownLabel = uilabel(app.PlotextrasPanel);
            app.ColorPlotDropDownLabel.Text = 'Color Plot';
            app.ColorPlotDropDown = uidropdown(app.PlotextrasPanel);
            app.ColorPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorPlotDropDownValueChanged, true);
            app.OkayButton = uibutton(app.UIFigure, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @OkayButtonPushed, true);
            app.OkayButton.Text = 'Okay';
            app.BrowseField = uieditfield(app.UIFigure, 'text');
            app.BrowseDirectoryforSavingButton = uibutton(app.UIFigure, 'push');
            app.BrowseDirectoryforSavingButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseDirectoryforSavingButtonPushed, true);
            app.BrowseDirectoryforSavingButton.Text = 'Browse Directory for Saving';
            app.SaveFormatDropDownLabel = uilabel(app.UIFigure);
            app.SaveFormatDropDownLabel.Text = 'Save Format';
            app.SaveFormatDropDown = uidropdown(app.UIFigure);
            app.SaveCheckbox = uicheckbox(app.UIFigure);
            app.SaveCheckbox.ValueChangedFcn = createCallbackFcn(app, @SaveCheckboxValueChanged, true);
            app.SaveCheckbox.Text = 'Save Each Plot into Directory: ';
            app.Savethedataintoafile = uicheckbox(app.UIFigure);
            app.Savethedataintoafile.ValueChangedFcn = createCallbackFcn(app, @Save_files_CheckboxValueChanged, true);
            app.Savethedataintoafile.Text = 'Save the data into a file';
            app.PathFileNamePanel = uipanel(app.UIFigure);
            app.PathFileNamePanel.Title = 'Path & File Name';
            app.EditField = uieditfield(app.PathFileNamePanel, 'text');
            app.BrowseButton = uibutton(app.PathFileNamePanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @Browse_path, true);
            app.BrowseButton.Text = 'Browse';
            app.NumericprecisionPanel = uipanel(app.UIFigure);
            app.NumericprecisionPanel.Title = 'Numeric precision';
            app.DecimalPlacesDropDownLabel = uilabel(app.UIFigure);
            app.DecimalPlacesDropDownLabel.Text = 'Decimal Places';
            app.DecimalPlacesDropDown = uidropdown(app.UIFigure);
            app.TimePanel = uipanel(app.UIFigure);
            app.TimePanel.Title = 'Time';
            app.IncludetimevaluesCheckBox = uicheckbox(app.TimePanel);
            app.IncludetimevaluesCheckBox.ValueChangedFcn = createCallbackFcn(app, @f_time_value_checkbox, true);
            app.IncludetimevaluesCheckBox.Text = 'Include time values';
            app.TimeunitsDropDownLabel = uilabel(app.TimePanel);
            app.TimeunitsDropDownLabel.Text = 'Time units';
            app.TimeunitsDropDown = uidropdown(app.TimePanel);
            app.TimeunitsDropDown.Items = {'Milliseconds', 'Seconds'};
            app.TimeunitsDropDown.Value = 'Milliseconds';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotTempGMGUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end