classdef Decode_mvpc_line_legend_ops < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FontFontsizeandColorforTimeAmplitudeAxisesPanel  matlab.ui.container.Panel
        Color_yaxis                matlab.ui.control.DropDown
        ColorDropDownLabel_2       matlab.ui.control.Label
        Fontsize_yaxis             matlab.ui.control.DropDown
        FontsizeDropDown_2Label_2  matlab.ui.control.Label
        Font_yaxis                 matlab.ui.control.DropDown
        FontDropDown_2Label_2      matlab.ui.control.Label
        AmplitudeAxisLabel         matlab.ui.control.Label
        Color_xaxis                matlab.ui.control.DropDown
        ColorDropDownLabel         matlab.ui.control.Label
        Fontsize_xaxis             matlab.ui.control.DropDown
        FontsizeDropDown_2Label    matlab.ui.control.Label
        Font_xaxis                 matlab.ui.control.DropDown
        FontDropDown_2Label        matlab.ui.control.Label
        TimeAxisLabel              matlab.ui.control.Label
        LegendSettingsPanel        matlab.ui.container.Panel
        columnDropDownLabel        matlab.ui.control.Label
        columnDropDown             matlab.ui.control.DropDown
        TextcolorButtonGroup       matlab.ui.container.ButtonGroup
        SameaslinesButton          matlab.ui.control.RadioButton
        AutoButton_3               matlab.ui.control.RadioButton
        FontsizeDropDown           matlab.ui.control.DropDown
        FontsizeDropDownLabel      matlab.ui.control.Label
        FontDropDown               matlab.ui.control.DropDown
        FontDropDownLabel          matlab.ui.control.Label
        LegendButtonGroup          matlab.ui.container.ButtonGroup
        CustomButton_2             matlab.ui.control.RadioButton
        AutoButton_2               matlab.ui.control.RadioButton
        LineSettingsButtonGroup    matlab.ui.container.ButtonGroup
        CustomButton               matlab.ui.control.RadioButton
        AutoButton                 matlab.ui.control.RadioButton
        ApplyButton                matlab.ui.control.Button
        CancelButton               matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
    end


    properties (Access = private)
    end

    properties (Access = public)
        Output % Description
        Finishbutton % Description
        ALLERP % Description
        ERPArray % Description
    end

    methods (Access = private)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            painterplabapp(app);
            setfonterplab(app);
            % Name & version
            %
            version = geterplabversion;
            set(app.UIFigure,'Name', ['Estudio ' version '   -   Settings for lines and legend for MVPCsets GUI']);

            %%time and amplitude axises
            app.Color_yaxis.BackgroundColor = [1 1 1];
            app.Color_xaxis.BackgroundColor = [1 1 1];
            app.Fontsize_yaxis.BackgroundColor = [1 1 1];
            app.Fontsize_xaxis.BackgroundColor = [1 1 1];
            app.Font_yaxis.BackgroundColor = [1 1 1];
            app.Font_xaxis.BackgroundColor = [1 1 1];
            try fontpars = varargin{3}; catch fontpars = [];end
            try Font_xaxis = fontpars{1}; catch Font_xaxis = 3;  end
            fonttype = {'Courier','Geneva','Helvetica','Monaco','Times'};
            if isempty(Font_xaxis) || numel(Font_xaxis)~=1 || any(Font_xaxis(:)<1) || any(Font_xaxis(:)>5)
                Font_xaxis = 3;
            end
            app.Font_xaxis.Value = fonttype{Font_xaxis};

            try Fontsize_xaxis = fontpars{2}; catch Fontsize_xaxis = 5;  end
            fontsizes  = {'4','6','8','10','12','14','16','18','20','24','28','32','36',...
                '40','50','60','70','80','90','100'};
            %              fontsizes = str2double(fontsizes);
            if isempty(Fontsize_xaxis) || numel(Fontsize_xaxis)~=1 || any(Fontsize_xaxis(:)<1) || any(Fontsize_xaxis(:)>20)
                Fontsize_xaxis = 5;
            end
            app.Fontsize_xaxis.Value = fontsizes{Fontsize_xaxis};
            ytextColor = {'Black','Red','Blue','Green','Orange','Cyan','Magenla'};
            try Color_xaxis = fontpars{3}; catch Color_xaxis = 1;  end
            if isempty(Color_xaxis) || numel(Color_xaxis)~=1 || any(Color_xaxis(:)<1) || any(Color_xaxis(:)>7)
                Color_xaxis = 1;
            end
            app.Color_xaxis.Value =ytextColor{Color_xaxis};

            %%amplitude axis
            try Font_yaxis = fontpars{4}; catch Font_yaxis = 3;  end
            if isempty(Font_yaxis) || numel(Font_yaxis)~=1 || any(Font_yaxis(:)<1) || any(Font_yaxis(:)>5)
                Font_yaxis = 3;
            end
            app.Font_yaxis.Value = fonttype{Font_yaxis};

            try Fontsize_yaxis = fontpars{5}; catch Fontsize_yaxis = 5;  end
            if isempty(Fontsize_yaxis) || numel(Fontsize_yaxis)~=1 || any(Fontsize_yaxis(:)<1) || any(Fontsize_yaxis(:)>20)
                Fontsize_yaxis = 5;
            end
            app.Fontsize_yaxis.Value = fontsizes{Fontsize_yaxis};

            try Color_yaxis = fontpars{6}; catch Color_yaxis = 1;  end
            if isempty(Color_yaxis) || numel(Color_yaxis)~=1 || any(Color_yaxis(:)<1) || any(Color_yaxis(:)>7)
                Color_yaxis = 1;
            end
            app.Color_yaxis.Value =ytextColor{Color_yaxis};


            try Lineparas = varargin{1}; catch Lineparas = [];end
            try linAutoValue = Lineparas{1}; catch  linAutoValue=1;end
            try lineset_strinput = Lineparas{2}; catch  lineset_strinput = []; end
            [lineNameStr,linecolors,linetypes,linewidths,linecolors_str,linetypes_str,linewidths_str,linecolorsrgb] = f_get_lineset_ERPviewer();
            if linAutoValue
                lineset_str  =table(lineNameStr,linecolors,linetypes,linewidths);
                lineset_strdef = table2cell(lineset_str);
            else
                lineset_str  =table(lineNameStr,linecolorsrgb,linetypes,linewidths);
                lineset_strdef = table2cell(lineset_str);
            end
            if isempty(lineset_strinput)
                lineset_strinput = lineset_strdef;
            end

            ColumnNames = {'# Line','Color','Style', 'Width'};
            app.UITable.ColumnFormat = {'char', 'char',...
                {'solid','dash','dot','dashdot','plus','circle','asterisk'},...
                {'0.25','0.5','1','1.5','2','2.5','3','3.5','4','4.5','5','5.5','6','6.5','7','7.5','8','8.5','9','9.5','10','10.5'}};
            app.UITable.ColumnName = ColumnNames;
            app.UITable.Data = lineset_strdef;
            app.UITable.ColumnEditable = [false, true,true,true];
            app.FontDropDown.Items = {'Courier','Geneva','Helvetica','Monaco','Times'};
            app.FontsizeDropDown.Items = {'4','6','8','10','12','14','16','18','20','24','28','32','36',...
                '40','50','60','70','80','90','100'};
            app.FontsizeDropDown.BackgroundColor = [1 1 1];
            app.FontDropDown.BackgroundColor = [1 1 1];
            for Numoflegend = 1:100
                columnStr{Numoflegend} = num2str(Numoflegend);
            end
            app.columnDropDown.Items = columnStr;
            app.columnDropDown.BackgroundColor = [1 1 1];
            if linAutoValue==1
                app.AutoButton.Value=1;
                app.CustomButton.Value=0;
                app.UITable.Enable = 'off';
            else
                app.AutoButton.Value=0;
                app.CustomButton.Value=1;
                app.UITable.Enable = 'on';
            end

            %%legend
            try legendparas = varargin{2}; catch legendparas = [];end
            try legendauto = legendparas{1}; catch legendauto=1; end
            if isempty(legendauto) || numel(legendauto)~=1 || (legendauto~=0 && legendauto~=1)
                legendauto=1;
            end
            if legendauto==1
                app.AutoButton_2.Value=1;
                app.CustomButton_2.Value=0;
            else
                app.AutoButton_2.Value=0;
                app.CustomButton_2.Value=1;
            end
            try fontnames = legendparas{2};catch fontnames = 'Helvetica';end
            if isempty(fontnames)
                fontnames = 'Helvetica';
            end
            [C,IA] =ismember_bc2(fontnames,{'Courier','Geneva','Helvetica','Monaco','Times'});
            if IA==0;
                fontnames = 'Helvetica';
            end
            try fontsizes = legendparas{3};catch fontsizes = '12';end
            if isempty(fontsizes)
                fontsizes = '12';
            end
            [C1,IA1] =ismember_bc2(fontsizes,{'4','6','8','10','12','14','16','18','20','24','28','32','36',...
                '40','50','60','70','80','90','100'});
            if IA1==0;
                fontsizes = '12';
            end

            try columnsnum = legendparas{4};catch columnsnum = '2';end
            if isempty(columnsnum)
                columnsnum = '2';
            end
            [C2,IA2] =ismember_bc2(columnsnum,columnStr);
            if IA2==0;
                columnsnum = '2';
            else
                columnsnum = columnStr{IA2};
            end

            try legendcolors = legendparas{5};catch legendcolors=1;end

            if  app.AutoButton_2.Value==1
                enableflag = 'off';
                app.FontDropDown.Value='Helvetica';
                app.FontsizeDropDown.Value='12';
                app.AutoButton_3.Value=1;
                app.SameaslinesButton.Value=0;
            else
                enableflag = 'on';
                app.FontDropDown.Value= fontnames;
                app.FontsizeDropDown.Value=fontsizes;
                app.AutoButton_3.Value=legendcolors;
                app.SameaslinesButton.Value=~legendcolors;
            end
            app.columnDropDown.Value = columnsnum;

            app.FontDropDown.Enable = enableflag;
            app.FontsizeDropDown.Enable = enableflag;
            app.columnDropDown.Enable = enableflag;
            app.TextcolorButtonGroup.Enable = enableflag;

            app.Finishbutton = 0;
            app.Output = [];
        end

        % Callback function
        function reset_erpname(app, event)

        end

        % Button pushed function: CancelButton
        function cancel(app, event)
            app.Finishbutton = 1;
            app.Output = [];
        end

        % Button pushed function: ApplyButton
        function apply(app, event)

            Lineparas{1} =app.AutoButton.Value;
            Lineparas{2} =app.UITable.Data;

            legendparas{1} = app.AutoButton_2.Value;
            legendparas{2} =  app.FontDropDown.Value;
            legendparas{3} =  app.FontsizeDropDown.Value;
            legendparas{4} =  app.columnDropDown.Value;
            legendparas{5} =   app.AutoButton_3.Value;

            %%time axis
            fonttype = {'Courier','Geneva','Helvetica','Monaco','Times'};
            [C,IA] =ismember_bc2( app.Font_xaxis.Value,fonttype);
            if IA == 0
                IA = 3;
            end
            fontpars{1}=IA;

            try Fontsize_xaxis = fontpars{2}; catch Fontsize_xaxis = 5;  end
            fontsizes  = {'4','6','8','10','12','14','16','18','20','24','28','32','36',...
                '40','50','60','70','80','90','100'};
            [C,IA1] =ismember_bc2(  app.Fontsize_xaxis.Value ,fontsizes);
            if IA1 == 0
                IA1 = 5;
            end
            fontpars{2}=IA1;

            ytextColor = {'Black','Red','Blue','Green','Orange','Cyan','Magenla'};
            [C,IA2] =ismember_bc2(   app.Color_xaxis.Value ,ytextColor);
            if IA2 == 0;
                IA2 = 1;
            end
            fontpars{3}=IA2;

            %%amplitude axis
            [C,IA3] =ismember_bc2( app.Font_yaxis.Value,fonttype);
            if IA3 == 0;
                IA3 = 3;
            end
            fontpars{4}=IA3;

            [C,IA4] =ismember_bc2( app.Fontsize_yaxis.Value  ,fontsizes);
            if IA4 == 0;
                IA4 = 5;
            end
            fontpars{5}=IA4;

            [C,IA5] =ismember_bc2( app.Color_yaxis.Value,ytextColor);
            if IA5 == 0;
                IA5 = 1;
            end
            fontpars{6}=IA5;

            app.Finishbutton = 1;
            app.Output = {Lineparas,legendparas,fontpars};
        end

        % Cell edit callback: UITable
        function cell_edit_changes(app, event)
            indices = event.Indices;
            newData = event.NewData;
        end

        % Selection changed function: LegendButtonGroup
        function legend_changs(app, event)

            if  app.AutoButton_2.Value==1
                enableflag = 'off';
                app.FontDropDown.Value='Helvetica';
                app.FontsizeDropDown.Value='12';
                app.AutoButton_3.Value=1;
                app.SameaslinesButton.Value=0;
            else
                enableflag = 'on';
            end

            app.FontDropDown.Enable = enableflag;
            app.FontsizeDropDown.Enable = enableflag;
            app.columnDropDown.Enable = enableflag;
            app.TextcolorButtonGroup.Enable = enableflag;
        end

        % Selection changed function: LineSettingsButtonGroup
        function linsetsel(app, event)
            [lineNameStr,linecolors,linetypes,linewidths,linecolors_str,linetypes_str,linewidths_str,linecolorsrgb] = f_get_lineset_ERPviewer();
            lineset_str  =table(lineNameStr,linecolors,linetypes,linewidths);
            lineset_str = table2cell(lineset_str);
            if app.AutoButton.Value==1
                app.UITable.Enable = 'off';
                app.UITable.Data = lineset_str;
            else
                app.UITable.Enable = 'on';
                for ii = 1:length(linecolorsrgb)
                    app.UITable.Data{ii,2} = linecolorsrgb{ii};
                end
            end
            app.UITable.ColumnFormat = {'char', 'char',...
                {'solid','dash','dot','dashdot','plus','circle','asterisk'},...
                {'0.25','0.5','1','1.5','2','2.5','3','3.5','4','4.5','5','5.5','6','6.5','7','7.5','8','8.5','9','9.5','10','10.5'}};

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Original erpname'; 'New erpname'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @cell_edit_changes, true);
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @apply, true);
            app.ApplyButton.Text = 'Apply';
            app.LineSettingsButtonGroup = uibuttongroup(app.UIFigure);
            app.LineSettingsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @linsetsel, true);
            app.LineSettingsButtonGroup.Title = 'Line Settings';
            app.AutoButton = uiradiobutton(app.LineSettingsButtonGroup);
            app.AutoButton.Text = 'Auto';
            app.AutoButton.Value = true;
            app.CustomButton = uiradiobutton(app.LineSettingsButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.LegendSettingsPanel = uipanel(app.UIFigure);
            app.LegendSettingsPanel.Title = 'Legend Settings';
            app.LegendButtonGroup = uibuttongroup(app.LegendSettingsPanel);
            app.LegendButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @legend_changs, true);
            app.LegendButtonGroup.Title = 'Legend';
            app.AutoButton_2 = uiradiobutton(app.LegendButtonGroup);
            app.AutoButton_2.Text = 'Auto';
            app.AutoButton_2.Value = true;
            app.CustomButton_2 = uiradiobutton(app.LegendButtonGroup);
            app.CustomButton_2.Text = 'Custom';
            app.FontDropDownLabel = uilabel(app.LegendSettingsPanel);
            app.FontDropDownLabel.Text = 'Font';
            app.FontDropDown = uidropdown(app.LegendSettingsPanel);
            app.FontsizeDropDownLabel = uilabel(app.LegendSettingsPanel);
            app.FontsizeDropDownLabel.Text = 'Font size';
            app.FontsizeDropDown = uidropdown(app.LegendSettingsPanel);
            app.TextcolorButtonGroup = uibuttongroup(app.LegendSettingsPanel);
            app.TextcolorButtonGroup.Title = 'Text color';
            app.AutoButton_3 = uiradiobutton(app.TextcolorButtonGroup);
            app.AutoButton_3.Text = 'Auto';
            app.AutoButton_3.Value = true;
            app.SameaslinesButton = uiradiobutton(app.TextcolorButtonGroup);
            app.SameaslinesButton.Text = 'Same as lines';
            app.columnDropDown = uidropdown(app.LegendSettingsPanel);
            app.columnDropDownLabel = uilabel(app.LegendSettingsPanel);
            app.columnDropDownLabel.Text = 'Columns:';
            app.FontFontsizeandColorforTimeAmplitudeAxisesPanel = uipanel(app.UIFigure);
            app.FontFontsizeandColorforTimeAmplitudeAxisesPanel.Title = 'Font, Fontsize, and Color for Time & Amplitude Axises';
            app.TimeAxisLabel = uilabel(app.FontFontsizeandColorforTimeAmplitudeAxisesPanel);
            app.TimeAxisLabel.Text = 'Time Axis:';
            app.FontDropDown_2Label = uilabel(app.FontFontsizeandColorforTimeAmplitudeAxisesPanel);
            app.FontDropDown_2Label.Text = 'Font';
            app.Font_xaxis = uidropdown(app.FontFontsizeandColorforTimeAmplitudeAxisesPanel);
            app.Font_xaxis.Items = {'Courier', 'Geneva', 'Helvetica', 'Monaco', 'Times'};
            app.Font_xaxis.Value = 'Courier';
            app.FontsizeDropDown_2Label = uilabel(app.FontFontsizeandColorforTimeAmplitudeAxisesPanel);
            app.FontsizeDropDown_2Label.Text = 'Font size';
            app.Fontsize_xaxis = uidropdown(app.FontFontsizeandColorforTimeAmplitudeAxisesPanel);
            app.Fontsize_xaxis.Items = {'4', '6', '8', '10', '12', '14', '16', '18', '20', '24', '28', '32', '36 ', '40', '50', '60', '70', '80', '90', '100'};
            app.Fontsize_xaxis.Value = '4';
            app.ColorDropDownLabel = uilabel(app.FontFontsizeandColorforTimeAmplitudeAxisesPanel);
            app.ColorDropDownLabel.Text = 'Color';
            app.Color_xaxis = uidropdown(app.FontFontsizeandColorforTimeAmplitudeAxisesPanel);
            app.Color_xaxis.Items = {'Black', 'Red', 'Blue', 'Green', 'Orange', 'Cyan', 'Magenla'};
            app.Color_xaxis.Value = 'Black';
            app.AmplitudeAxisLabel = uilabel(app.FontFontsizeandColorforTimeAmplitudeAxisesPanel);
            app.AmplitudeAxisLabel.Text = 'Amplitude Axis:';
            app.FontDropDown_2Label_2 = uilabel(app.FontFontsizeandColorforTimeAmplitudeAxisesPanel);
            app.FontDropDown_2Label_2.Text = 'Font';
            app.Font_yaxis = uidropdown(app.FontFontsizeandColorforTimeAmplitudeAxisesPanel);
            app.Font_yaxis.Items = {'Courier', 'Geneva', 'Helvetica', 'Monaco', 'Times'};
            app.Font_yaxis.Value = 'Courier';
            app.FontsizeDropDown_2Label_2 = uilabel(app.FontFontsizeandColorforTimeAmplitudeAxisesPanel);
            app.FontsizeDropDown_2Label_2.Text = 'Font size';
            app.Fontsize_yaxis = uidropdown(app.FontFontsizeandColorforTimeAmplitudeAxisesPanel);
            app.Fontsize_yaxis.Items = {'4', '6', '8', '10', '12', '14', '16', '18', '20', '24', '28', '32', '36 ', '40', '50', '60', '70', '80', '90', '100'};
            app.Fontsize_yaxis.Value = '4';
            app.ColorDropDownLabel_2 = uilabel(app.FontFontsizeandColorforTimeAmplitudeAxisesPanel);
            app.ColorDropDownLabel_2.Text = 'Color';
            app.Color_yaxis = uidropdown(app.FontFontsizeandColorforTimeAmplitudeAxisesPanel);
            app.Color_yaxis.Items = {'Black', 'Red', 'Blue', 'Green', 'Orange', 'Cyan', 'Magenla'};
            app.Color_yaxis.Value = 'Black';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Decode_mvpc_line_legend_ops(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end