classdef Save_Confusion_file_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PathFileNamePanel            matlab.ui.container.Panel
        BrowseButton_2               matlab.ui.control.Button
        EditField                    matlab.ui.control.EditField
        PrecisionPanel               matlab.ui.container.Panel
        DecimalDropDown              matlab.ui.control.DropDown
        DecimalDropDownLabel         matlab.ui.control.Label
        PlotSettingsPanel            matlab.ui.container.Panel
        erromesg                     matlab.ui.control.Label
        EpochRange                   matlab.ui.control.EditField
        LatencytoplotEditFieldLabel  matlab.ui.control.Label
        etcLabel                     matlab.ui.control.Label
        plot_menu                    matlab.ui.control.DropDown
        ValuetoplotLabel             matlab.ui.control.Label
        MVPCSetsPanel                matlab.ui.container.Panel
        MVPCIndexEditField           matlab.ui.control.EditField
        MVPCIndexEditFieldLabel      matlab.ui.control.Label
        BrowseButton                 matlab.ui.control.Button
        ApplyButton                  matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
    end


    properties (Access = private)
    end

    properties (Access = public)
        Output % Description
        Finishbutton % Description
        ALLMVPC % Description
        MVPCArray % Description
        MVPC % Description
    end

    methods (Access = private)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            painterplabapp(app);
            setfonterplab(app);
            % Name & version
            %
            version = geterplabversion;
            set(app.UIFigure,'Name', ['Estudio ' version '   -   Save confusion matrix to a file GUI']);

            try
                ALLMVPC = varargin{1};
            catch
                ALLMVPC = [];
            end
            try
                MVPCArray = varargin{2};
            catch
                MVPCArray = [1];
            end
            app.MVPCArray = MVPCArray;
            try app.MVPC = ALLMVPC(MVPCArray(1)); catch app.MVPC = []; end
            MVPCArray = vect2colon(MVPCArray,'Sort', 'on');
            MVPCArray = erase(MVPCArray,{'[',']'});
            app.MVPCIndexEditField.Value = MVPCArray;
            app.ALLMVPC = ALLMVPC;

            try  plot_menu= varargin{3}{1}; catch  plot_menu =1; end
            plot_type = {'Timepoint Confusion Matrix', 'Average Confusion Matrix between two latencies'};

            app.plot_menu.Items = plot_type;
            app.plot_menu.Value = plot_type{plot_menu};
            if plot_menu == 1
                text_instruct = 'e.g., 300 to plot confusion matrix at 300ms or or 100:50:350 to plot at 100, 150, 200,...,350 ms' ;
                app.etcLabel.Text = text_instruct;

            elseif plot_menu == 2
                text_instruct = 'e.g., 200 250 to plot average confusion matrix across 200 to 250 ms';
                app.etcLabel.Text = text_instruct;
            end

            %%latency
            try  latencyvalues = varargin{3}{2}; catch  latencyvalues =[]; end
            latencyvalues = vect2colon(latencyvalues,'Sort', 'on');
            latencyvalues = erase(latencyvalues,{'[',']'});
            app.EpochRange.Value = latencyvalues;
            %%precision
            precisiontype = {'1','2','3','4','5','6'};
            app.DecimalDropDown.Items = precisiontype;
            app.plot_menu.BackgroundColor = [1 1 1];
            app.DecimalDropDown.BackgroundColor = [1 1 1];
            try precisionvalue = varargin{3}{3}; catch precisionvalue=3; end
            if isempty(precisionvalue) || numel(precisionvalue)~=1 || any(precisionvalue(:)<1) ||any(precisionvalue(:)>6)
                precisionvalue=3;
            end
            app.DecimalDropDown.Value = precisiontype{precisionvalue};

            try PathName = varargin{3}{4}; catch  PathName = [pwd,filesep,'Confusion_matrix']; end
            app.EditField.Value = PathName;

            if isempty(ALLMVPC)
                app.MVPCSetsPanel.Enable = 'off';
                app.PlotSettingsPanel.Enable = 'off';
                app.PrecisionPanel.Enable = 'off';
                app.PathFileNamePanel.Enable = 'off';
                app.ApplyButton.Enable = 'off';
            end
            app.Finishbutton = 0;
            app.Output = [];
        end

        % Callback function
        function reset_erpname(app, event)

        end

        % Button pushed function: CancelButton
        function cancel(app, event)
            app.Finishbutton = 1;
            app.Output = [];
        end

        % Button pushed function: ApplyButton
        function apply(app, event)
            MVPCArray = str2num(app.MVPCIndexEditField.Value);

            if isempty(MVPCArray) || any(MVPCArray(:)<1) || any(MVPCArray(:)>length(app.ALLMVPC))
                MVPCArray = 1:length(app.ALLMVPC);
                MVPCArray = vect2colon(MVPCArray,'Sort', 'on');
                MVPCArray = erase(MVPCArray,{'[',']'});
                app.MVPCIndexEditField.Value = MVPCArray;
                app.erromesg.Text = ['Index(es) of MVPCsets shuold be between 1 and',32,num2str(length(app.ALLMVPC))];
            end
            if  strcmpi(app.plot_menu.Value,'Timepoint Confusion Matrix')
                plot_menu=1;
            else
                plot_menu=2;
            end
            measure_latency = str2num(app.EpochRange.Value);
            if isempty(measure_latency)
                app.EpochRange.Value = '';
                app.erromesg.Text = ['The latency is empty'];
                return;
            end

            if any(measure_latency(:)<app.MVPC.times(1)) || any(measure_latency(:)>app.MVPC.times(end))
                app.EpochRange.Value = '';
                app.erromesg.Text =  ['The latency should be between ',32,num2tr(app.MVPC.times(1)),32,'and',32,num2tr(app.MVPC.times(2))];
                return;
            end
            if plot_menu==2
                if numel(measure_latency)~=2
                    app.erromesg.Text = ['The latency should be two numbers for "Average Confusion Matrix between two latencies".'];
                    return;
                end
            end
            Decimal = str2num(app.DecimalDropDown.Value);

            PathNames =  app.EditField.Value;
            [pathstr, erpfilename, ext] = fileparts(PathNames) ;
            if isempty(pathstr)
                pathstr = pwd;
            end
            if isempty(erpfilename)
                erpfilename = 'confusion_matrix';
            end
            if ~strcmpi(ext,'.txt') && ~strcmpi(ext,'.xls') && ~strcmpi(ext,'.xlsx')
                ext='.txt';
            end

            erpFilename = char(strcat(pathstr,filesep,erpfilename,ext));
            app.EditField.Value = erpFilename;

            app.Finishbutton = 1;
            app.Output = {MVPCArray,plot_menu,measure_latency,Decimal,erpFilename};
        end

        % Callback function
        function legend_changs(app, event)


        end

        % Callback function
        function linsetsel(app, event)

        end

        % Value changed function: plot_menu
        function plot_menuValueChanged(app, event)
            value = app.plot_menu.Value;
            plot_types = {'Timepoint Confusion Matrix', 'Average Confusion Matrix between two latencies'};
            idx = find(strcmp(plot_types, value));
            if idx == 1
                text_instruct = 'e.g., 300 to plot confusion matrix at 300ms or or 100:50:350 to plot at 100, 150, 200,...,350 ms' ;
                app.etcLabel.Text = text_instruct;

            elseif idx == 2
                text_instruct = 'e.g., 200 250 to plot average confusion matrix across 200 to 250 ms';
                app.etcLabel.Text = text_instruct;
            end
        end

        % Button pushed function: BrowseButton_2
        function Browse_path(app, event)
            PathNames =  app.EditField.Value;

            [pathstr, erpfilename, ext] = fileparts(PathNames) ;
            if isempty(erpfilename)
                erpfilename = 'confusion_matrix';
            end
            if isempty(pathstr)
                pathstr = pwd;
            end
            erpFilename = char(strcat(pathstr,filesep,erpfilename));
            formatname = {'*.txt';'*.xls';'*.xlsx'};

            [erpfilename, erppathname, indxs] = uiputfile(formatname, ...
                ['Export confusion matrix'],...
                erpFilename);
            if isequal(erpfilename,0)
                return
            end
            if isequal(erpfilename,0)
                return
            end

            [pathstr, erpfilename, ext] = fileparts(erpfilename) ;
            if ~strcmpi(ext,'.txt') && ~strcmpi(ext,'.xls') && ~strcmpi(ext,'.xlsx')
                ext='.txt';
            end

            erpFilename = char(strcat(erppathname,erpfilename,ext));
            app.EditField.Value = erpFilename;
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: BrowseButton
        function browse_mvpc(app, event)

            for Numofbin = 1:length(app.ALLMVPC)
                try
                    listb{Numofbin} = app.ALLMVPC(Numofbin).mvpcname;
                catch
                    listb{Numofbin} = char(['MVPC',32,num2str(Numofbin)]);
                end
            end
            try
                indxlistb = 1:length(app.ALLMVPC);
            catch
                app.UIFigure.Visible = 'off';
                app.UIFigure.Visible = 'on';
                return;
            end
            titlename = 'Select MVPC(es):';
            if ~isempty(listb)
                MVPCselect = browsechanbinGUI(listb, indxlistb, titlename);
                if ~isempty(MVPCselect)
                    binset = vect2colon(MVPCselect,'Sort', 'on');
                    binset = erase(binset,{'[',']'});
                    app.MVPCIndexEditField.Value=binset;
                end
            else
                app.UIFigure.Visible = 'off';
                app.UIFigure.Visible = 'on';
                return;
            end
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: MVPCIndexEditField
        function mvpc_edit(app, event)
            MVPCEdit = str2num(app.MVPCIndexEditField.Value);
            if isempty(MVPCEdit) || any(MVPCEdit(:)<1) || any(MVPCEdit(:)>length(app.ALLMVPC))
                MVPCArray = 1:length(app.ALLMVPC);
                MVPCArray = vect2colon(MVPCArray,'Sort', 'on');
                MVPCArray = erase(MVPCArray,{'[',']'});
                app.MVPCIndexEditField.Value = MVPCArray;
                app.erromesg.Text = ['Index(es) of MVPCsets shuold be between 1 and',32,num2str(length(app.ALLMVPC))];
            end
        end

        % Value changed function: EpochRange
        function latency_changed(app, event)
            value = app.EpochRange.Value;
            kktime = 1000; %change from s to ms
            %reset working bestfile
            MVPC = app.MVPC;
            orig_times = MVPC.times;
            epoch_window = [orig_times(1) orig_times(end)];
            fs = MVPC.srate;

            xxlim = str2num(value);
            if strcmpi(app.plot_menu.Value,'Average Confusion Matrix between two latencies')
                if isempty(xxlim) | length(xxlim) <2
                    app.erromesg.Text =  'You have not specified a time range';
                end
            end

            if ~strcmpi(app.plot_menu.Value,'Average Confusion Matrix between two latencies') %single timepoint(s)
                if numel(xxlim) == 1
                    %only one timepoint
                    if xxlim < round(epoch_window(1),2)
                        tscale = epoch_window(1); %change from s to ms
                        %tscale(2) = MVPC.xmax * 1000; %change from s to ms
                        edit_time_range = sprintf('%.2f', tscale(1));
                        app.EpochRange.Value = edit_time_range;
                        aux_xxlim = tscale;
                    elseif ~ismember(xxlim, round(orig_times,2))
                        [value,ind] = closest(orig_times,xxlim);
                        tscale = value ; %change from s to ms
                        edit_time_range = sprintf('%.0f', tscale);
                        app.EpochRange.Value = edit_time_range;
                        aux_xxlim = value;
                    else
                        aux_xxlim = xxlim;
                    end
                else
                    %range of timepoints are input
                    if xxlim(1) < round(epoch_window(1),2)
                        % Revert to default time range
                        xxlim(1) = epoch_window(1); %change from s to ms
                        edit_time_range = sprintf('%.2f', xxlim(1));
                        app.EpochRange.Value = edit_time_range;
                    end
                    check_vals = ismember(xxlim, round(orig_times,2));
                    z = nnz(~check_vals);
                    if z > 0
                        [value,ind] = closest(orig_times,xxlim);
                        tscale = mat2colon(value,'delimiter','off');  ; %change from s to ms
                        edit_time_range = sprintf('%s', tscale);
                        app.EpochRange.Value = edit_time_range;
                        %  epoch_window = [tscale(1) tscale(2)];
                        aux_xxlim = value;
                    else
                        aux_xxlim = xxlim;
                    end
                end
            else
                %aveerage across two timepoints
                if xxlim(1) < round(epoch_window(1),2)
                    tscale(1) = epoch_window(1); %change from s to ms
                    edit_time_range = sprintf('%.2f %.2f', tscale(1), xxlim(2));
                    app.EpochRange.Value = edit_time_range;
                    aux_xxlim(1) = tscale(1);
                    %aux_xxlim(1) = round(MVPC.xmin*kktime);
                elseif ~ismember(xxlim(1), round(orig_times,2))
                    [value,ind] = closest(orig_times,xxlim(1));
                    tscale(1) = value ;
                    tscale(2) = xxlim(2); %change from s to ms
                    edit_time_range = sprintf('%.2f %.2f', tscale(1), tscale(2));
                    app.EpochRange.Value = edit_time_range;
                    epoch_window = [tscale(1) tscale(2)];
                    aux_xxlim(1) = value;
                else
                    aux_xxlim(1) = xxlim(1);
                end

                if xxlim(2)> round(epoch_window(end),2)
                    tscale(2) = epoch_window(end); %change from s to ms
                    edit_time_range = sprintf('%.2f %.2f', aux_xxlim(1), tscale(2));
                    app.EpochRange.Value = edit_time_range;
                    aux_xxlim(2)=  tscale(2);
                elseif ~ismember(xxlim(2), round(orig_times,2))
                    [value,ind] = closest(orig_times,xxlim(2));
                    tscale(1) = aux_xxlim(1) ;
                    tscale(2) = value ; %change from s to ms
                    edit_time_range = sprintf('%.2f %.2f', tscale(1), tscale(2));
                    app.EpochRange.Value = edit_time_range;
                    epoch_window = [tscale(1) tscale(2)];
                    aux_xxlim(2) = value;
                else
                    aux_xxlim(2) = xxlim(2);
                end
            end

            if strcmpi(app.plot_menu.Value,'Average Confusion Matrix between two latencies')
                [xp1, xp2, checkw] = window2sample(MVPC, aux_xxlim(1:2) , fs, 'relaxed');
                if checkw==1
                    app.erromesg.Text  =  'Time window cannot be larger than epoch.';
                    return
                elseif checkw==2
                    app.erromesg.Text =  'Too narrow time window (are the start and end times reversed?)';
                    app.EpochRange.Value = '';
                    return
                end
            end
            %saveoutput %display
            if numel(aux_xxlim)>2
                tscale = mat2colon(aux_xxlim,'delimiter','off');
                edit_time_range = sprintf('%s', tscale);
                app.EpochRange.Value = edit_time_range;
            else
                app.EpochRange.Value = num2str(aux_xxlim);
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @apply, true);
            app.ApplyButton.Text = 'Apply';
            app.MVPCSetsPanel = uipanel(app.UIFigure);
            app.MVPCSetsPanel.Title = 'MVPC Sets';
            app.BrowseButton = uibutton(app.MVPCSetsPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @browse_mvpc, true);
            app.BrowseButton.Text = 'Browse';
            app.MVPCIndexEditFieldLabel = uilabel(app.MVPCSetsPanel);
            app.MVPCIndexEditFieldLabel.Text = 'MVPC Index:';
            app.MVPCIndexEditField = uieditfield(app.MVPCSetsPanel, 'text');
            app.MVPCIndexEditField.ValueChangedFcn = createCallbackFcn(app, @mvpc_edit, true);
            app.PlotSettingsPanel = uipanel(app.UIFigure);
            app.PlotSettingsPanel.Title = 'Plot Settings';
            app.ValuetoplotLabel = uilabel(app.PlotSettingsPanel);
            app.ValuetoplotLabel.Text = 'Value to plot:';
            app.plot_menu = uidropdown(app.PlotSettingsPanel);
            app.plot_menu.ValueChangedFcn = createCallbackFcn(app, @plot_menuValueChanged, true);
            app.etcLabel = uilabel(app.PlotSettingsPanel);
            app.etcLabel.Text = 'etc';
            app.LatencytoplotEditFieldLabel = uilabel(app.PlotSettingsPanel);
            app.LatencytoplotEditFieldLabel.Text = 'Latency to plot:';
            app.EpochRange = uieditfield(app.PlotSettingsPanel, 'text');
            app.EpochRange.ValueChangedFcn = createCallbackFcn(app, @latency_changed, true);
            app.erromesg = uilabel(app.PlotSettingsPanel);
            app.erromesg.Text = '';
            app.PrecisionPanel = uipanel(app.UIFigure);
            app.PrecisionPanel.Title = 'Precision';
            app.DecimalDropDownLabel = uilabel(app.PrecisionPanel);
            app.DecimalDropDownLabel.Text = 'Decimal';
            app.DecimalDropDown = uidropdown(app.PrecisionPanel);
            app.PathFileNamePanel = uipanel(app.UIFigure);
            app.PathFileNamePanel.Title = 'Path & File Name';
            app.EditField = uieditfield(app.PathFileNamePanel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.BrowseButton_2 = uibutton(app.PathFileNamePanel, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @Browse_path, true);
            app.BrowseButton_2.Text = 'Browse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Save_Confusion_file_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end