classdef estudio_decode_mvpa_ops < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AlgorithmLabel                matlab.ui.control.Label
        ParallelizationButtonGroup    matlab.ui.container.ButtonGroup
        NoButton                      matlab.ui.control.RadioButton
        YesButton                     matlab.ui.control.RadioButton
        TimeParametersPanel           matlab.ui.container.Panel
        Timepoint0msisalwaysincludedLabel  matlab.ui.control.Label
        EffectiveSamplingRateHzLabel  matlab.ui.control.Label
        EffectiveSamplingRateHzEditField  matlab.ui.control.NumericEditField
        DecodeeveryTmsSpinner         matlab.ui.control.Spinner
        DecodeeveryTmsSpinnerLabel    matlab.ui.control.Label
        DownsampleOptionalCheckBox    matlab.ui.control.CheckBox
        DecodingTimeRangeButtonGroup  matlab.ui.container.ButtonGroup
        EpochRange                    matlab.ui.control.EditField
        minmaxinmsLabel               matlab.ui.control.Label
        CustomButton                  matlab.ui.control.RadioButton
        PostButton                    matlab.ui.control.RadioButton
        PreButton                     matlab.ui.control.RadioButton
        AllButton                     matlab.ui.control.RadioButton
        CodingSVMOnlyButtonGroup      matlab.ui.container.ButtonGroup
        OnevsOneButton                matlab.ui.control.RadioButton
        OnevsAllButton                matlab.ui.control.RadioButton
        MethodButtonGroup             matlab.ui.container.ButtonGroup
        regulariz_LDA                 matlab.ui.control.NumericEditField
        regulariz_SVM                 matlab.ui.control.NumericEditField
        RegularizationLabel           matlab.ui.control.Label
        LDAButton                     matlab.ui.control.RadioButton
        CrossvalidatedMahalanobisButton  matlab.ui.control.RadioButton
        SVMButton                     matlab.ui.control.RadioButton
        OkayButton                    matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
    end


    properties (Access = public)
        output % Description
        Finishbutton % Description
        BEST % Description
        BEST_working % Description
    end

    methods (Access = private)


    end

    methods (Access = public)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            painterplabapp(app);
            setfonterplab(app);
            % Name & version
            %
            version = geterplabversion;
            set(app.UIFigure,'Name', ['ERPLAB ' version '   -   EStudio Multivariate Pattern Classification options GUI']);
            try
                BEST = varargin{1};
            catch
                BEST = [];
            end

            app.EffectiveSamplingRateHzEditField.Enable = 'off';
            app.DecodeeveryTmsSpinner.BackgroundColor = [1 1 1];
            if isempty(BEST)
                app.MethodButtonGroup.Enable = 'off';
                app.CodingSVMOnlyButtonGroup.Enable = 'off';
                app.TimeParametersPanel.Enable = 'off';
                app.ParallelizationButtonGroup.Enable = 'off';
                return;
            end
            app.BEST = BEST;
            app.BEST_working.xmin = BEST.xmin;
            app.BEST_working.xmax = BEST.xmax;
            app.BEST_working.srate = BEST.srate;
            app.BEST_working.pnts = BEST.pnts;
            app.BEST_working.times = BEST.times;
            app.DecodeeveryTmsSpinner.Step = 1000/BEST.srate;
            try
                def = varargin{2};
            catch
                def = [];
            end
            %%Method--1: SVM /2: LDA;  3: Crossnobis - def: SVM
            try MethodFlag = def{1};catch MethodFlag=1; end
            try      regularizationvalues = def{7}; catch  regularizationvalues = []; end
            if MethodFlag==2;%%LDA
                app.SVMButton.Value=0;
                app.LDAButton.Value=1;
                app.CrossvalidatedMahalanobisButton.Value=0;
                app.DecodeeveryTmsSpinner.Enable = 'on';
                app.CodingSVMOnlyButtonGroup.Enable = 'off';
                if isempty(regularizationvalues) || numel(regularizationvalues)~=1 || any(regularizationvalues<0) || any(regularizationvalues>1)
                    app.regulariz_LDA.Value = 0;
                else
                    app.regulariz_LDA.Value = regularizationvalues;
                end
                app.regulariz_SVM.Enable = 'off';
                app.regulariz_LDA.Enable = 'on';
            elseif MethodFlag==3
                app.SVMButton.Value=0;
                app.LDAButton.Value=0;
                app.CrossvalidatedMahalanobisButton.Value=1;
                app.DecodeeveryTmsSpinner.Enable = 'off';
                app.CodingSVMOnlyButtonGroup.Enable = 'off';
                app.regulariz_SVM.Enable = 'off';
                app.regulariz_LDA.Enable = 'on';
                app.regulariz_SVM.Value = 1;
            else%%SVM
                app.SVMButton.Value=1;
                app.LDAButton.Value=0;
                app.CrossvalidatedMahalanobisButton.Value=0;
                app.DecodeeveryTmsSpinner.Enable = 'on';
                app.CodingSVMOnlyButtonGroup.Enable = 'on';
                if isempty(regularizationvalues) || numel(regularizationvalues)~=1 || any(regularizationvalues<=0)
                    app.regulariz_SVM.Value = 1;
                else
                    app.regulariz_SVM.Value = regularizationvalues;
                end
                app.regulariz_SVM.Enable = 'on';
                app.regulariz_LDA.Enable = 'off';
            end
            lab_text = ['By default, the regularization parameter in SVM is 1. This parameter  must be a positive number.'];
            app.SVMButton.Tooltip = lab_text;%%GH Oct 20 2025

            lab_text = ['By default, the regularization parameter in LDA is 0 which means there is no regularization.This parameter should be between 0 and 1.'];
            app.LDAButton.Tooltip = lab_text;%%GH Oct 20 2025

            %%SVM coding (1: 1vs1 / 2: 1vsAll or empty - def: 1vsALL)
            try codemethod =def{2}; catch codemethod=2;  end
            if codemethod==1
                app.OnevsOneButton.Value=1;
                app.OnevsAllButton.Value=0;
            else
                app.OnevsOneButton.Value=0;
                app.OnevsAllButton.Value=1;
            end

            %need to further confirm
            times =  BEST.times;
            tm_zero = find(times==0);
            try decode_every_Npoint = def{5};catch  decode_every_Npoint=1;end
            if isempty(decode_every_Npoint) || numel(decode_every_Npoint)~=1 || any(decode_every_Npoint(:)<1) || any(decode_every_Npoint(:)>tm_zero-1)
                decode_every_Npoint=1;
            end
            %             app.DecodeeveryTmsSpinner.Limits(1) = 1000/BEST.srate;
            if decode_every_Npoint==1
                app.DecodeeveryTmsSpinner.LowerLimitInclusive = 0;
                app.DownsampleOptionalCheckBox.Value=0;
            else
                app.DecodeeveryTmsSpinner.LowerLimitInclusive = 1;
                app.DownsampleOptionalCheckBox.Value=1;
            end
            if app.DownsampleOptionalCheckBox.Value==1
                app.DecodeeveryTmsSpinner.Enable = 'on';
            else
                app.DecodeeveryTmsSpinner.Enable = 'off';
            end

            decodevalues =  decode_every_Npoint*1000/BEST.srate;
            app.DecodeeveryTmsSpinner.Value = decodevalues;

            npnts_old = BEST.pnts;
            fs = BEST.srate;
            samps = 1/fs; %granularity of data sample in ms
            epochtime = (samps*npnts_old) * 1000 ; %ms
            npnts = round(epochtime/decodevalues); %can't have intermediate points
            new_FS = (npnts/epochtime * 1000);

            %save changes
            set(app.EffectiveSamplingRateHzEditField, 'Value', new_FS);

            value_in_samples = decodevalues/(samps*1000);

            %start find indexes forward and backward to zero
            tm_zero_to_end = (tm_zero:value_in_samples:length(times));
            tm_zero_to_begin= (tm_zero-value_in_samples:-value_in_samples:1);
            decoding_times_index = sort(unique_bc2([tm_zero_to_begin tm_zero_to_end]));

            app.BEST_working.Decode_every_Npoint = value_in_samples;
            app.BEST_working.decoding_times_index = decoding_times_index; %save to app

            %change epoch decoding window
            new_window = [times(decoding_times_index(1)) times(decoding_times_index(end))];
            edit_time_range = sprintf('%.2f %.2f', new_window(1), new_window(2));
            app.EpochRange.Value = edit_time_range;
            app.BEST_working.epoch_window = new_window;

            app.BEST_working.xmin = new_window(1)/1000;
            app.BEST_working.xmax = new_window(2)/1000;
            app.BEST_working.srate = new_FS;
            app.BEST_working.pnts = length(decoding_times_index);
            app.BEST_working.times = times(decoding_times_index);

            app.BEST_working.epoch_window = [BEST.times(1),BEST.times(end)];
            app.BEST_working.Decode_every_Npoint = value_in_samples;
            app.BEST_working.decoding_times_index = decoding_times_index; %save to app

            %%Decoding Time Range
            %%-----epochTimes (1:all, 2: pre, 3: post, 4:custom)
            try epochTimes =def{3}; catch epochTimes=1;  end
            try  decodeTimes = def{4}*1000; catch decodeTimes = [];end
            epochTimesValues = [0 0 0 0];
            TimeRangeEnable = 'off';
            if epochTimes==2%%Pre
                epochTimesValues(2) =1;
                EpochRange = [app.BEST_working.times(1),0];
            elseif epochTimes==3%%post
                epochTimesValues(3) =1;
                EpochRange = [0,app.BEST_working.times(end)];
            elseif epochTimes==4%%custom
                epochTimesValues(4) =1; %%all
                TimeRangeEnable = 'on';
                if isempty(decodeTimes) || numel(decodeTimes)~=2 || any(decodeTimes(:)<app.BEST_working.times(1)) || any(decodeTimes(:)>app.BEST_working.times(end)) || decodeTimes(1)>=decodeTimes(2)
                    decodeTimes = [app.BEST_working.times(1),app.BEST_working.times(end)] ;
                end
                EpochRange = decodeTimes;
            else
                epochTimesValues(1) =1;
                EpochRange = [app.BEST_working.times(1),app.BEST_working.times(end)];
            end
            app.EpochRange.Enable = TimeRangeEnable;
            app.AllButton.Value = epochTimesValues(1);
            app.PreButton.Value = epochTimesValues(2);
            app.PostButton.Value = epochTimesValues(3);
            app.CustomButton.Value = epochTimesValues(4);
            app.EpochRange.Value = sprintf('%.2f %.2f', EpochRange(1), EpochRange(2));

            %%para: parCompute (def = 0)
            try Paradef = def{6};catch Paradef =0; end
            if isempty(Paradef) || numel(Paradef)~=1 || (Paradef~=0 && Paradef~=1)
                Paradef=0;
            end
            if Paradef==1
                app.NoButton.Value = 0;
                app.YesButton.Value = 1;
            else
                app.NoButton.Value = 1;
                app.YesButton.Value = 0;
            end

        end

        % Button pushed function: CancelButton
        function cancel_button(app, event)
            app.output =[];
            app.Finishbutton = 1;
        end

        % Button pushed function: OkayButton
        function button_continue(app, event)
            if app.LDAButton.Value == 1
                method = 2; %LDA
                output{7} = app.regulariz_LDA.Value;
            elseif app.CrossvalidatedMahalanobisButton.Value == 1
                method = 3; %crossnobis
                output{7} = [];
            else
                method = 1; %svm
                output{7} = app.regulariz_SVM.Value;
            end
            output{1} = method;
            if method == 1
                if app.OnevsOneButton.Value == 1
                    SVMcoding = 1;
                elseif app.OnevsAllButton.Value == 1
                    SVMcoding = 2;
                end
            else
                SVMcoding = 0; %not svm
            end
            output{2} = SVMcoding;


            if app.AllButton.Value == 1
                epochTimes = 1;
            elseif app.PreButton.Value == 1
                epochTimes = 2;
            elseif app.PostButton.Value == 1
                epochTimes = 3;
            else %custom
                epochTimes = 4;
            end
            output{3} = epochTimes;

            decodeTimes = str2num(app.EpochRange.Value)/1000;
            if isempty(decodeTimes) || numel(decodeTimes)~=2 || any(decodeTimes(:)<app.BEST_working.xmin) || any(decodeTimes(:)>app.BEST_working.xmax)
                decodeTimes(1) = app.BEST_working.xmin; %in s;
                decodeTimes(2) =  app.BEST_working.xmax; %in s
                app.EpochRange.Value = sprintf('%.2f %.2f', decodeTimes(1)*1000, decodeTimes(2)*1000);
            end
            output{4} = decodeTimes;
            if app.DownsampleOptionalCheckBox.Value==0
             output{5} = 1;
            else
            output{5} = app.BEST_working.Decode_every_Npoint;
            end
            ParCompute = double(app.YesButton.Value);
            output{6} = ParCompute;


            app.output =output;
            app.Finishbutton = 1;
        end

        % Selection changed function: DecodingTimeRangeButtonGroup
        function time_range_select(app, event)
            selectedButton = app.DecodingTimeRangeButtonGroup.SelectedObject;
            if strcmpi(selectedButton.Text,'custom')
                app.EpochRange.Enable = 'on';
            else
                app.EpochRange.Enable = 'off';
            end
            if app.AllButton.Value==1
                app.EpochRange.Value = sprintf('%.2f %.2f', app.BEST_working.times(1), app.BEST_working.times(end));
            elseif app.PreButton.Value==1
                app.EpochRange.Value = sprintf('%.2f %.2f', app.BEST_working.times(1), 0);
            elseif app.PostButton.Value==1
                app.EpochRange.Value = sprintf('%.2f %.2f', 0, app.BEST_working.times(end));
            end
        end

        % Value changed function: DecodeeveryTmsSpinner
        function TPspinnerValueChanged(app, event)
            value = app.DecodeeveryTmsSpinner.Value;
            %check value if valid from standard
            times = app.BEST.times;
            tm_zero = find(times==0);
            if value<= 1000/app.BEST.srate
                app.DecodeeveryTmsSpinner.LowerLimitInclusive = 0;
                app.DecodeeveryTmsSpinner.Value = 1000/app.BEST.srate;
                value = 1000/app.BEST.srate;
            else
                app.DecodeeveryTmsSpinner.LowerLimitInclusive = 1;
            end

            if value>(tm_zero-1)*1000/app.BEST.srate
                app.DecodeeveryTmsSpinner.UpperLimitInclusive=0;
                value = (tm_zero-1)*1000/app.BEST.srate;
            else
                app.DecodeeveryTmsSpinner.UpperLimitInclusive=1;
            end


            % change "EFFECTIVE SAMPLING RATE" window from standard
            BEST = app.BEST;
            npnts_old = BEST.pnts;
            fs = BEST.srate;
            samps = 1/fs; %granularity of data sample in ms
            epochtime = (samps*npnts_old) * 1000 ; %ms
            npnts = round(epochtime/value); %can't have intermediate points
            new_FS = (npnts/epochtime * 1000);

            %save changes
            set(app.EffectiveSamplingRateHzEditField, 'Value', new_FS);

            %Make sure resampling includes 0 tp
            %find time zero

            value_in_samples = value/(samps*1000);

            %start find indexes forward and backward to zero
            tm_zero_to_end = (tm_zero:value_in_samples:length(times));
            tm_zero_to_begin= (tm_zero-value_in_samples:-value_in_samples:1);
            decoding_times_index = sort(unique_bc2([tm_zero_to_begin tm_zero_to_end]));

            app.BEST_working.Decode_every_Npoint = value_in_samples;
            app.BEST_working.decoding_times_index = decoding_times_index; %save to app

            %change epoch decoding window
            new_window = [times(decoding_times_index(1)) times(decoding_times_index(end))];
            app.BEST_working.times = times(decoding_times_index);
            if app.AllButton.Value==1
                app.EpochRange.Value = sprintf('%.2f %.2f', app.BEST_working.times(1), app.BEST_working.times(end));
            elseif app.PreButton.Value==1
                app.EpochRange.Value = sprintf('%.2f %.2f', app.BEST_working.times(1), 0);
            elseif app.PostButton.Value==1
                app.EpochRange.Value = sprintf('%.2f %.2f', 0, app.BEST_working.times(end));
            elseif app.CustomButton.Value==1
                EpochRange = str2num(app.EpochRange.Value);
                if isempty(EpochRange) || numel(EpochRange)~=2 || EpochRange(1)>= EpochRange(2)
                    EpochRange = [app.BEST_working.times(1), app.BEST_working.times(end)];
                end
                [xxx, latsamp, latdiffms] = closest(app.BEST_working.times, EpochRange);
                try
                    app.EpochRange.Value = sprintf('%.2f %.2f', app.BEST_working.times(latsamp(1)), app.BEST_working.times(latsamp(2)));
                catch
                    app.EpochRange.Value = sprintf('%.2f %.2f', app.BEST_working.times(1), app.BEST_working.times(end));
                end
            end
            app.BEST_working.epoch_window = new_window;
            app.BEST_working.xmin = new_window(1)/1000;
            app.BEST_working.xmax = new_window(2)/1000;
            app.BEST_working.srate = new_FS;
            app.BEST_working.pnts = length(decoding_times_index);
        end

        % Value changed function: DownsampleOptionalCheckBox
        function DownsampleOptionalCheckBoxValueChanged(app, event)
            value = app.DownsampleOptionalCheckBox.Value;
            if value==1
                app.DecodeeveryTmsSpinner.Enable = 'on';
                value = app.DecodeeveryTmsSpinner.Value;
                %check value if valid from standard
                times = app.BEST.times;
                tm_zero = find(times==0);
                if value<= 1000/app.BEST.srate
                    app.DecodeeveryTmsSpinner.LowerLimitInclusive = 0;
                    app.DecodeeveryTmsSpinner.Value = 1000/app.BEST.srate;
                    value = 1000/app.BEST.srate;
                else
                    app.DecodeeveryTmsSpinner.LowerLimitInclusive = 1;
                end
            else
                app.DecodeeveryTmsSpinner.Enable = 'off';
            end
        end

        % Selection changed function: MethodButtonGroup
        function decode_methods(app, event)
            if  app.SVMButton.Value==1  || app.LDAButton.Value==1
                app.CodingSVMOnlyButtonGroup.Enable = 'on';
                if  app.SVMButton.Value==1
                    regularizvalue = app.regulariz_SVM.Value;
                    if isempty(regularizvalue) || numel(regularizvalue)~=1 || any(regularizvalue<=0)
                        msgboxText =  'The regularization parameter for SVM should be a  positive scalar. The default one of 1 will be used.';
                        title = 'ERPLAB Studio: ERP decoding Options';
                        errorfound(msgboxText, title);
                        app.regulariz_SVM.Value = 1;
                    end
                    app.regulariz_SVM.Enable = 'on';
                    app.regulariz_LDA.Enable = 'off';
                else
                    regularizationvalues = app.regulariz_LDA.Value;
                    if isempty(regularizationvalues) || numel(regularizationvalues)~=1 || any(regularizationvalues<0) || any(regularizationvalues>1)
                        msgboxText =  'The regularization parameter for LDA should be a  positive scalar and between 0 and 1. The default one of 0 will be used.';
                        title = 'ERPLAB Studio: ERP decoding Options';
                        errorfound(msgboxText, title);
                        app.regulariz_LDA.Value = 0;
                    end
                    app.regulariz_SVM.Enable = 'off';
                    app.regulariz_LDA.Enable = 'on';
                    app.CodingSVMOnlyButtonGroup.Enable = 'off';
                end
            else
                app.CodingSVMOnlyButtonGroup.Enable = 'off';
                app.regulariz_SVM.Enable = 'off';
                app.regulariz_LDA.Enable = 'off';
            end

        end

        % Value changed function: regulariz_SVM
        function f_regulariz_svm(app, event)
            regularizvalue = app.regulariz_SVM.Value;
            if isempty(regularizvalue) || numel(regularizvalue)~=1 || any(regularizvalue<=0)
                msgboxText =  'The regularization parameter for SVM should be a  positive scalar. The default one of 1 will be used.';
                title = 'ERPLAB Studio: ERP decoding Options';
                errorfound(msgboxText, title);
                app.regulariz_SVM.Value = 1;
            end
        end

        % Value changed function: regulariz_LDA
        function f_regulariz_lda(app, event)
            regularizationvalues = app.regulariz_LDA.Value;
            if isempty(regularizationvalues) || numel(regularizationvalues)~=1 || any(regularizationvalues<0) || any(regularizationvalues>1)
                msgboxText =  'The regularization parameter for LDA should be a  positive scalar and between 0 and 1. The default one of 0 will be used.';
                title = 'ERPLAB Studio: ERP decoding Options';
                errorfound(msgboxText, title);
                app.regulariz_LDA.Value = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel_button, true);
            app.CancelButton.Text = 'Cancel';
            app.OkayButton = uibutton(app.UIFigure, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @button_continue, true);
            app.OkayButton.Text = 'Okay';
            app.MethodButtonGroup = uibuttongroup(app.UIFigure);
            app.MethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @decode_methods, true);
            app.MethodButtonGroup.Title = 'Method';
            app.SVMButton = uiradiobutton(app.MethodButtonGroup);
            app.SVMButton.Text = 'SVM';
            app.SVMButton.Value = true;
            app.CrossvalidatedMahalanobisButton = uiradiobutton(app.MethodButtonGroup);
            app.CrossvalidatedMahalanobisButton.Text = 'Crossvalidated Mahalanobis';
            app.LDAButton = uiradiobutton(app.MethodButtonGroup);
            app.LDAButton.Text = 'LDA';
            app.RegularizationLabel = uilabel(app.MethodButtonGroup);
            app.RegularizationLabel.Text = 'Regularization';
            app.regulariz_SVM = uieditfield(app.MethodButtonGroup, 'numeric');
            app.regulariz_SVM.ValueChangedFcn = createCallbackFcn(app, @f_regulariz_svm, true);
            app.regulariz_LDA = uieditfield(app.MethodButtonGroup, 'numeric');
            app.regulariz_LDA.ValueChangedFcn = createCallbackFcn(app, @f_regulariz_lda, true);
            app.CodingSVMOnlyButtonGroup = uibuttongroup(app.UIFigure);
            app.CodingSVMOnlyButtonGroup.Title = 'Coding (SVM Only)';
            app.OnevsAllButton = uiradiobutton(app.CodingSVMOnlyButtonGroup);
            app.OnevsAllButton.Text = 'One vs All';
            app.OnevsAllButton.Value = true;
            app.OnevsOneButton = uiradiobutton(app.CodingSVMOnlyButtonGroup);
            app.OnevsOneButton.Text = 'One vs One';
            app.TimeParametersPanel = uipanel(app.UIFigure);
            app.TimeParametersPanel.Title = 'Time Parameters';
            app.DecodingTimeRangeButtonGroup = uibuttongroup(app.TimeParametersPanel);
            app.DecodingTimeRangeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @time_range_select, true);
            app.DecodingTimeRangeButtonGroup.Title = 'Decoding Time Range';
            app.AllButton = uiradiobutton(app.DecodingTimeRangeButtonGroup);
            app.AllButton.Text = 'All';
            app.AllButton.Value = true;
            app.PreButton = uiradiobutton(app.DecodingTimeRangeButtonGroup);
            app.PreButton.Text = 'Pre';
            app.PostButton = uiradiobutton(app.DecodingTimeRangeButtonGroup);
            app.PostButton.Text = 'Post';
            app.CustomButton = uiradiobutton(app.DecodingTimeRangeButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.minmaxinmsLabel = uilabel(app.DecodingTimeRangeButtonGroup);
            app.minmaxinmsLabel.Text = '(min, max in ms)';
            app.EpochRange = uieditfield(app.DecodingTimeRangeButtonGroup, 'text');
            app.DownsampleOptionalCheckBox = uicheckbox(app.TimeParametersPanel);
            app.DownsampleOptionalCheckBox.ValueChangedFcn = createCallbackFcn(app, @DownsampleOptionalCheckBoxValueChanged, true);
            app.DownsampleOptionalCheckBox.Text = 'Downsample (Optional)';
            app.DecodeeveryTmsSpinnerLabel = uilabel(app.TimeParametersPanel);
            app.DecodeeveryTmsSpinnerLabel.Text = 'Decode every T (ms)';
            app.DecodeeveryTmsSpinner = uispinner(app.TimeParametersPanel);
            app.DecodeeveryTmsSpinner.ValueChangedFcn = createCallbackFcn(app, @TPspinnerValueChanged, true);
            app.EffectiveSamplingRateHzEditField = uieditfield(app.TimeParametersPanel, 'numeric');
            app.EffectiveSamplingRateHzLabel = uilabel(app.TimeParametersPanel);
            app.EffectiveSamplingRateHzLabel.Text = 'Effective Sampling Rate (Hz)';
            app.Timepoint0msisalwaysincludedLabel = uilabel(app.TimeParametersPanel);
            app.Timepoint0msisalwaysincludedLabel.Text = 'Time-point 0 (ms) is always included';
            app.ParallelizationButtonGroup = uibuttongroup(app.UIFigure);
            app.ParallelizationButtonGroup.Title = 'Parallelization';
            app.YesButton = uiradiobutton(app.ParallelizationButtonGroup);
            app.YesButton.Text = 'Yes';
            app.YesButton.Value = true;
            app.NoButton = uiradiobutton(app.ParallelizationButtonGroup);
            app.NoButton.Text = 'No';
            app.AlgorithmLabel = uilabel(app.UIFigure);
            app.AlgorithmLabel.Text = 'Algorithm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = estudio_decode_mvpa_ops(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end