classdef f_editchan_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OkayButton                      matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        ResetlocationinfotableButton    matlab.ui.control.Button
        EditlocsinDropDown              matlab.ui.control.DropDown
        EditlocsinDropDownLabel         matlab.ui.control.Label
        LoadsavechanlocationsPanel      matlab.ui.container.Panel
        GuesschanlocsButton             matlab.ui.control.Button
        SavelocationstolocfileButton    matlab.ui.control.Button
        LoadlocationsfromEEGdataButton  matlab.ui.control.Button
        LoadchanlocsfromlocfileButton   matlab.ui.control.Button
        ChannelLocationHeadplotPanel    matlab.ui.container.Panel
        headplotaxs                     matlab.ui.control.UIAxes
        LocationInformationTablePanel   matlab.ui.container.Panel
        loc_table                       matlab.ui.control.Table
    end


    properties (Access = public)
        output % Description
        Finishbutton % Description
    end

    properties (Access = private)
        nchl % Description
        ch_n % Description
        xyz % Description
        nchl_orig % Description
        EEG % Description
        ch_labels % Description
        locformat % Description
        tformat % Description
    end


    %%refresh table and topos
    methods (Access = private)
        function refreshtable(app)

            if strcmp(app.tformat,'xyz')
                for i = 1:app.ch_n
                    try
                        ch_xyz(i,:) = [app.nchl(i).X app.nchl(i).Y app.nchl(i).Z];
                    catch
                        ch_xyz(i,:) = [0 0 0];
                    end
                end
                set(app.loc_table,'Data',ch_xyz,'ColumnName',{'X';'Y';'Z'},'RowName',app.ch_labels );
                set(app.loc_table,'ColumnEditable',true(1,2,3));
            end


            if strcmp(app.tformat,'sph')
                for i = 1:app.ch_n
                    ch_sph(i,:) = [app.nchl(i).sph_theta app.nchl(i).sph_phi];
                end
                set(app.loc_table,'Data',ch_sph,'ColumnName',{'sph_theta';'sph_radius';' '},'RowName',app.ch_labels );
                set(app.loc_table,'ColumnEditable',false);
                set(app.loc_table,'ColumnEditable',true(1,2));
            end

        end

        function refreshhp(app)
            cla(app.headplotaxs);
            hp_fig = figure('Visible','Off');
            topoplot([],app.nchl, 'style', 'blank',  'electrodes', 'labelpoint', 'chaninfo', app.EEG.chaninfo);
            topo_axes = gca;
            topo2 = get(topo_axes,'children');
            copyobj(topo2, app.headplotaxs);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            %paint GUI
            painterplabapp(app);
            setfonterplab(app);
            % Name & version
            %
            version = geterplabversion;
            set(app.UIFigure,'Name', ['ERPLAB ' version '   -   Channel Location Editor GUI']);
            %             set(app.ChannelLocationHeadplotPanel,'BackgroundColor',[1 1 1]);
            set(app.headplotaxs,'XTick',[],'YTick',[],'XTickLabel',[],'YTickLabel',[]);
            app.headplotaxs.XLabel.String = '';
            app.headplotaxs.YLabel.String = '';
            app.headplotaxs.Title.String = '';

            %to close out gui
            ready = 0;
            if ready == 1
                app.Finishbutton = 1;
            end
            app.EditlocsinDropDown.Value = 'XYZ';
            %%-----------------get the input-------------------------------
            app.output = [];
            %%set title name
            try
                titleName = varargin{2};
                set(app.UIFigure,'Name', ['Estudio' version, '-',32,titleName]);
            catch
                set(app.UIFigure,'Name', ['Estudio ' version '   -   Channel Location Editor GUI']);
            end

            try
                EEG = varargin{1};
            catch
                beep;
                disp('There is no input');
                app.Finishbutton = 1;
                return;
            end

            try
                assert(isstruct(EEG)==1)
                assert(isfield(EEG, 'chanlocs')==1)
            catch
                beep;
                disp('This doesn''t look like a valid dataset. Check input');
                app.Finishbutton = 1;
                return;
            end
            if isempty(EEG)
                beep;
                disp('The dataset is empty');
                app.Finishbutton = 1;
                return;
            end
            % Check for chaninfo
            if isfield(EEG,'chaninfo') == 0
                EEG.chaninfo.icachanind = []; EEG.chaninfo.plotrad = []; EEG.chaninfo.shrink = []; EEG.chaninfo.nosedir = '+X'; EEG.chaninfo.nodatchans = [];
            end

            ch_n = numel(EEG.chanlocs);
            locs_in = struct2cell(EEG.chanlocs);
            locs_in = squeeze(locs_in)';
            locs_in_size = size(locs_in);

            fields_here = fieldnames(EEG.chanlocs);
            field_x = find(strcmp(fields_here,'X')==1);
            field_y = find(strcmp(fields_here,'Y')==1);
            field_z = find(strcmp(fields_here,'Z')==1);
            field_labels = find(strcmp(fields_here,'labels')==1);

            locs_in_xyz = zeros(ch_n, 3);
            missing = zeros(ch_n, 1);

            ch_labels = locs_in(:,field_labels);

            % Check for missing input
            if locs_in_size(2) < 12

                % in the case of missing chanloc columns, leave as zeros
            else
                % if not missing input, load from chanloc
                locs_cells = [locs_in(:,field_x) locs_in(:,field_y) locs_in(:,field_z)];

                % check for nans or empty, if so, write zeros
                empty_cells = cellfun('isempty',locs_cells);
                if any(empty_cells(:))
                    for i = 1:numel(empty_cells)
                        if empty_cells(i)
                            locs_cells{i} = 0;
                        end
                    end

                    % Record which chans were missing
                    for ir = 1:ch_n
                        if any(empty_cells(ir,:))
                            missing(ir) = 1;
                        end
                    end
                end
                % convert de-nan'd cells to numeric array
                locs_in_xyz = cell2mat(locs_cells);
            end

            for i = 1:ch_n
                nchl(i).labels = ch_labels{i};
                nchl(i).type = [];
                nchl(i).theta = [];
                nchl(i).radius = [];
                nchl(i).X = locs_in_xyz(i,1);
                nchl(i).Y = locs_in_xyz(i,2);
                nchl(i).Z = locs_in_xyz(i,3);
                nchl(i).sph_theta = [];
                nchl(i).sph_phi = [];
                nchl(i).sph_radius = 85;
                nchl(i).urchan = i;
                nchl(i).ref = [];
            end

            [nchl] = pop_chanedit(nchl, 'convert','cart2topo');


            %app.locformat = {'channum','X','Y','Z','labels'};
            app.locformat = 'sph';
            app.tformat = 'xyz';

            app.nchl = nchl;
            app.ch_n = ch_n;
            app.ch_labels = ch_labels;
            app.xyz = locs_in_xyz;
            app.nchl_orig = nchl;
            app.EEG = EEG;
            if isfield(EEG,'bindata')
                app.LoadlocationsfromEEGdataButton.Text = 'Load locations from ERP data';
            else
                app.LoadlocationsfromEEGdataButton.Text = 'Load locations from EEG data';
            end

            refreshtable(app);
            refreshhp(app);
            painterplabapp(app);
            setfonterplab(app);
        end

        % Callback function
        function headplotButtonDown(app, event)
            set(app.headplot, '')
        end

        % Button pushed function: LoadchanlocsfromlocfileButton
        function load_locfile(app, event)
            done_loading = 0;
            [lfile,lpath] = uigetfile('locations.sph','Choose a file with channel location information');
            %load_str = [lpath lfile];  ' ''filetype'' ''xyz'''];
            %load_cell = {[lpath lfile], 'filetype', 'xyz'};
            %EEG3 = pop_chanedit(app.EEG,'load',load_cell);

            if lfile == 0
                disp('Location load file cancelled')
                return
            end


            lnchl = readlocs([lpath lfile],'filetype',app.locformat);
            %try
            %lnchl = pop_chanedit(app.nchl,'load',[lpath lfile],'format',app.locformat);
            %lnchl = readlocs(app.nchl,[lpath lfile],'format',app.locformat);
            %catch
            %disp('Location file loading problems?')
            %end

            % Sanity check
            loaded = struct2cell(lnchl);
            loaded = squeeze(loaded)';
            loaded_labels = loaded(:,1);
            loaded_n = length(lnchl);

            if isequal(loaded_labels,app.ch_labels)
                % if the labels exactly match, just write to app
                app.nchl = lnchl;
                done_loading = 1;

            else
                EEG_loaded.chanlocs = lnchl;
                app.EEG = chanlocs_matcher(app.EEG,EEG_loaded);
                app.nchl = app.EEG.chanlocs;
            end

            loaded = struct2cell(app.nchl);
            loaded = squeeze(loaded)';
            loaded_labels = loaded(:,1);
            loaded_n = length(lnchl);

            fields_here = fieldnames(app.nchl);
            field_x = find(strcmp(fields_here,'X')==1);
            field_y = find(strcmp(fields_here,'Y')==1);
            field_z = find(strcmp(fields_here,'Z')==1);
            field_labels = find(strcmp(fields_here,'labels')==1);


            % Remake the xyz num array
            locs_cells = [loaded(:,field_x) loaded(:,field_y) loaded(:,field_z)];
            empty_cells = cellfun('isempty',locs_cells);
            if any(empty_cells(:))
                for i = 1:numel(empty_cells)
                    if empty_cells(i)
                        locs_cells{i} = 0;
                    end
                end

                % Record which chans were missing
                for ir = 1:loaded_n
                    if any(empty_cells(ir,:))
                        missing(ir) = 1;
                    end
                end
            end

            % convert de-nan'd cells to numeric array
            app.xyz = cell2mat(locs_cells);

            refreshtable(app);
            refreshhp(app);
        end

        % Button pushed function: LoadlocationsfromEEGdataButton
        function load2dataset(app, event)
            EEG = app.EEG;
            if isfield(EEG,'bindata')
                [lfile,lpath] = uigetfile({'*.erp','ERP (*.erp)'},'Choose a dataset file that has channel location information',...
                    'MultiSelect', 'off');
            else
                [lfile,lpath] = uigetfile({'*.set','EEG (*.set)';'*.erp','ERP (*.erp)'},'Choose a dataset file that has channel location information',...
                    'MultiSelect', 'off');
            end


            if lfile == 0
                disp('Location load file cancelled')
                return
            end
            %%need to change this. Sep 27, 2023
            if isfield(EEG,'bindata')%%load erp
                EEG_l = pop_loaderp('filename', lfile, 'filepath', lpath );
            else
                EEG_l = pop_loadset(lfile,lpath);
            end
            lnchl = EEG_l.chanlocs;

            % Sanity check
            loaded = struct2cell(lnchl);
            loaded = squeeze(loaded)';
            loaded_labels = loaded(:,1);
            loaded_n = length(lnchl);

            if isequal(loaded_labels,app.ch_labels)
                % if the labels exactly match, just write to app
                app.nchl = lnchl;
                done_loading = 1;
            else
                EEG_loaded.chanlocs = lnchl;
                app.EEG = chanlocs_matcher(app.EEG,EEG_loaded);
                app.nchl = app.EEG.chanlocs;
            end
            
            fields_here = fieldnames(app.nchl);
            field_x = find(strcmp(fields_here,'X')==1);
            field_y = find(strcmp(fields_here,'Y')==1);
            field_z = find(strcmp(fields_here,'Z')==1);
            field_labels = find(strcmp(fields_here,'labels')==1);


            % Remake the xyz num array
            locs_cells = [loaded(:,field_x) loaded(:,field_y) loaded(:,field_z)];
            empty_cells = cellfun('isempty',locs_cells);
            if any(empty_cells(:))
                for i = 1:numel(empty_cells)
                    if empty_cells(i)
                        locs_cells{i} = 0;
                    end
                end

                % Record which chans were missing
                for ir = 1:loaded_n
                    if any(empty_cells(ir,:))
                        missing(ir) = 1;
                    end
                end
            end

            % convert de-nan'd cells to numeric array
            app.xyz = cell2mat(locs_cells);

            %nchl.type = 'nope';
            refreshtable(app);
            refreshhp(app);
        end

        % Button pushed function: SavelocationstolocfileButton
        function save2locfile(app, event)
            %%save locations to loc file
            [sfile,spath] = uiputfile('locations.sph','Choose a location save file name');
            if sfile == 0
                disp('Save locations - file selection cancelled')
                return
            end
            writelocs(app.nchl,[spath sfile],'filetype',app.locformat);
        end

        % Value changed function: EditlocsinDropDown
        function editlocsop(app, event)
            value = app.EditlocsinDropDown.Value;
            if strcmpi(value,'XYZ')
                app.tformat = 'xyz';
            else
                app.tformat = 'sph';
            end
            refreshtable(app);
        end

        % Button pushed function: ResetlocationinfotableButton
        function restlocstable(app, event)
            confirm_reset = questdlg('Reset the locations in this table to the original values?');
            if strcmp(confirm_reset, 'Yes') == 1
                app.nchl = app.nchl_orig;
                refreshtable(app);
                refreshhp(app);
                loc_table_CellEditCallback(app, event);
            end
        end

        % Button pushed function: CancelButton
        function cancel(app, event)
            app.output =[];
            app.Finishbutton = 1;
        end

        % Button pushed function: OkayButton
        function Okay(app, event)
            app.EEG.chanlocs = app.nchl;
            app.output = app.EEG;
            app.Finishbutton = 1;
        end

        % Cell edit callback: loc_table
        function loc_table_CellEditCallback(app, event)
            %             indices = event.Indices;
            %             newData = event.NewData;
            tdata = app.loc_table.Data;

            % recreate a new channel location structure
            for i = 1:app.ch_n
                nchl(i).labels = app.ch_labels{i};
                nchl(i).type = [];
                nchl(i).theta = [];
                nchl(i).radius = [];
                nchl(i).X = [];%handles.xyz(i,1);
                nchl(i).Y = [];%handles.xyz(i,2);
                nchl(i).Z = [];%handles.xyz(i,3);
                nchl(i).sph_theta = [];
                nchl(i).sph_phi = [];
                nchl(i).sph_radius = 85;
                nchl(i).urchan = i;
                nchl(i).ref = [];
                if strcmp(app.tformat,'xyz')  % in xyz
                    nchl(i).X = tdata(i,1);
                    nchl(i).Y = tdata(i,2);
                    nchl(i).Z = tdata(i,3);

                elseif strcmp(app.tformat,'sph')  % in sph
                    nchl(i).sph_theta = tdata(i,1);
                    nchl(i).sph_phi = tdata(i,2);
                end
            end

            if strcmp(app.tformat,'xyz')  % in xyz
                [nchl] = pop_chanedit(nchl, 'convert','cart2all');
            else
                [nchl] = pop_chanedit(nchl, 'convert','sph2all');
            end
            app.nchl = nchl;
            %disp(app.xyz);
            refreshtable(app);
            refreshhp(app);
        end

        % Button pushed function: GuesschanlocsButton
        function geuss_chanlocs(app, event)
            EEG1 = app.EEG;
            if isfield(EEG1,'bindata')
                EEG = eeg_emptyset();
                EEG.nbchan      = EEG1.nchan;
                EEG.trials      = EEG1.nbin;
                EEG.pnts        = EEG1.pnts;
                EEG.srate       = EEG1.srate;
                EEG.xmin        = EEG1.xmin;
                EEG.xmax        = EEG1.xmax;
                EEG.times       = EEG1.times;
                EEG.data        = EEG1.bindata;
                EEG.icaact      = [];
                EEG.icawinv     = [];
                EEG.icasphere   = [];
                EEG.icaweights  = [];
                EEG.icachansind = [];
                EEG.chanlocs    = EEG1.chanlocs;
                EEG.urchanlocs  = [];
                EEG.chaninfo    = [];
                [EEG2, chaninfo, urchans, LASTCOM] =pop_chanedit(EEG);
            else
                [EEG2, chaninfo, urchans, LASTCOM] =pop_chanedit(EEG1);
               
            end
            EEG1.chanlocs = EEG2.chanlocs;
            app.EEG = EEG1;
             
             lnchl = EEG1.chanlocs;

            % Sanity check
            loaded = struct2cell(lnchl);
            loaded = squeeze(loaded)';
            loaded_labels = loaded(:,1);
            loaded_n = length(lnchl);

            if isequal(loaded_labels,app.ch_labels)
                % if the labels exactly match, just write to app
                app.nchl = lnchl;
                done_loading = 1;
            end
              app.nchl = app.EEG.chanlocs;
            fields_here = fieldnames(app.nchl);
            field_x = find(strcmp(fields_here,'X')==1);
            field_y = find(strcmp(fields_here,'Y')==1);
            field_z = find(strcmp(fields_here,'Z')==1);
            field_labels = find(strcmp(fields_here,'labels')==1);

            % Remake the xyz num array
            locs_cells = [loaded(:,field_x) loaded(:,field_y) loaded(:,field_z)];
            empty_cells = cellfun('isempty',locs_cells);
            if any(empty_cells(:))
                for i = 1:numel(empty_cells)
                    if empty_cells(i)
                        locs_cells{i} = 0;
                    end
                end

                % Record which chans were missing
                for ir = 1:loaded_n
                    if any(empty_cells(ir,:))
                        missing(ir) = 1;
                    end
                end
            end
            
            % convert de-nan'd cells to numeric array
            app.xyz = cell2mat(locs_cells);

            %nchl.type = 'nope';
            refreshtable(app);
            refreshhp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LocationInformationTablePanel = uipanel(app.UIFigure);
            app.LocationInformationTablePanel.Title = 'Location Information Table';
            app.loc_table = uitable(app.LocationInformationTablePanel);
            app.loc_table.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.loc_table.RowName = {};
            app.loc_table.CellEditCallback = createCallbackFcn(app, @loc_table_CellEditCallback, true);
            app.ChannelLocationHeadplotPanel = uipanel(app.UIFigure);
            app.ChannelLocationHeadplotPanel.Title = 'Channel Location Headplot';
            app.headplotaxs = uiaxes(app.ChannelLocationHeadplotPanel);
            app.headplotaxs.XTick = [];
            app.headplotaxs.YTick = [];
            app.LoadsavechanlocationsPanel = uipanel(app.UIFigure);
            app.LoadsavechanlocationsPanel.Title = 'Load/save chan locations';
            app.LoadchanlocsfromlocfileButton = uibutton(app.LoadsavechanlocationsPanel, 'push');
            app.LoadchanlocsfromlocfileButton.ButtonPushedFcn = createCallbackFcn(app, @load_locfile, true);
            app.LoadchanlocsfromlocfileButton.Text = 'Load chanlocs from loc file';
            app.LoadlocationsfromEEGdataButton = uibutton(app.LoadsavechanlocationsPanel, 'push');
            app.LoadlocationsfromEEGdataButton.ButtonPushedFcn = createCallbackFcn(app, @load2dataset, true);
            app.LoadlocationsfromEEGdataButton.Text = 'Load locations from EEG data';
            app.SavelocationstolocfileButton = uibutton(app.LoadsavechanlocationsPanel, 'push');
            app.SavelocationstolocfileButton.ButtonPushedFcn = createCallbackFcn(app, @save2locfile, true);
            app.SavelocationstolocfileButton.Text = 'Save locations to loc file';
            app.GuesschanlocsButton = uibutton(app.LoadsavechanlocationsPanel, 'push');
            app.GuesschanlocsButton.ButtonPushedFcn = createCallbackFcn(app, @geuss_chanlocs, true);
            app.GuesschanlocsButton.Text = 'Guess chanlocs';
            app.EditlocsinDropDownLabel = uilabel(app.UIFigure);
            app.EditlocsinDropDownLabel.Text = 'Edit locs in ';
            app.EditlocsinDropDown = uidropdown(app.UIFigure);
            app.EditlocsinDropDown.Items = {'XYZ', 'Spherical Matlab co-ord'};
            app.EditlocsinDropDown.ValueChangedFcn = createCallbackFcn(app, @editlocsop, true);
            app.EditlocsinDropDown.Value = 'Spherical Matlab co-ord';
            app.ResetlocationinfotableButton = uibutton(app.UIFigure, 'push');
            app.ResetlocationinfotableButton.ButtonPushedFcn = createCallbackFcn(app, @restlocstable, true);
            app.ResetlocationinfotableButton.Text = 'Reset location info table';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.OkayButton = uibutton(app.UIFigure, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @Okay, true);
            app.OkayButton.Text = 'Okay';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = f_editchan_gui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end