classdef EEG_Summary_AR_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        TextArea_2      matlab.ui.control.TextArea
        TextArea        matlab.ui.control.TextArea
        Edit_pos_width  matlab.ui.control.EditField
        Button_2        matlab.ui.control.Button
        Button          matlab.ui.control.Button
        UITable         matlab.ui.control.Table
    end


    properties (Access = private)
        ALLEEG % Description
        CURRENTSET % Description
        Finishbutton % Description
        Output % Description
    end

    methods (Access = private)

        function results = func_updated(app)
            EEG = app.ALLEEG(app.CURRENTSET);
            app.TextArea.Value = EEG.setname;
            if EEG.trials==1
                app.TextArea_2.Value = 'Current EEGset should be epoched';
                app.UITable.Data = '';
                return;
            end
            [EEG, tprej, acce, rej, histoflags ] = pop_summary_AR_eeg_detection(EEG,'none');

            nbin = EEG.EVENTLIST.nbin;


            for Numofbin = 1:nbin
                data{Numofbin,1} = num2str(Numofbin);
                data{Numofbin,2} = [num2str(acce(Numofbin)),32,'(',num2str(roundn(100*acce(Numofbin)/(acce(Numofbin)+rej(Numofbin)),-1)),'%)'];
                data{Numofbin,3} = [num2str(rej(Numofbin)),32,'(',num2str(roundn(100*rej(Numofbin)/(acce(Numofbin)+rej(Numofbin)),-1)),'%)'];
                data{Numofbin,4} = num2str(histoflags(Numofbin,2));
                data{Numofbin,5} = num2str(histoflags(Numofbin,3));
                data{Numofbin,6} = num2str(histoflags(Numofbin,4));
                data{Numofbin,7} = num2str(histoflags(Numofbin,5));
                data{Numofbin,8} = num2str(histoflags(Numofbin,6));
                data{Numofbin,9} = num2str(histoflags(Numofbin,7));
                data{Numofbin,10} = num2str(histoflags(Numofbin,8));
            end
            data{Numofbin+1,1} = 'Total';
            data{Numofbin+1,2} = [num2str(sum(acce(:))),32,'(',num2str(roundn(100*sum(acce(:))/(sum(acce(:))+sum(rej(:))),-1)),'%)'];
            data{Numofbin+1,3} = [num2str(sum(rej(:))),32,'(',num2str(roundn(100*sum(rej(:))/(sum(acce(:))+sum(rej(:))),-1)),'%)'];
            data{Numofbin+1,4} =num2str(sum(histoflags(:,2)));
            data{Numofbin+1,5} =num2str(sum(histoflags(:,3)));
            data{Numofbin+1,6} =num2str(sum(histoflags(:,4)));
            data{Numofbin+1,7} =num2str(sum(histoflags(:,5)));
            data{Numofbin+1,8} =num2str(sum(histoflags(:,6)));
            data{Numofbin+1,9} =num2str(sum(histoflags(:,7)));
            data{Numofbin+1,10} =num2str(sum(histoflags(:,8)));
            app.UITable.Data = data;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            painterplabapp(app);
            setfonterplab(app);
            % Name & version
            %
            version = geterplabversion;
            set(app.UIFigure,'Name', ['Estudio ' version '   -   Summary Artifacts GUI']);
            try ALLEEG = varargin{1}; catch ALLEEG = [];end
            if isempty(ALLEEG)
                app.TextArea_2.Value = 'Please input validate EEGsets';
                return;
            end
            app.ALLEEG = ALLEEG;
            try CURRENTSET = varargin{2};catch  CURRENTSET=1;end
            if isempty(CURRENTSET) || any(CURRENTSET>length(ALLEEG)) || any(CURRENTSET<1) || numel(CURRENTSET)~=1
                CURRENTSET=1;
            end

            if  ~isempty(app.ALLEEG)
                app.Edit_pos_width.Value=num2str(CURRENTSET);
                app.CURRENTSET=CURRENTSET;
                func_updated(app);
            end
            app.Finishbutton = 0;
            app.Output = [];
        end

        % Value changed function: TextArea
        function erpname(app, event)
            value = app.TextArea.Value;

        end

        % Value changed function: TextArea_2
        function messag_text(app, event)
            value = app.TextArea_2.Value;

        end

        % Button pushed function: Button
        function erpset_pre(app, event)
            app.TextArea_2.Value = '';
            ALLEEG =  app.ALLEEG;
            CURRENTSET = str2num(app.Edit_pos_width.Value);
            CURRENTSET = CURRENTSET-1;
            if isempty(CURRENTSET)|| any(CURRENTSET<=0) || numel(CURRENTSET)~=1 || any(CURRENTSET>length(ALLEEG))
                CURRENTSET=app.CURRENTSET;
                msgboxText = ['The EEGset should be between 1 and ',32,num2str(length(ALLEEG))];
                app.TextArea_2.Value = msgboxText;
            end
            app.CURRENTSET =CURRENTSET;
            app.Edit_pos_width.Value =num2str(app.CURRENTSET);
            func_updated(app);
        end

        % Value changed function: Edit_pos_width
        function erpset_edit(app, event)
            app.TextArea_2.Value = '';
            ALLEEG =  app.ALLEEG;
            CURRENTSET = str2num(app.Edit_pos_width.Value);
            if isempty(CURRENTSET)|| any(CURRENTSET<=0) || numel(CURRENTSET)~=1 || any(CURRENTSET>length(ALLEEG))
                CURRENTSET=app.CURRENTSET;
                msgboxText = ['The EEGset should be between 1 and ',32,num2str(length(ALLEEG))];
                app.TextArea_2.Value = msgboxText;
            end
            app.CURRENTSET =CURRENTSET;
            app.Edit_pos_width.Value =num2str(app.CURRENTSET);
            func_updated(app);
        end

        % Button pushed function: Button_2
        function erpset_next(app, event)
            app.TextArea_2.Value = '';
            ALLEEG =  app.ALLEEG;
            CURRENTSET = str2num(app.Edit_pos_width.Value);
            CURRENTSET = CURRENTSET+1;
            if isempty(CURRENTSET)|| any(CURRENTSET<=0) || numel(CURRENTSET)~=1 || any(CURRENTSET>length(ALLEEG))
                CURRENTSET=app.CURRENTSET;
                msgboxText = ['The EEGset should be between 1 and ',32,num2str(length(ALLEEG))];
                app.TextArea_2.Value = msgboxText;
            end
            app.CURRENTSET =CURRENTSET;
            app.Edit_pos_width.Value =num2str(app.CURRENTSET);
            func_updated(app);
        end

        % Display data changed function: UITable
        function summary_ar_table(app, event)
            newDisplayData = app.UITable.DisplayData;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Bin'; 'Accepted'; 'Rejected'; 'F2'; 'F3'; 'F4'; 'F5'; 'F6'; 'F7'; 'F8'};
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @summary_ar_table, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @erpset_pre, true);
            app.Button.Text = '<';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @erpset_next, true);
            app.Button_2.Text = '>';
            app.Edit_pos_width = uieditfield(app.UIFigure, 'text');
            app.Edit_pos_width.ValueChangedFcn = createCallbackFcn(app, @erpset_edit, true);
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @erpname, true);
            app.TextArea.Editable = 'off';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @messag_text, true);
            app.TextArea_2.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEG_Summary_AR_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end