classdef EEG_evenlist_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        TextArea        matlab.ui.control.TextArea
        Edit_pos_width  matlab.ui.control.EditField
        Button_2        matlab.ui.control.Button
        Button          matlab.ui.control.Button
        UITable_2       matlab.ui.control.Table
        UITable         matlab.ui.control.Table
    end


    properties (Access = private)
        ALLEEG % Description
        EEGset_current % Description
        Finishbutton % Description
        Output % Description
    end

    methods (Access = private)

        function results = func_updated(app)
            %             app.TextArea.Value = '';
            EEG = app.ALLEEG(app.EEGset_current);
            if ~isfield(EEG,'EVENTLIST') || isempty(EEG.EVENTLIST)
                app.UITable_2.Data = '';
                app.UITable.Data = '';
                app.TextArea.Value = 'Please create EVENTLIST first!';
                return;
            end
            if isfield(EEG,'EVENTLIST') && ~isempty(EEG.EVENTLIST)
                data2 = '';
                %%----------------------header------------------
                if EEG.trials==1
                    data1{1,1}   = 'continuous';
                else
                    data1{1,1}   = 'epoched';
                end

                data1{2,1}   = EEG.setname;
                data1{3,1}   = EEG.filename;
                data1{4,1}   = EEG.filepath;
                data1{5,1}   = num2str(EEG.nbchan);
                data1{6,1}   = num2str(EEG.pnts);
                data1{7,1}   = num2str(EEG.srate);
                try data1{8,1}   = num2str(length(EEG.EVENTLIST.eventinfo));catch data1{8,1} = '';end
                try data1{9,1}   = EEG.EVENTLIST.bdfname;catch data1{9,1} = [];end
                try data1{10,1}   = EEG.EVENTLIST.setname;catch data1{10,1} = [];end
                try data1{11,1}   = EEG.EVENTLIST.report;catch data1{11,1} = '';end
                try data1{12,1}   = EEG.EVENTLIST.version;catch data1{12,1} = '';end
                try data1{13,1}   = EEG.EVENTLIST.eldate;catch data1{13,1} = '';end
                try data1{14,1}   = EEG.EVENTLIST.account;catch data1{14,1} = '';end
                if EEG.trials==1
                    if ~isempty(EEG.EVENTLIST.bdfname)
                        data1{15,1}  =[];
                        data1{16,1}  =[];
                        for jj = 1:numel(EEG.EVENTLIST.trialsperbin )
                            try
                                data1{16+jj,1}  =['Bin',32,num2str(jj),':',32,EEG.EVENTLIST.bdf(jj).description,',',...
                                    32,32,32,'#',num2str(EEG.EVENTLIST.trialsperbin(jj))];
                            catch
                            end
                        end
                    end
                else

                    if ~isempty(EEG.EVENTLIST.bdfname)
                        data1{15,1}  =[];
                        data1{16,1}  =[];
                        [ERP, EVENTLISTi, countbiORI, countbinINV, countbinOK, countflags, workfname] = averager(EEG, 1, 1, 1, 1, [], [],0);
                        ERP.erpname = EEG.setname;
                        ERP.ntrials.accepted = countbinOK;
                        ERP.ntrials.rejected = countbiORI-countbinINV-countbinOK;
                        ERP.ntrials.invalid = countbinINV;

                        for jj = 1:numel(EEG.EVENTLIST.trialsperbin )
                            try
                                allacep = roundn(100*countbinOK(jj)/countbiORI(jj),-1);
                                allrej = countbiORI(jj)-countbinINV(jj)-countbinOK(jj);
                                allrejp = roundn(100*allrej/countbiORI(jj),-1);
                                allinvd = roundn(100*countbinINV(jj)/countbiORI(jj),-1);

                                data1{16+jj,1}  =['Bin',32,num2str(jj),':',32,EEG.EVENTLIST.bdf(jj).description,',',...
                                    32,32,32,'all trials:',32,num2str(countbiORI(jj)),';',32,'Accepted trials: ',num2str(countbinOK(jj)),' (',num2str(allacep),'%); ',...
                                    32,'Rejected trials: ',num2str(allrej),' (',num2str(allrejp),'%); ',...
                                    32,'Invalid trials: ',num2str(countbinINV(jj)),' (',num2str(allinvd),'%); '];
                            catch
                            end
                        end
                    end

                end
                app.UITable.Data = data1;

                %%------------eventlist information-------------------------
                for ii = 1:length(EEG.EVENTLIST.eventinfo)

                    try data2{ii,1} =  num2str(EEG.EVENTLIST.eventinfo(ii).item);catch data2{ii,1} = '';  end
                    try data2{ii,2} =  num2str(EEG.EVENTLIST.eventinfo(ii).bepoch);catch data2{ii,2} = '';  end
                  
                     try 
                        code = EEG.EVENTLIST.eventinfo(ii).code; 
                        if isnumeric(code) 
                           data2{ii,3}  = num2str(code);
                        else
                             data2{ii,3} =  EEG.EVENTLIST.eventinfo(ii).code;
                        end
                    catch 
                        data2{ii,3} = ''; 
                     end
                     
                    try data2{ii,4} =  EEG.EVENTLIST.eventinfo(ii).codelabel;catch data2{ii,4} = '';  end
                    try data2{ii,5} =  num2str(EEG.EVENTLIST.eventinfo(ii).time);catch data2{ii,5} = '';  end
                    if ii==1
                        try data2{ii,6} =  '0';catch data2{ii,6} = '';  end
                    else
                        try tdiff = EEG.EVENTLIST.eventinfo(ii).spoint-EEG.EVENTLIST.eventinfo(ii-1).spoint; data2{ii,6} = num2str(roundn(tdiff*1000/EEG.srate,-2));catch data2{ii,6} = [];  end
                    end
                    %                     try data2{ii,6} =  EEG.EVENTLIST.eventinfo(ii).spoint;catch data2{ii,6} = '';  end

                    try data2{ii,7} =  num2str(EEG.EVENTLIST.eventinfo(ii).dura);catch data2{ii,7} = '';  end
                    try
                        sflag = dec2bin(EEG.EVENTLIST.eventinfo(ii).flag,16);
                        sflaga  = [sflag(9:16)];
                        sflagb  = [sflag(1:8)];

                        data2{ii,8} = sflagb;
                        data2{ii,9} = sflaga;
                    catch
                        data2{ii,8} = '';
                        data2{ii,9} = '';
                    end


                    try
                        senbl = EEG.EVENTLIST.eventinfo(ii).enable;
                        %                         senbl   = [blanks(4-length(senbl)) senbl];
                        data2{ii,10} =  num2str(senbl);
                    catch
                        data2{ii,10} = '';
                    end

                    try
                        sbini = EEG.EVENTLIST.eventinfo(ii).bini;
                        if sbini==-1
                            sbini = [];
                        end
                        %                         sbini   = [blanks(7-length(sbini)) sbini];
                        data2{ii,11} =  num2str(sbini);
                    catch
                        data2{ii,11} = '';
                    end
                    if str2num(sflaga)>0
                        bgColor(ii, :) = [1 1 0.783];
                    else
                        bgColor(ii, :) = [1 1 1];
                    end
                end
                app.UITable_2.Data = data2;
                try set(app.UITable_2, 'BackgroundColor', bgColor);catch end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            painterplabapp(app);
            setfonterplab(app);
            % Name & version
            %
            version = geterplabversion;
            set(app.UIFigure,'Name', ['Estudio ' version '   -   eventlist information GUI']);
            try ALLEEG = varargin{1}; catch ALLEEG = [];end
            if isempty(ALLEEG)
                app.TextArea.Value = 'Please input validate EEGsets';
                return;
            end
            app.ALLEEG = ALLEEG;
            if  ~isempty(app.ALLEEG)
                app.Edit_pos_width.Value='1';
                app.EEGset_current=1;
                func_updated(app);
            end
            app.UITable_2.ColumnWidth =  {70,70,70,80,80,80,80,80,80,70,70};
            app.Finishbutton = 0;
            app.Output = [];
        end

        % Display data changed function: UITable_2
        function table_eventlist(app, event)
            newDisplayData = app.UITable_2.DisplayData;
        end

        % Display data changed function: UITable
        function table_header(app, event)
            newDisplayData = app.UITable.DisplayData;
        end

        % Value changed function: TextArea
        function error_messg(app, event)
            value = app.TextArea.Value;
        end

        % Button pushed function: Button
        function pre_erpset(app, event)
            app.TextArea.Value = '';
            ALLEEG =  app.ALLEEG;
            EEGset_current = str2num(app.Edit_pos_width.Value);
            EEGset_current = EEGset_current-1;
            if isempty(EEGset_current)|| any(EEGset_current<=0) || numel(EEGset_current)~=1 || any(EEGset_current>length(ALLEEG))
                EEGset_current=length(ALLEEG);
                msgboxText = ['The EEGset should be between 1 and ',32,num2str(length(ALLEEG))];
                app.TextArea.Value = msgboxText;
            end
            app.EEGset_current =EEGset_current;
            app.TextArea.Value =num2str(EEGset_current);
            func_updated(app);
        end

        % Value changed function: Edit_pos_width
        function edit_erpset(app, event)
            app.TextArea.Value = '';
            EEGset_current = str2num(app.Edit_pos_width.Value);
            if isempty(EEGset_current)|| any(EEGset_current<=0) || numel(EEGset_current)~=1 || any(EEGset_current>length(ALLEEG))
                EEGset_current=app.EEGset_current;
                msgboxText = ['The EEGset should be between 1 and ',32,num2str(length(ALLEEG))];
                app.TextArea.Value = msgboxText;
            end
            app.EEGset_current =EEGset_current;
            app.Edit_pos_width.Value =num2str(EEGset_current);
            func_updated(app);
        end

        % Button pushed function: Button_2
        function next_erpset(app, event)
            app.TextArea.Value = '';
            ALLEEG =  app.ALLEEG;
            EEGset_current = str2num(app.Edit_pos_width.Value);
            EEGset_current = EEGset_current+1;
            if isempty(EEGset_current)|| any(EEGset_current<=0) || numel(EEGset_current)~=1 || any(EEGset_current>length(ALLEEG))
                EEGset_current=length(ALLEEG);
                msgboxText = ['The EEGset should be between 1 and ',32,num2str(length(ALLEEG))];
                app.TextArea.Value = msgboxText;
            end
            app.EEGset_current =EEGset_current;
            app.Edit_pos_width.Value =num2str(EEGset_current);
            func_updated(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {'data format'; 'setname'; 'filename'; 'filepath'; 'nchan'; 'pnts'; 'srate'; 'nevents'; 'generated by (bdf)'; 'generated by (set)'; 'reported in'; 'prog Version'; 'creation date'; 'user Account'};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @table_header, true);
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'item'; 'bepoch'; 'ecode'; 'label'; 'onset'; 'diff'; 'dura'; 'b_flags'; 'a_flags'; 'enable'; 'bin'};
            app.UITable_2.RowName = {};
            app.UITable_2.DisplayDataChangedFcn = createCallbackFcn(app, @table_eventlist, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @pre_erpset, true);
            app.Button.Text = '<';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @next_erpset, true);
            app.Button_2.Text = '>';
            app.Edit_pos_width = uieditfield(app.UIFigure, 'text');
            app.Edit_pos_width.ValueChangedFcn = createCallbackFcn(app, @edit_erpset, true);
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @error_messg, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEG_evenlist_gui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end