classdef EEG_select_segement_artifact_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        RejectButton  matlab.ui.control.Button
        CancelButton  matlab.ui.control.Button
        Panel         matlab.ui.container.Panel
        UIAxes        matlab.ui.control.UIAxes
        EditField     matlab.ui.control.EditField
        Button_2      matlab.ui.control.Button
        XButton_3     matlab.ui.control.Button
        XButton_5     matlab.ui.control.Button
        XButton_2     matlab.ui.control.Button
        XButton       matlab.ui.control.Button
        Button        matlab.ui.control.Button
    end


    properties (Access = private)
        ALLEEG % Description
        CURRENTSET % Description
        Startimes % Description
        Winlength % Description
        winrej % Description
        incallback % Description
        spacing % Description
        trialstag % Description
        limits % Description
        srate % Description
        chans % Description
        envelope % Description
        wincolor % Description
        mocap % Description
        setelectrode % Description
        Xlim % Description
        GapSize % Description
        ylims % Description
        flag % Description
    end

    properties (Access = public)
        Finishbutton % Description
        Output % Description
    end

    methods (Access = private)
        function results = func_updated(app)


            EEG = app.ALLEEG (app.CURRENTSET);
            OutputViewereegpar = f_preparms_eegwaviewer(EEG,0);

            ChanArray = OutputViewereegpar{1};
            ICArray =OutputViewereegpar{2};
            EEGdispFlag = 1;
            ICdispFlag = 0;
            AmpScale = OutputViewereegpar{6};
            ChanLabel = OutputViewereegpar{7};
            Submean = OutputViewereegpar{8};
            EventOnset = OutputViewereegpar{9};
            StackFlag = OutputViewereegpar{10};
            NormFlag = OutputViewereegpar{11};
            %             Startimes = OutputViewereegpar{12};
            AmpIC = OutputViewereegpar{13};
            bufftobo = OutputViewereegpar{14};
            myeegviewer = app.UIAxes;

            Winlength = app.Winlength ;
            Startimes = app.Startimes;

            [chaNum,sampleNum,trialNum]=size(EEG.data);
            Frames = sampleNum*trialNum;
            if EEG.trials>1 % time in second or in trials
                multiplier = size(EEG.data,2);
                Winlength = ceil(Winlength);
            else
                multiplier = EEG.srate;
            end


            if EEG.trials>1
                Startimes=Startimes-1;
            end


            try cla(myeegviewer); catch end
            set(app.UIAxes, 'XTick', [], 'YTick', [],'Box','off', 'Color','none','xcolor','none','ycolor','none');
            hold(myeegviewer,'on');
            box(myeegviewer,'on');
            try   opengl software; catch end;
            try opengl hardware; catch end
            %
            %%selected channels
            nbchan = EEG.nbchan;
            if isempty(ChanArray) || min(ChanArray(:)) >nbchan || max(ChanArray(:))> nbchan||  min(ChanArray(:))<=0
                ChanArray = 1:nbchan;
            end

            %%selected ICs
            if isempty(EEG.icachansind)
                ICArray = [];
            else
                nIC = numel(EEG.icachansind);
                if isempty(ICArray) || min(ICArray(:))>nIC || max(ICArray(:)) >  nIC ||  min(ICArray(:))<=0
                    ICArray = 1:nIC;
                end
            end

            %%display EEG?
            if isempty(EEGdispFlag) || (EEGdispFlag~=0 && EEGdispFlag~=1)
                EEGdispFlag = 1;
            end

            %%Dispaly ICs?
            if isempty(ICdispFlag) || (ICdispFlag~=0 && ICdispFlag~=1)
                ICdispFlag = 0;
            end
            if ICdispFlag==0
                ICArray = [];
            end

            %%Time range that is to display (1s or 5s)?
            if isempty(Winlength) || numel(Winlength)~=1 || min(Winlength(:))<=0
                Winlength=5;
            end

            %%Vertical scale?
            if isempty(AmpScale) || numel(AmpScale)~=1 || AmpScale<=0
                AmpScale = 50;
            end
            OldAmpScale = AmpScale;
            app.spacing = AmpScale;
            %%channe labels (name or number)
            if isempty(ChanLabel) || numel(ChanLabel)~=1 || (ChanLabel~=0 && ChanLabel~=1)
                ChanLabel = 1;
            end

            %%remove DC?
            if isempty(Submean) || numel(Submean)~=1 || (Submean~=0 && Submean~=1)
                Submean = 1;
            end

            %%Display events?
            if isempty(EventOnset) ||  numel(EventOnset)~=1 || (EventOnset~=0 && EventOnset~=1)
                EventOnset = 1;
            end


            %%Stack?
            if isempty(StackFlag) || numel(StackFlag)~=1 || (StackFlag~=0&&StackFlag~=1)
                StackFlag = 0;
            end
            if StackFlag==0
                app.envelope =0;
            else
                app.envelope =1;
            end

            %%Norm?
            if isempty(NormFlag) ||numel(NormFlag)~=1 || (NormFlag~=0 && NormFlag~=1)
                NormFlag = 0;
            end

            % Startimes =20;
            %%start time for the displayed data


            %%VERTICAL SCALE for ICs
            if isempty(AmpIC) || numel(AmpIC)~=1 || AmpIC<=0
                AmpIC = 20;
            end
            OldAmpIC = AmpIC;

            %%buffer at top and bottom
            if isempty(bufftobo) || numel(bufftobo)~=1 || any(bufftobo(:)<=0)
                bufftobo = 100;
            end

            [ChanNum,Allsamples,tmpnb] = size(EEG.data);
            Allsamples = Allsamples*tmpnb;
            if EEG.trials > 1
                multiplier = size(EEG.data,2);
            else
                multiplier = EEG.srate;
            end

            if isempty(Startimes) || Startimes<0 ||  Startimes>(ceil((Allsamples-1)/multiplier)-Winlength)
                if EEG.trials >1
                    Startimes=1;
                else
                    Startimes=0;
                end
            end
            %             if EEG.trials>1
            %                 app.EditField.Value = num2str(Startimes+1);
            %             else
            %                 app.EditField.Value = num2str(Startimes);
            %             end
            FonsizeDefault = f_get_default_fontsize();
            if isempty(FonsizeDefault) || numel(FonsizeDefault)~=1|| any(FonsizeDefault(:)<=0)
                FonsizeDefault=10;
            end

            %%determine the time range that will be dispalyed
            lowlim = round(Startimes*multiplier+1);
            highlim = round(min((Startimes+Winlength)*multiplier+1,Allsamples));
            app.Winlength  =Winlength ;

            %%--------------------prepare event array if any --------------------------
            Events = EEG.event;
            if ~isempty(Events)
                if ~isfield(Events, 'type') || ~isfield(Events, 'latency'), Events = []; end
            end
            if ~isempty(Events)
                if ischar(Events(1).type)
                    [Eventtypes tmpind indexcolor] = unique_bc({Events.type}); % indexcolor countinas the event type
                else [Eventtypes tmpind indexcolor] = unique_bc([ Events.type ]);
                end
                Eventcolors     = { 'r', [0 0.8 0], 'm', 'c', 'k', 'b', [0 0.8 0] };
                Eventstyle      = { '-' '-' '-'  '-'  '-' '-' '-' '--' '--' '--'  '--' '--' '--' '--'};
                Eventwidths     = [ 2.5 1 ];
                Eventtypecolors = Eventcolors(mod([1:length(Eventtypes)]-1 ,length(Eventcolors))+1);
                Eventcolors     = Eventcolors(mod(indexcolor-1               ,length(Eventcolors))+1);
                Eventtypestyle  = Eventstyle (mod([1:length(Eventtypes)]-1 ,length(Eventstyle))+1);
                Eventstyle      = Eventstyle (mod(indexcolor-1               ,length(Eventstyle))+1);

                % for width, only boundary events have width 2 (for the line)
                % -----------------------------------------------------------
                indexwidth = ones(1,length(Eventtypes))*2;
                if iscell(Eventtypes)
                    for index = 1:length(Eventtypes)
                        if strcmpi(Eventtypes{index}, 'boundary'), indexwidth(index) = 1; end
                    end
                else
                end
                Eventtypewidths = Eventwidths (mod(indexwidth([1:length(Eventtypes)])-1 ,length(Eventwidths))+1);
                Eventwidths     = Eventwidths (mod(indexwidth(indexcolor)-1               ,length(Eventwidths))+1);

                % latency and duration of events
                % ------------------------------
                Eventlatencies  = [ Events.latency ]+1;
                if isfield(Events, 'duration')
                    durations = { Events.duration };
                    durations(cellfun(@isempty, durations)) = { NaN };
                    Eventlatencyend   = Eventlatencies + [durations{:}]+1;
                else Eventlatencyend   = [];
                end
                %     EventOnset       = 1;
            end

            if isempty(Events)
                EventOnset  = 0;
            end

            chanNum = numel(ChanArray);


            %%-------------------------------IC and original data----------------------
            dataica = [];chaNum=0;
            if ~isempty(EEG.icaweights) && ~isempty(ICArray)%%pop_eegplot from eeglab
                tmpdata = eeg_getdatact(EEG, 'component', [1:size(EEG.icaweights,1)]);
                try
                    dataica = tmpdata(ICArray,:);
                catch
                    dataica = tmpdata(:,:);
                    ICArray = [1:size(tmpdata,1)];
                end
            end

            if EEGdispFlag==1
                dataeeg = EEG.data(ChanArray,:);
                chaNum = numel(ChanArray);
            else
                dataeeg =[];
                chaNum = 0;
            end
            %%---------------------------Normalize-------------------------------------
            if NormFlag==1
                %%Norm for origanal
                %     data2 = [];
                if ~isempty(dataeeg)
                    datastd = std(dataeeg(:,1:min(1000,Allsamples)),[],2);%
                    for i = 1:size(dataeeg,1)
                        dataeeg(i,:,:) = dataeeg(i,:,:)/datastd(i);
                        %             if ~isempty(data2)
                        %                 data2(i,:,:) = data2(i,:,:)*datastd(i);
                        %             end
                    end
                end

                %%norm for IC data
                if ~isempty(dataica)
                    dataicstd = std(dataica(:,1:min(1000,Allsamples)),[],2);
                    for i = 1:size(dataica,1)
                        dataica(i,:,:) = dataica(i,:,:)/dataicstd(i);
                        %        if ~isempty(data2)
                        %            data2(i,:,:) = data2(i,:,:)*dataicstd(i);
                        %        end
                    end
                end
            end

            % Removing DC for IC data?
            % -------------------------
            meandataica =[];ICNum=0;
            if ICdispFlag==1
                if ~isempty(EEG.icaweights) && ~isempty(ICArray) && ~isempty(dataica)%%pop_eegplot from eeglab
                    switch Submean % subtract the mean ?
                        case 1
                            meandataica = mean(dataica(:,lowlim:highlim)');
                            if any(isnan(meandataica))
                                meandataica = nan_mean(dataica(:,lowlim:highlim)');
                            end
                        otherwise, meandataica = zeros(1,numel(ICArray));
                    end
                end
                ICNum = numel(ICArray);
            else
                ICNum = 0;
            end

            % Removing DC for original data?
            % -------------------------
            meandata = [];
            if EEGdispFlag==1 && ~isempty(dataeeg)
                switch Submean % subtract the mean ?
                    case 1
                        meandata = mean(dataeeg(:,lowlim:highlim)');
                        if any(isnan(meandata))
                            meandata = nan_mean(dataeeg(:,lowlim:highlim)');
                        end
                    otherwise, meandata = zeros(1,numel(ChanArray));
                end
            end


            PlotNum = chaNum+ICNum;
            if chaNum==0 && ICNum==0
                Ampscold = 0*[1:PlotNum]';
                if StackFlag==1
                    Ampsc = 0*[1:PlotNum]';
                else
                    Ampsc = Ampscold;
                end
                AmpScaleold = 0;
                ylims = [0 (PlotNum+1)*AmpScale];
                data = [dataeeg;dataica];
                meandata = [meandata,meandataica];
            elseif ICNum==0 && chaNum~=0
                Ampscold = AmpScale*[1:PlotNum]';
                if  StackFlag==1
                    Ampsc = ((Ampscold(end)+AmpScale)/2)*ones(1,PlotNum)';
                else
                    Ampsc = Ampscold;
                end
                AmpScaleold = AmpScale;
                ylims = [AmpScale*(100-bufftobo)/100 PlotNum*AmpScale+AmpScale*bufftobo/100];
                data = [dataeeg;dataica];
                meandata = [meandata,meandataica];
            elseif ICNum~=0 && chaNum==0
                Ampscold = AmpIC*[1:PlotNum]';
                if  StackFlag==1
                    Ampsc = ((Ampscold(end)+AmpIC)/2)*ones(1,PlotNum)';
                else
                    Ampsc = Ampscold;
                end
                ylims = [AmpIC*(100-bufftobo)/100 PlotNum*AmpIC+AmpIC*bufftobo/100];
                AmpScaleold = AmpIC;
                data = [dataeeg;dataica];
                meandata = [meandata,meandataica];
                AmpICNew = AmpIC;
            elseif ICNum~=0 && chaNum~=0
                AmpICNew = (AmpScale*chaNum+AmpScale/2)/ICNum;
                Ampscold1 = AmpICNew*[1:ICNum]';
                Ampscold2 = Ampscold1(end)+AmpScale/2+AmpScale*[1:chaNum]';
                Ampscold = [Ampscold1;Ampscold2];
                if  StackFlag==1
                    Ampsc = [(Ampscold1(end)/2)*ones(ICNum,1);((Ampscold2(end)+AmpScale+Ampscold2(1)+AmpIC)/2)*ones(chaNum,1)];
                else
                    Ampsc = Ampscold;
                end
                AmpScaleold = AmpScale;
                ylims = [AmpICNew*(100-bufftobo)/100 Ampscold(end)+AmpScale*bufftobo/100];
                data = [dataeeg;(AmpICNew/AmpIC)*dataica];
                meandata = [meandata,(AmpICNew/AmpIC)*meandataica];
            end
            app.ylims = ylims;

            Colorgbwave = [];
            %%set the wave color for each channel
            if ~isempty(data)
                ColorNamergb = roundn([255 0 7;186 85 255;255 192 0;0 238 237;0 78 255;0 197 0]/255,-3);
                Colorgb_chan = [];
                if ~isempty(dataeeg)
                    chanNum = numel(ChanArray);
                    if chanNum<=6
                        Colorgb_chan = ColorNamergb(1:chanNum,:);
                    else
                        jj = floor(chanNum/6);
                        Colorgb_chan = [];
                        for ii = 1:jj
                            Colorgb_chan = [Colorgb_chan; ColorNamergb];
                        end
                        if jj*6~=chanNum
                            Colorgb_chan = [Colorgb_chan; ColorNamergb(1:chanNum-jj*6,:)];
                        end
                    end
                end

                %%colors for ICs
                %     Coloricrgb = roundn([211,211,211;169,169,16;128,128,128]/255,-3);
                Coloricrgb = roundn([180 0 0;127 68 127;228 88 44;15 175 175;0 0 0;9 158 74]/255,-3);
                Colorgb_IC = [];
                if ~isempty(ICArray)
                    ICNum = numel(ICArray);
                    if ICNum<7
                        Colorgb_IC = Coloricrgb(1:ICNum,:);
                    else
                        jj = floor(ICNum/6);
                        for ii = 1:jj
                            Colorgb_IC = [Colorgb_IC; Coloricrgb];
                        end

                        if jj*6~=ICNum
                            Colorgb_IC = [Colorgb_IC; Coloricrgb(1:ICNum-jj*6,:)];
                        end

                    end
                end
                Colorgbwave = [Colorgb_chan;Colorgb_IC];
            end


            PlotNum =0;
            if ICdispFlag==1 && EEGdispFlag==1
                if ~isempty(EEG.icaweights) && ~isempty(ICArray)
                    PlotNum = chanNum +numel(ICArray);
                else
                    PlotNum = chanNum;
                end
            elseif ICdispFlag==0 && EEGdispFlag==1
                PlotNum = chanNum;
            elseif ICdispFlag==1 && EEGdispFlag==0
                if ~isempty(EEG.icaweights) && ~isempty(ICArray)
                    PlotNum =  numel(ICArray);
                end
            end
            %%
            EventOnsetdur =1;
            Trialstag = size(EEG.data,2);
            GapSize = ceil(numel([lowlim:highlim])/40);
            app.GapSize = GapSize;
            if GapSize<=2
                GapSize=5;
            end
            % -------------------------------------------------------------------------
            % -------------------------draw events if any------------------------------
            % -------------------------------------------------------------------------
            % ylims = [0 (PlotNum+1)*AmpScale];

            if EventOnset==1 && ~isempty(data) && PlotNum~=0
                MAXEVENTSTRING = 75;
                if MAXEVENTSTRING<0
                    MAXEVENTSTRING = 0;
                elseif MAXEVENTSTRING>75
                    MAXEVENTSTRING=75;
                end
                %     AXES_POSITION = [0.0964286 0.15 0.842 0.75-(MAXEVENTSTRING-5)/100];
                %

                % find event to plot
                % ------------------
                event2plot    = find ( Eventlatencies >=lowlim & Eventlatencies <= highlim );
                if ~isempty(Eventlatencyend)
                    event2plot2 = find ( Eventlatencyend >= lowlim & Eventlatencyend <= highlim );
                    event2plot3 = find ( Eventlatencies  <  lowlim & Eventlatencyend >  highlim );
                    event2plot  = setdiff(union(event2plot, event2plot2), event2plot3);
                end
                for index = 1:length(event2plot)
                    %Just repeat for the first one
                    if index == 1
                        EVENTFONT = [' \fontsize{',num2str(FonsizeDefault),'}'];
                    end

                    % draw latency line
                    % -----------------
                    if ndims(EEG.data)==2
                        tmplat = Eventlatencies(event2plot(index))-lowlim-1;
                    else
                        % -----------------
                        tmptaglat = [lowlim:highlim];
                        tmpind = find(mod(tmptaglat-1,Trialstag) == 0);
                        tmpind = setdiff(tmpind,[1,numel(tmptaglat)]);
                        alltaglat = setdiff(tmptaglat(tmpind),[1,tmptaglat(end)]);
                        %%add gap between epochs if any
                        if ~isempty(tmpind) && ~isempty(alltaglat) %%two or multiple epochs were displayed
                            if length(alltaglat)==1
                                if Eventlatencies(event2plot(index)) >= alltaglat
                                    Singlat = Eventlatencies(event2plot(index))+GapSize;
                                else
                                    Singlat = Eventlatencies(event2plot(index));
                                end
                            else
                                if  Eventlatencies(event2plot(index)) < alltaglat(1)%%check the first epoch
                                    Singlat = Eventlatencies(event2plot(index));
                                else%%the other epochs
                                    for ii  = 2:length(alltaglat)
                                        if Eventlatencies(event2plot(index)) >= alltaglat(ii-1) && Eventlatencies(event2plot(index)) < alltaglat(ii)
                                            Singlat = Eventlatencies(event2plot(index))+GapSize*(ii-1);
                                            break;
                                        elseif Eventlatencies(event2plot(index)) >= alltaglat(end)  %%check the last epoch
                                            Singlat = Eventlatencies(event2plot(index))+GapSize*length(alltaglat);
                                            break;
                                        end
                                    end
                                end
                            end
                            tmplat = Singlat-lowlim;%%adjust the latency if any
                        else%%within one epoch
                            tmplat = Eventlatencies(event2plot(index))-lowlim;%-1;
                        end
                    end
                    tmph   = plot(myeegviewer, [ tmplat tmplat ], ylims, 'color', Eventcolors{ event2plot(index) }, ...
                        'linestyle', Eventstyle { event2plot(index) }, ...
                        'linewidth', Eventwidths( event2plot(index) ) );

                    % schtefan: add Event types text above event latency line
                    % -------------------------------------------------------
                    evntxt = strrep(num2str(Events(event2plot(index)).type),'_','-');
                    if length(evntxt)>MAXEVENTSTRING, evntxt = [ evntxt(1:MAXEVENTSTRING-1) '...' ]; end % truncate
                    try,
                        tmph2 = text(myeegviewer, [tmplat], ylims(2)-0.005, [EVENTFONT evntxt], ...
                            'color', Eventcolors{ event2plot(index) }, ...
                            'horizontalalignment', 'left',...
                            'rotation',90,'FontSize',FonsizeDefault);
                    catch, end

                    % draw duration is not 0
                    % ----------------------
                    %         if EventOnsetdur && ~isempty(Eventlatencyend) ...
                    %                 && Eventwidths( event2plot(index) ) ~= 2.5 % do not plot length of boundary events
                    %             tmplatend = Eventlatencyend(event2plot(index))-lowlim-1;
                    %             if tmplatend ~= 0
                    %                 tmplim = ylims;
                    %                 tmpcol = Eventcolors{ event2plot(index) };
                    %                 h = patch(myeegviewer, [ tmplat tmplatend tmplatend tmplat ], ...
                    %                     [ tmplim(1) tmplim(1) tmplim(2) tmplim(2) ], ...
                    %                     tmpcol );  % this argument is color
                    %                 set(h, 'EdgeColor', 'none')
                    %             end
                    %         end
                end
            else % JavierLC
                MAXEVENTSTRING = 10; % default
                %     AXES_POSITION = [0.0964286 0.15 0.842 0.75-(MAXEVENTSTRING-5)/100];
            end


            DEFAULT_GRID_SPACING =Winlength/5;

            % -------------------------------------------------------------------------
            % -----------------draw EEG wave if any------------------------------------
            % -------------------------------------------------------------------------
            leftintv = [];
            tmpcolsbgc = [1 1 0.783];
            %%-------------------------Plot continuous EEG-----------------------------
            tmpcolor = [ 0 0 0.4 ];
            if EEG.trials==1
                %%plot background color
                if ~isempty(app.winrej)
                    event2plot1 = find ( app.winrej(:,1) >= lowlim & app.winrej(:,1) <= highlim );
                    event2plot2 = find ( app.winrej(:,2) >= lowlim & app.winrej(:,2) <= highlim );
                    event2plot3 = find ( app.winrej(:,1) <  lowlim & app.winrej(:,2) >  highlim );
                    event2plot  = union_bc(union(event2plot1, event2plot2), event2plot3);

                    for tpmi = event2plot(:)'
                        h = patch(myeegviewer, [app.winrej(tpmi,1)-lowlim app.winrej(tpmi,2)-lowlim ...
                            app.winrej(tpmi,2)-lowlim app.winrej(tpmi,1)-lowlim], ...
                            [ylims(1),ylims(1),ylims(end),ylims(end)], tmpcolsbgc,'EdgeColor','none','FaceAlpha',.8);
                    end
                end

                if ~isempty(data) && PlotNum~=0

                    for ii = size(data,1):-1:1
                        try
                            plot(myeegviewer, (data(ii,lowlim:highlim)+ Ampsc(size(data,1)-ii+1)-meandata(ii))' , ...
                                'color', Colorgbwave(ii,:), 'clipping','on','LineWidth',0.75);%%
                        catch
                            plot(myeegviewer, (data(ii,lowlim:highlim)+ Ampsc(size(data,1)-ii+1)-meandata(ii))', ...
                                'color', tmpcolor, 'clipping','on','LineWidth',0.75);%%
                        end
                    end
                    set(myeegviewer,'TickDir', 'in','LineWidth',1);
                    %%xtick
                    set(myeegviewer, 'Xlim',[1 Winlength*multiplier+1],...
                        'XTick',[1:multiplier*DEFAULT_GRID_SPACING:Winlength*multiplier+1]);
                    set(myeegviewer, 'XTickLabel', num2str((Startimes:DEFAULT_GRID_SPACING:Startimes+Winlength)'));
                    app.Xlim = [0 Winlength*multiplier+1];
                    %%
                    %%-----------------plot scale------------------
                    leftintv = Winlength*multiplier+1;
                else
                    set(myeegviewer, 'XTick', [], 'YTick', [],'Box','off', 'Color','none','xcolor','none','ycolor','none');
                end

            end

            %%------------------------------plot single-trial EEG----------------------
            %%Thanks for the eeglab developers so that we can borrow their codes
            isfreq = 0;
            Limits = [EEG.times(1),EEG.times(end)];
            Srate = EEG.srate;
            Freqlimits = [];
            if EEG.trials>1 %%&& ~isempty(app.winrej)
                if ~isempty(data) && PlotNum~=0
                    % plot trial limits
                    % -----------------
                    tmptag = [lowlim:highlim];
                    tmpind = find(mod(tmptag-1,Trialstag) == 0);

                    alltag = tmptag(tmpind);
                    if isempty(tmpind)
                        epochNum = 0;
                    else
                        epochNum = numel(setdiff(tmpind,[0 1 numel(tmptag)]));
                    end
                    % compute epoch number
                    % --------------
                    %                   alltag =   alltag(1:(epochNum+1));
                    alltag1 = alltag;
                    if ~isempty(tmpind)
                        tagnum = (alltag1-1)/Trialstag+1;%%
                        for ii = 1:numel(tmpind)
                            alltag1(ii)  = alltag1(ii)+(ii-1)*GapSize;
                        end
                        for ii = 1:numel(tagnum)
                            text(myeegviewer, [alltag1(ii)-lowlim+Trialstag/2],ylims(2)+1.1, [32,num2str(tagnum(ii))], ...
                                'color', 'k','FontSize',FonsizeDefault, ...
                                'horizontalalignment', 'left','rotation',90); %%
                            set(myeegviewer,'Xlim',[0 (Winlength*multiplier+epochNum*GapSize)]);
                        end
                    end

                    %%add the gap between epochs if any
                    Epochintv = [];
                    if ~isempty(tmpind)
                        if (numel(tmpind)==1 && tmpind(end) == numel(tmptag)) || (numel(tmpind)==1 && tmpind(1) == 1)
                            dataplot =   data(:,lowlim:highlim);
                            Epochintv(1,1) =1;
                            Epochintv(1,2) =numel(lowlim:highlim);
                        else
                            tmpind = unique(setdiff([0,tmpind, numel(tmptag)],1));
                            dataplotold =    data(:,lowlim:highlim);
                            dataplot_new = [];
                            for ii = 2:numel(tmpind)
                                GapLeft = tmpind(ii-1)+1;
                                GapRight = tmpind(ii);
                                if GapLeft<1
                                    GapLeft =1;
                                end
                                if GapRight > numel(tmptag)
                                    GapRight =  numel(tmptag);
                                end
                                dataplot_new = [dataplot_new,dataplotold(:,GapLeft:GapRight),nan(size(dataplotold,1),GapSize)];
                                Epochintv(ii-1,1) = GapLeft+(ii-2)*GapSize;
                                Epochintv(ii-1,2) = GapRight+(ii-2)*GapSize;
                            end
                            if ~isempty(dataplot_new)
                                dataplot = dataplot_new;
                            else
                                dataplot = dataplotold;
                            end
                        end
                    end

                    %%-----------plot background color for trias with artifact---------
                    %%highlight waves with labels
                    if ~isempty(app.winrej)
                        indices = find((app.winrej(:,1)' >= lowlim & app.winrej(:,1)' <= highlim) | ...
                            (app.winrej(:,2)' >= lowlim & app.winrej(:,2)' <= highlim));
                        Value_adjust = floor(app.Startimes);
                        if Value_adjust<1
                            Value_adjust=1;
                        end
                        tagnum  = unique([Value_adjust,tagnum]);

                        if ~isempty(indices)
                            try trialsMakrs1 = ceil(app.winrej(indices,2)/Trialstag)';catch trialsMakrs1 = zeros(1,numel(indices)) ; end
                            trialsMakrs11 = sort(trialsMakrs1);
                            for Numofindex = 1:numel(indices)
                                xpos0= find(ceil(app.winrej(:,2)/Trialstag)'==trialsMakrs11(Numofindex));
                                if isempty(xpos0)
                                    indices11(Numofindex) = 0;
                                else
                                    indices11(Numofindex) = xpos0;
                                end
                            end
                            try trialsMakrschan = app.winrej(indices11,6:end)';catch trialsMakrschan = zeros(EEG.nbchan,numel(indices)) ; end
                            %                        [~,trialsMakrs] = find(tagnum==trialsMakrs1);
                            X = trialsMakrs1(:)==tagnum; % requires MATLAB >=R2016b, for earlier versions replace == with BSXFUN.
                            [~,trialsMakrs] = find(X & cumsum(X,2)==cumsum(X,1));

                            if ~isempty(Epochintv) && ~isempty(trialsMakrs)
                                for jj = 1:numel(trialsMakrs)
                                    [xpos,~]=find(trialsMakrschan(:,jj)==1);
                                    if trialsMakrs(jj)>0
                                        if trialsMakrs(jj)<= size(Epochintv,1)
                                            patch(myeegviewer,[Epochintv(trialsMakrs(jj),1),Epochintv(trialsMakrs(jj),2),Epochintv(trialsMakrs(jj),2),Epochintv(trialsMakrs(jj),1)],...
                                                [ylims(1),ylims(1),ylims(end),ylims(end)],tmpcolsbgc,'EdgeColor','none','FaceAlpha',.8);
                                        end
                                        %%highlight the wave if the channels exist
                                        if  chaNum~=0
                                            ChanArray = reshape(ChanArray,1,numel(ChanArray));
                                            [~,ypos1]=find(ChanArray==xpos);
                                            if ~isempty(ypos1) && trialsMakrs(jj)<= size(Epochintv,1)
                                                for kk = 1:numel(ypos1)
                                                    dataChan = nan(1,size(dataplot,2));
                                                    dataChan (1,Epochintv(trialsMakrs(jj),1):Epochintv(trialsMakrs(jj),2)) = dataplot(ypos1(kk),Epochintv(trialsMakrs(jj),1):Epochintv(trialsMakrs(jj),2));
                                                    dataChan1= nan(1,size(dataplot,2));
                                                    dataChan1 (1,Epochintv(trialsMakrs(jj),1):Epochintv(trialsMakrs(jj),1)) = dataplot(ypos1(kk),Epochintv(trialsMakrs(jj),1):Epochintv(trialsMakrs(jj),1));
                                                    try
                                                        plot(myeegviewer, (dataChan+ Ampsc(size(dataplot,1)-ypos1(kk)+1)-meandata(ypos1(kk)))' , ...
                                                            'color', Colorgbwave(ypos1(kk),:), 'clipping','on','LineWidth',1.5);%%

                                                        plot(myeegviewer, (dataChan1+ Ampsc(size(dataplot,1)-ypos1(kk)+1)-meandata(ypos1(kk)))' , ...
                                                            'color', Colorgbwave(ypos1(kk),:), 'clipping','on','LineWidth',1.5,'Marker' ,'s','MarkerSize',8,...
                                                            'MarkerEdgeColor',Colorgbwave(ypos1(kk),:),'MarkerFaceColor',Colorgbwave(ypos1(kk),:));%%
                                                    catch
                                                        plot(myeegviewer, (dataChan+ Ampsc(size(dataplot,1)-ypos1(kk)+1)-meandata(ypos1(kk)))', ...
                                                            'color', tmpcolor, 'clipping','on','LineWidth',1.5);%%
                                                        plot(myeegviewer, (dataChan1+ Ampsc(size(dataplot,1)-ypos1(kk)+1)-meandata(ypos1(kk)))', ...
                                                            'color', tmpcolor, 'clipping','on','LineWidth',1.5,'Marker' ,'s','MarkerSize',8,...
                                                            'MarkerEdgeColor',Colorgbwave(ypos1(kk),:),'MarkerFaceColor',Colorgbwave(ypos1(kk),:));%%
                                                    end
                                                end
                                            end
                                        end
                                    end
                                end
                            end
                        end
                    end
                    %%
                    for ii = size(dataplot,1):-1:1
                        try
                            plot(myeegviewer, (dataplot(ii,:)+ Ampsc(size(dataplot,1)-ii+1)-meandata(ii))' + (PlotNum+1)*(OldAmpScale-AmpScale)/2, ...
                                'color', Colorgbwave(ii,:), 'clipping','on','LineWidth',0.75);%%
                        catch
                            plot(myeegviewer, (dataplot(ii,:)+ Ampsc(size(dataplot,1)-ii+1)-meandata(ii))' + (PlotNum+1)*(OldAmpScale-AmpScale)/2, ...
                                'color', tmpcolor, 'clipping','on','LineWidth',0.75);%%
                        end
                    end

                    %------------------------Xticks------------------------------------
                    tagpos  = [];
                    tagtext = [];
                    if ~isempty(alltag)
                        alltag = [alltag(1)-Trialstag alltag alltag(end)+Trialstag]; % add border trial limits
                    else
                        alltag = [ floor(lowlim/Trialstag)*Trialstag ceil(highlim/Trialstag)*Trialstag ]+1;
                    end

                    nbdiv = 20/Winlength; % approximative number of divisions
                    divpossible = [ 100000./[1 2 4 5] 10000./[1 2 4 5] 1000./[1 2 4 5] 100./[1 2 4 5 10 20]]; % possible increments
                    [tmp indexdiv] = min(abs(nbdiv*divpossible-(Limits(2)-Limits(1)))); % closest possible increment
                    incrementpoint = divpossible(indexdiv)/1000*Srate;

                    % tag zero below is an offset used to be sure that 0 is included
                    % in the absicia of the data epochs
                    if Limits(2) < 0, tagzerooffset  = (Limits(2)-Limits(1))/1000*Srate+1;
                    else                tagzerooffset  = -Limits(1)/1000*Srate;
                    end
                    if tagzerooffset < 0, tagzerooffset = 0; end

                    for i=1:length(alltag)-1
                        if ~isempty(tagpos) && tagpos(end)-alltag(i)<2*incrementpoint/3
                            tagpos  = tagpos(1:end-1);
                        end
                        if ~isempty(Freqlimits)
                            tagpos  = [ tagpos linspace(alltag(i),alltag(i+1)-1, nbdiv) ];
                        else
                            if tagzerooffset ~= 0
                                tmptagpos = [alltag(i)+tagzerooffset:-incrementpoint:alltag(i)];
                            else
                                tmptagpos = [];
                            end
                            tagpos  = [ tagpos [tmptagpos(end:-1:2) alltag(i)+tagzerooffset:incrementpoint:(alltag(i+1)-1)]];
                        end
                    end

                    % find corresponding epochs
                    % -------------------------
                    if ~isfreq
                        tmplimit = Limits;
                        tpmorder = 1E-3;
                    else
                        tmplimit =Freqlimits;
                        tpmorder = 1;
                    end
                    tagtext = eeg_point2lat(tagpos, floor((tagpos)/Trialstag)+1, Srate, tmplimit,tpmorder);

                    %%adjust xticks
                    EpochFlag = floor((tagpos)/Trialstag)+1;%%

                    EpochFlag = reshape(EpochFlag,1,numel(EpochFlag));
                    xtickstr =  tagpos-lowlim+1;
                    [xpos,ypos] = find(xtickstr>0);
                    if ~isempty(ypos)
                        EpochFlag =EpochFlag(ypos);
                        xtickstr = xtickstr(ypos);
                        tagtext = tagtext(ypos);
                    end

                    EpochFlagunique =unique(setdiff(EpochFlag,0));
                    if  numel(EpochFlagunique)~=1
                        for ii = 2:numel(EpochFlagunique)
                            [xpos,ypos]=  find(EpochFlag==EpochFlagunique(ii));
                            if ~isempty(ypos)
                                xtickstr(ypos) = xtickstr(ypos)+(EpochFlagunique(ii)-EpochFlagunique(1))*GapSize;%*(1000/Srate);
                            end
                        end
                    end
                    set(myeegviewer,'XTickLabel', tagtext,...
                        'Xlim',[0 (Winlength*multiplier+epochNum*GapSize)],...
                        'XTick',xtickstr,...
                        'FontWeight','normal',...
                        'xaxislocation', 'bottom','FontSize',FonsizeDefault);
                    XTickLabel = cellstr(myeegviewer.XTickLabel);
                    for Numofxtick = 1:length(XTickLabel)
                        if strcmpi(XTickLabel{Numofxtick,:},'-0')
                            XTickLabel{Numofxtick} = '0';
                        end
                    end
                    set(myeegviewer,'XTickLabel',XTickLabel);

                    app.Xlim = [0 (Winlength*multiplier+epochNum*GapSize)];
                    %%
                    %%-----------------plot scale------------------
                    leftintv = (Winlength*multiplier+epochNum*GapSize);
                else
                    set(myeegviewer, 'XTick', [], 'YTick', [],'Box','off', 'Color','none','xcolor','none','ycolor','none');
                end
            end
            %%
            %%----------------------------plot y scale---------------------------------
            if ~isempty(data) && PlotNum~=0  && ~isempty(leftintv)
                ytick_bottom = myeegviewer.TickLength(1)*diff(myeegviewer.XLim);
                %     xtick_bottom = myeegviewer.TickLength(1)*diff(myeegviewer.YLim);
                leftintv = leftintv+ytick_bottom*1.5;
                rightintv = leftintv;
                if ICdispFlag~=0
                    line(myeegviewer,[leftintv,rightintv],[ylims(1) AmpICNew+ylims(1)],'color','k','LineWidth',1, 'clipping','off');
                    line(myeegviewer,[leftintv-ytick_bottom,rightintv+ytick_bottom],[ylims(1) ylims(1)],'color','k','LineWidth',1, 'clipping','off');
                    line(myeegviewer,[leftintv-ytick_bottom,rightintv+ytick_bottom],[AmpICNew+ylims(1) AmpICNew+ylims(1)],'color','k','LineWidth',1, 'clipping','off');
                    text(myeegviewer,leftintv,((ylims(2)-ylims(1))/43+AmpICNew+ylims(1)), [num2str(AmpIC),32,'\muV'],'HorizontalAlignment', 'center','FontSize',myeegviewer.FontSize);
                    text(myeegviewer,leftintv,((ylims(2)-ylims(1))/20+AmpICNew+ylims(1)), ['ICs'],'HorizontalAlignment', 'center','FontSize',myeegviewer.FontSize);
                end
                if EEGdispFlag~=0
                    line(myeegviewer,[leftintv,rightintv],[ylims(end)-AmpScale ylims(end)],'color','k','LineWidth',1, 'clipping','off');
                    line(myeegviewer,[leftintv-ytick_bottom,rightintv+ytick_bottom],[ylims(end)-AmpScale ylims(end)-AmpScale],'color','k','LineWidth',1, 'clipping','off');
                    line(myeegviewer,[leftintv-ytick_bottom,rightintv+ytick_bottom],[ylims(end) ylims(end)],'color','k','LineWidth',1, 'clipping','off');
                    text(myeegviewer,leftintv,(ylims(2)-ylims(1))/43+ylims(end), [num2str(AmpScale),32,'\muV'],'HorizontalAlignment', 'center','FontSize',FonsizeDefault);
                    text(myeegviewer,leftintv,(ylims(2)-ylims(1))/20+ylims(end), ['Chans'],'HorizontalAlignment', 'center','FontSize',FonsizeDefault);
                end
            end

            %%---------------------------ytick ticklabels------------------------------
            if ~isempty(data) && PlotNum~=0
                if chaNum==0
                    ChanArray = [];
                end
                set(myeegviewer, 'ylim',[ylims(1) ylims(end)],'YTick',[ylims(1) Ampscold']);
                [YLabels,chaName,ICName] = f_eeg_read_chan_IC_names(EEG.chanlocs,ChanArray,ICArray,ChanLabel);
                YLabels = flipud(char(YLabels,''));
                set(myeegviewer,'YTickLabel',cellstr(YLabels),...
                    'TickLength',[.005 .005],...
                    'Color','none',...
                    'XColor','k',...
                    'YColor','k',...
                    'FontWeight','normal',...
                    'TickDir', 'in',...
                    'LineWidth',0.5,'FontSize',FonsizeDefault);%%,'HorizontalAlignment','center'
                count=0;
                for ii = length(myeegviewer.YTickLabel):-1:2
                    count = count+1;
                    Cellsing = strrep(myeegviewer.YTickLabel{ii},'_','\_');
                    myeegviewer.YTickLabel{ii} = strcat('\color[rgb]{',num2str(Colorgbwave(count,:)),'}', Cellsing);
                end
            end
            hold(myeegviewer,'off');
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            painterplabapp(app);
            setfonterplab(app);
            app.Panel.BackgroundColor  = [1 1 1];
            version = geterplabversion;
            set(app.UIFigure,'Name', ['Estudio ' version '   - Plot EEG GUI']);
            try app.ALLEEG = varargin{1};catch app.ALLEEG = []; end
            try app.CURRENTSET =varargin{2}; catch app.CURRENTSET = []; end
            set(app.UIAxes, 'XTick', [], 'YTick', [],'Box','off', 'Color','none','xcolor','none','ycolor','none');
            if isempty(app.ALLEEG)
                return;
            end
            if isempty(app.CURRENTSET) || numel(app.CURRENTSET)~=1|| any(app.CURRENTSET>length(app.ALLEEG))
                app.CURRENTSET =  length(app.ALLEEG);
            end

            try app.flag=varargin{3}; catch app.flag=1; end
            if isempty(app.flag) || any(app.flag(:)<1) || any(app.flag(:)>16)
                app.flag=1;
            end

            if app.ALLEEG(app.CURRENTSET).trials>1
                app.Startimes=1;
                app.RejectButton.Text = 'Mark';
            else
                app.Startimes=0;
                app.RejectButton.Text = 'Reject';
            end
            app.EditField.Value = num2str(app.Startimes);
            EEG = app.ALLEEG(app.CURRENTSET);
            EEG_plotset = estudioworkingmemory('EEG_plotset');
            try
                Winlength =   EEG_plotset{3};
            catch
                Winlength = 5;
            end
            [chaNum,sampleNum,trialNum]=size(EEG.data);
            Frames = sampleNum*trialNum;
            if EEG.trials>1 % time in second or in trials
                multiplier = size(EEG.data,2);
                multiplier_winleg = size(EEG.data,2);
            else
                multiplier = EEG.srate;
                multiplier_winleg = multiplier;
            end

            if isempty(Winlength)|| Winlength<0 ||  (Winlength>floor(Frames/multiplier_winleg))
                Winlength = 5;
            end
            app.Winlength = Winlength;

            app.Finishbutton = 0;
            app.Output = [];
            app.winrej = [];
            app.incallback = 0;
            app.trialstag = -1;
            if EEG.trials>1
                app.trialstag = size(EEG.data, 2);
            end
            app.limits	    = [0 1000*(size(EEG.data,2)-1)/EEG.srate];
            app.srate =EEG.srate;
            OutputViewereegpar = f_preparms_eegwaviewer(EEG,0);
            ChanArray = OutputViewereegpar{1};
            if isempty(ChanArray) || any(ChanArray(:)>EEG.nbchan) || any(ChanArray(:)<1)
                ChanArray = [1:EEG.nbchan];
            end
            app.chans = numel(ChanArray);
            app.envelope = 0;
            app.wincolor = [0.7 1 0.9];
            app.mocap = 'off';
            app.setelectrode  = 0;

            if EEG.trials > 1
                if ~isempty(EEG.reject)
                    icacomp=1;
                    superpose=1;
                    reject=1;
                    if icacomp == 1 macrorej  = 'EEG.reject.rejmanual';
                        macrorejE = 'EEG.reject.rejmanualE';
                    else			macrorej  = 'EEG.reject.icarejmanual';
                        macrorejE = 'EEG.reject.icarejmanualE';
                    end
                    if icacomp == 1
                        elecrange = [1:EEG.nbchan];
                    else elecrange = [1:size(EEG.icaweights,1)];
                    end
                    colrej = EEG.reject.rejmanualcol;
                    rej  = eval(macrorej);
                    rejE = eval(macrorejE);
                    eeg_rejmacro; % script macro for generating command and old rejection arrays
                    if ~isempty(eegplotoptions)
                        for i = 1:2:numel(eegplotoptions)
                            if strcmpi(eegplotoptions{i},'winrej')
                                app.winrej = eegplotoptions{i+1};
                            end
                        end
                    end
                end
                if isempty(EEG.reject.rejmanual)
                    app.ALLEEG(app.CURRENTSET).reject.rejmanual = zeros(1,EEG.trials);
                end
                if isempty(EEG.reject.rejmanualE)
                    app.ALLEEG(app.CURRENTSET).reject.rejmanualE  = zeros(EEG.nbchan,EEG.trials);
                end
            end
            set(app.UIFigure,'Name', ['Estudio ' version '   - Plot EEG GUI:',32,EEG.setname]);
            func_updated(app);
        end

        % Button pushed function: Button
        function start_wid(app, event)
            if isempty(app.ALLEEG )
                return;
            end
            EEG = app.ALLEEG (app.CURRENTSET);
            if EEG.trials>1 % time in second or in trials
                Startimes =1;
            else
                Startimes = 0;
            end
            app.Startimes = Startimes;
            app.EditField.Value = num2str(app.Startimes);
            func_updated(app);
        end

        % Button pushed function: XButton
        function left_large(app, event)

            if isempty(app.ALLEEG )
                return;
            end
            EEG = app.ALLEEG (app.CURRENTSET);

            Winlength = app.Winlength ;
            [chaNum,sampleNum,trialNum]=size(EEG.data);
            Frames = sampleNum*trialNum;
            if EEG.trials>1 % time in second or in trials
                multiplier_winleg = size(EEG.data,2);
            else
                multiplier_winleg = EEG.srate;
            end
            if isempty(Winlength)|| Winlength<0 || Winlength>floor(Frames/multiplier_winleg)
                Winlength = 5;
            end
            app.Winlength = Winlength ;
            Startimesdef = str2num(app.EditField.Value);
            % if Startimesdef>0
            Startimes = Startimesdef-fastif(Winlength>=5, round(5*Winlength), 5*Winlength);
            % end
            if Startimes<0
                if EEG.trials>1
                    Startimes=1;
                else
                    Startimes=0;
                end
            end
            app.Startimes = Startimes;
            app.EditField.Value = num2str(app.Startimes);
            func_updated(app);
        end

        % Button pushed function: XButton_2
        function left_small(app, event)
            if isempty(app.ALLEEG )
                return;
            end
            EEG = app.ALLEEG (app.CURRENTSET);
            Winlength = app.Winlength;
            [chaNum,sampleNum,trialNum]=size(EEG.data);
            Frames = sampleNum*trialNum;
            if EEG.trials>1 % time in second or in trials
                multiplier_winleg = size(EEG.data,2);
            else
                multiplier_winleg = EEG.srate;
            end
            if isempty(Winlength)|| Winlength<0 || Winlength>floor(Frames/multiplier_winleg)
                Winlength = 5;
            end
            Startimesdef = str2num(app.EditField.Value);

            Startimes = Startimesdef-fastif(Winlength>=5, round(Winlength), Winlength);
            if Startimes<0
                if observe_EEGDAT.EEG.trials>1
                    Startimes=1;
                else
                    Startimes=0;
                end
            end
            app.Winlength = Winlength;
            app.EditField.Value = num2str(Startimes);
            app.Startimes = Startimes;
            func_updated(app);
        end

        % Button pushed function: XButton_5
        function right_small(app, event)
            if isempty(app.ALLEEG )
                return;
            end
            EEG = app.ALLEEG (app.CURRENTSET);
            Winlength=app.Winlength;

            [chaNum,sampleNum,trialNum]=size(EEG.data);
            Frames = sampleNum*trialNum;
            if EEG.trials>1 % time in second or in trials
                multiplier = size(EEG.data,2);
                multiplier_winleg = size(EEG.data,2);
            else
                multiplier = EEG.srate;
                multiplier_winleg = multiplier;
            end

            if isempty(Winlength)|| Winlength<0 || Winlength>floor(Frames/multiplier_winleg)
                Winlength = 5;
            end
            Startimesdef = str2num(app.EditField.Value);
            if ~isempty(Startimesdef) && isnumeric(Startimesdef) && numel(Startimesdef)==1 && Startimesdef>=0
            else
                Startimesdef = 0;
            end

            Startimes = Startimesdef+fastif(Winlength>=5, round(Winlength), Winlength); %%> add one second
            if Startimes<0
                Startimes=0;
            end

            if isempty(Winlength)|| Winlength<0 ||  (Winlength>floor(Frames/multiplier_winleg))
                Winlength = floor(Frames/multiplier_winleg);
            end
            StartimesMax = max(0,ceil((Frames-1)/multiplier)-Winlength);
            if ndims(EEG.data)==3
                StartimesMax = StartimesMax+1;
            end
            if Startimes>StartimesMax
                Startimes=StartimesMax;
            end
            app.Winlength = Winlength;
            app.EditField.Value = num2str(Startimes);
            app.Startimes = Startimes;
            func_updated(app);
        end

        % Button pushed function: XButton_3
        function right_large(app, event)
            if isempty(app.ALLEEG )
                return;
            end
            EEG = app.ALLEEG (app.CURRENTSET);

            Winlength = app.Winlength ;
            [chaNum,sampleNum,trialNum]=size(EEG.data);
            Frames = sampleNum*trialNum;
            if EEG.trials>1 % time in second or in trials
                multiplier = size(EEG.data,2);
                multiplier_winleg = size(EEG.data,2);
            else
                multiplier = EEG.srate;
                multiplier_winleg = multiplier;
            end

            if isempty(Winlength)|| Winlength<0 || Winlength>floor(Frames/multiplier_winleg)
                Winlength = 5;
            end
            Startimesdef = str2num(app.EditField.Value);
            if ~isempty(Startimesdef) && isnumeric(Startimesdef) && numel(Startimesdef)==1 && Startimesdef>=0
            else
                Startimesdef = 0;
            end

            Startimes = Startimesdef+fastif(Winlength>=5, round(5*Winlength), 5*Winlength); %%> add one second


            if Startimes<0
                Startimes=0;
            end

            if isempty(Winlength)|| Winlength<0 ||  (Winlength>floor(Frames/multiplier_winleg))
                Winlength = floor(Frames/multiplier_winleg);
            end
            StartimesMax = max(0,ceil((Frames-1)/multiplier)-Winlength);
            if EEG.trials>1
                StartimesMax = StartimesMax+1;
            end
            if Startimes>StartimesMax
                Startimes=StartimesMax;
            end
            app.Winlength =Winlength;
            app.EditField.Value = num2str(Startimes);
            app.Startimes = Startimes;
            func_updated(app);
        end

        % Value changed function: EditField
        function edit_time(app, event)
            if isempty(app.ALLEEG )
                return;
            end
            EEG = app.ALLEEG (app.CURRENTSET);
            Startimes =str2num(app.EditField.Value);
            if EEG.trials>1
                Startimes= ceil(Startimes);
            end
            if isempty(Startimes)
                Startimes = 0;
                app.EditField.Value = '0';
            end
            if numel(Startimes)~=1
                Startimes = Startimes(1);
            end
            Winlength = app.Winlength ;
            [chaNum,sampleNum,trialNum]=size(EEG.data);
            Frames = sampleNum*trialNum;
            if EEG.trials>1 % time in second or in trials
                multiplier = size(EEG.data,2);
                multiplier_winleg = size(EEG.data,2);
            else
                multiplier = EEG.srate;
                multiplier_winleg = EEG.srate;
            end

            if isempty(Winlength)|| Winlength<0 ||  (Winlength>floor(Frames/multiplier_winleg))
                Winlength = 5;
            end
            if EEG.trials>1
                Startimes = Startimes-1;
            end
            Startimes = max(0,min(Startimes,ceil((Frames-1)/multiplier)-Winlength));
            if EEG.trials>1
                Startimes = Startimes+1;
            end
            app.Winlength = Winlength ;
            app.EditField.Value = num2str(Startimes);
            app.Startimes = Startimes;
            func_updated(app);
        end

        % Button pushed function: Button_2
        function right_end(app, event)
            if isempty(app.ALLEEG )
                return;
            end
            EEG = app.ALLEEG (app.CURRENTSET);
            Winlength = app.Winlength ;
            [chaNum,sampleNum,trialNum]=size(EEG.data);
            Frames = sampleNum*trialNum;
            if EEG.trials>1 % time in second or in trials
                multiplier = size(EEG.data,2);
                multiplier_winleg = size(EEG.data,2);
            else
                multiplier = EEG.srate;
                multiplier_winleg = multiplier;
            end

            if isempty(Winlength)|| Winlength<0 ||  (Winlength>floor(Frames/multiplier_winleg))
                Winlength = 5;
            end
            StartimesMax = max(0,ceil((Frames-1)/multiplier)-Winlength);
            if EEG.trials>1
                StartimesMax = StartimesMax+1;
            end
            Startimes = StartimesMax;
            app.Startimes = Startimes;
            app.EditField.Value = num2str(app.Startimes);
            app.Winlength =Winlength;
            app.spacing = 50;
            func_updated(app);
        end

        % Window button motion function: UIFigure
        function defmotion(app, event)
            if isempty(app.ALLEEG)
                return;
            end
            EEG = app.ALLEEG (app.CURRENTSET);
            tmppos = get(app.UIAxes, 'currentpoint');
            if  all([tmppos(1,1) >= 0,tmppos(1,2)>= 0])
                Startimes = app.Startimes;
                if EEG.trials>1
                    Startimes=Startimes-1;
                end
                EEG = app.ALLEEG (app.CURRENTSET);
                [ChanNum,Allsamples,tmpnb] = size(EEG.data);
                Allsamples = Allsamples*tmpnb;
                if EEG.trials > 1
                    multiplier = size(EEG.data,2);
                else
                    multiplier = EEG.srate;
                end
                Winlength = app.Winlength ;
                if isempty(Startimes) || Startimes<0 ||  Startimes>(ceil((Allsamples-1)/multiplier)-Winlength)
                    if EEG.trials >1
                        Startimes=1;
                    else
                        Startimes=0;
                    end
                end

                %%determine the time range that will be dispalyed
                lowlim = round(Startimes*multiplier+1);
                if app.incallback
                    app.winrej = [app.winrej(1:end-1,:)' [app.winrej(end,1) tmppos(1)+lowlim app.winrej(end,3:end)]']';
                else
                    if app.trialstag ~= -1
                        tmpval = mod(tmppos(1)+lowlim-1,app.trialstag)/app.trialstag*(app.limits(2)-app.limits(1)+1000/app.srate) + app.limits(1);
                        %                 if app.isfreq, tmpval = tmpval/1000 + app.freqs(1); end
                        %                 set(hh, 'string', num2str(tmpval));
                    else
                        tmpval = (tmppos(1)+lowlim-1)/app.srate;
                        tmppos = get(app.UIAxes, 'currentpoint');
                        tmpelec = round(tmppos(1,2) / app.spacing);
                        tmpelec = min(max(double(tmpelec), 1),app.chans);
                        labls = get(app.UIAxes, 'YtickLabel');
                        if ~app.envelope
                            %                             text(app.UIAxes, tmppos(1,1),tmppos(1,2), labls(tmpelec+1,:));
                            %                             pause(0.5);
                            %                             text(app.UIAxes, tmppos(1,1),tmppos(1,2), '');
                        else
                            text(app.UIAxes, tmppos(1,1),tmppos(1,2), '');
                        end
                    end
                end
            end
        end

        % Window button down function: UIFigure
        function defdowncom(app, event)
            tmppos = get(app.UIAxes, 'currentpoint');
            if isempty(app.ALLEEG) || tmppos(1,2)<=0 || tmppos(1,2)>app.ylims(2)
                return;
            end
            EEG = app.ALLEEG (app.CURRENTSET);
            if app.incallback ~= 1 % interception of nestest calls

                Startimes = app.Startimes;
                if EEG.trials>1
                    Startimes=Startimes-1;
                end
                EEG = app.ALLEEG (app.CURRENTSET);
                [ChanNum,Allsamples,tmpnb] = size(EEG.data);
                Allsamples = Allsamples*tmpnb;
                if EEG.trials > 1
                    multiplier = size(EEG.data,2);
                else
                    multiplier = EEG.srate;
                end
                Winlength = app.Winlength ;
                if isempty(Startimes) || Startimes<0 ||  Startimes>(ceil((Allsamples-1)/multiplier)-Winlength)
                    if EEG.trials >1
                        Startimes=1;
                    else
                        Startimes=0;
                    end
                end
                %                 app.EditField.Value = num2str(Startimes);

                %%determine the time range that will be dispalyed
                lowlim = round(Startimes*multiplier+1);
                highlim = round(min((Startimes+Winlength)*multiplier+1,Allsamples));
                if EEG.trials == 1
                    highlim1 = highlim;
                else
                    highlim1 = app.Xlim(2);
                end
                Trialstag = EEG.pnts;
                Epochintv = [];
                alltag1 = [];
                if (tmppos(1) >= 0) && (tmppos(1) <= highlim1)
                    if EEG.trials>1
                        tmptag = [lowlim:highlim];
                        tmpind = find(mod(tmptag-1,Trialstag) == 0);

                        alltag = tmptag(tmpind);
                        if isempty(tmpind)
                            epochNum = 0;
                        else
                            epochNum = numel(setdiff(tmpind,[0 1 numel(tmptag)]));
                        end
                        % compute epoch number
                        % --------------
                        %                   alltag =   alltag(1:(epochNum+1));
                        GapSize = app.GapSize;
                        alltag1 = alltag;
                        if ~isempty(tmpind)
                            tagnum = (alltag1-1)/Trialstag+1;%%
                            for ii = 1:numel(tmpind)
                                alltag1(ii)  = alltag1(ii)+(ii-1)*GapSize;
                            end
                        end

                        %%add the gap between epochs if any
                        if ~isempty(tmpind)
                            if (numel(tmpind)==1 && tmpind(end) == numel(tmptag)) || (numel(tmpind)==1 && tmpind(1) == 1)
                                Epochintv(1,1) =1;
                                Epochintv(1,2) =numel(lowlim:highlim);
                            else
                                tmpind = unique(setdiff([0,tmpind, numel(tmptag)],1));
                                for ii = 2:numel(tmpind)
                                    GapLeft = tmpind(ii-1)+1;
                                    GapRight = tmpind(ii);
                                    if GapLeft<1
                                        GapLeft =1;
                                    end
                                    if GapRight > numel(tmptag)
                                        GapRight =  numel(tmptag);
                                    end
                                    Epochintv(ii-1,1) = GapLeft+(ii-2)*GapSize;
                                    Epochintv(ii-1,2) = GapRight+(ii-2)*GapSize;
                                end
                            end
                        end
                    end

                    if isempty(app.winrej)
                        Allwin=0;
                    else
                        if EEG.trials == 1
                            Allwin = (app.winrej(:,1) < lowlim+tmppos(1)) & (app.winrej(:,2) > lowlim+tmppos(1));
                        else
                            if ~isempty(Epochintv)
                                Allwin1 = (Epochintv(:,1) < tmppos(1)) & (Epochintv(:,2) > tmppos(1));
                                trialAr = ceil(app.winrej(:,2)/Trialstag);
                                Value_adjust = floor(app.Startimes);
                                if Value_adjust<1
                                    Value_adjust=1;
                                end
                                tagnum  = unique([Value_adjust,tagnum]);
                                trialIndex = tagnum(1:numel(Allwin1));
                                Allwin1 = reshape(Allwin1,1,numel(Allwin1));
                                [xpos111, ypos111] = find(Allwin1==1);
                                if ~isempty(ypos111)
                                    [xpos100,~]   =find(trialAr == trialIndex(ypos111));
                                    if ~isempty(xpos100)
                                        Allwin = zeros(size(app.winrej,1),1);
                                        Allwin(xpos100)=1;
                                    else
                                        Allwin=0;
                                    end
                                else
                                    Allwin=0;
                                end
                            else
                                Allwin=0;
                            end
                        end
                    end
                    if any(Allwin) % remove the mark or select electrode if necessary
                        lowlim = find(Allwin==1);
                        if app.setelectrode  % select electrode
                            tmpelec = app.chans + 1 - round(tmppos(1,2) / app.spacing);
                            tmpelec = min(max(tmpelec, 1), app.chans);
                            app.winrej(lowlim,tmpelec+5) = ~app.winrej(lowlim,tmpelec+5); % set the electrode
                        else  % remove mark
                            app.winrej(lowlim,:) = [];
                        end
                    else
                        if app.trialstag ~= -1 % find nearest trials boundaries if epoched data
                            if isempty(alltag1)
                                alltrialtag = [0:app.trialstag:Allsamples];%%need to further confirm% Mar 28 2024
                            else
                                alltrialtag =  alltag1;
                            end
                            alltrialtag1 = tmpind+lowlim;
                            I1 = find(alltrialtag < (tmppos(1)+lowlim) );
                            if ~isempty(I1) && I1(end) ~= length(alltrialtag1),
                                app.winrej = [app.winrej' [alltrialtag1(I1(end)) round(alltrialtag1(I1(end)+1)/multiplier)*multiplier app.wincolor zeros(1,EEG.nbchan)]']';
                            end
                        else,
                            app.incallback = 1;  % set this variable for callback for continuous data
                            if size(app.winrej,2) < 5
                                app.winrej(:,3:5) = repmat(app.wincolor, [size(app.winrej,1) 1]);
                            end
                            if size(app.winrej,2) < 5+EEG.nbchan
                                app.winrej(:,6:(5+EEG.nbchan)) = zeros(size(app.winrej,1),EEG.nbchan);
                            end
                            app.winrej = [app.winrej' [tmppos(1)+lowlim tmppos(1)+lowlim app.wincolor zeros(1,EEG.nbchan)]']';
                        end
                    end
                    if  EEG.trials> 1
                        func_updated(app);
                    end
                end
            end

        end

        % Window button up function: UIFigure
        function defupcom(app, event)

            tmppos = get(app.UIAxes, 'currentpoint');
            if isempty(app.ALLEEG) || tmppos(1,2)<=0 || tmppos(1,2)>app.ylims(2)
                return;
            end
            EEG = app.ALLEEG (app.CURRENTSET);
            app.incallback = 0;
            [ChanNum,Allsamples,tmpnb] = size(EEG.data);
            Allsamples = Allsamples*tmpnb;
            if strcmp(app.mocap,'on'), app.winrej = app.winrej(end,:);end % nima
            if ~isempty(app.winrej)', ...
                    if app.winrej(end,1) == app.winrej(end,2) % remove unitary windows
                    app.winrej = app.winrej(1:end-1,:);
                    else
                        if app.winrej(end,1) > app.winrej(end,2) % reverse values if necessary
                            app.winrej(end, 1:2) = [app.winrej(end,2) app.winrej(end,1)];
                        end
                        app.winrej(end,1) = max(1, app.winrej(end,1));
                        app.winrej(end,2) = min(Allsamples, app.winrej(end,2));
                        if app.trialstag == -1 % find nearest trials boundaries if necessary
                            I1 = find((app.winrej(end,1) >= app.winrej(1:end-1,1)) & (app.winrej(end,1) <= app.winrej(1:end-1,2)) );
                            if ~isempty(I1)
                                app.winrej(I1,2) = max(app.winrej(I1,2), app.winrej(end,2)); % extend epoch
                                app.winrej = app.winrej(1:end-1,:); % remove if empty match
                            else,
                                I2 = find((app.winrej(end,2) >= app.winrej(1:end-1,1)) & (app.winrej(end,2) <= app.winrej(1:end-1,2)) );
                                if ~isempty(I2)
                                    app.winrej(I2,1) = min(app.winrej(I2,1), app.winrej(end,1)); % extend epoch
                                    app.winrej = app.winrej(1:end-1,:); % remove if empty match
                                else,
                                    I2 = find((app.winrej(end,1) <= app.winrej(1:end-1,1)) & (app.winrej(end,2) >= app.winrej(1:end-1,1)) );
                                    if ~isempty(I2)
                                        app.winrej(I2,:) = []; % remove if empty match
                                    end
                                end
                            end
                        end
                    end
            end
            func_updated(app);
            if strcmp(app.mocap,'on'), show_mocap_for_eegplot(app.winrej); app.winrej = app.winrej(end,:); end % nima
        end

        % Button pushed function: CancelButton
        function cancel_botton(app, event)
            app.Finishbutton = 1;
            app.Output = [];
            app.delete;
        end

        % Button pushed function: RejectButton
        function reject_botton(app, event)
            if isempty(app.ALLEEG)
                app.Finishbutton = 1;
                app.Output = [];
                return;
            end
            EEG = app.ALLEEG (app.CURRENTSET);
            %%----------------for continous EEG-----------------
            if EEG.trials ==1
                if ~isempty(app.winrej)
                    timeRange = app.winrej(:,1:2);
                    [xpos,ypos] = find(timeRange(:,2)>EEG.pnts);
                    if isempty(xpos)
                        timeRange(xpos,2) = EEG.pnts;
                    end
                    EEG = pop_select( EEG, 'rmpoint',timeRange);
                    if isfield(EEG,'EVENTLIST')
                        EEG.EVENTLIST = [];
                    end
                    EEG    = eeg_checkset( EEG, 'eventconsistency' );
                    if isfield(EEG,'event')%%Update the events
                        event =  EEG.event;
                        for Numofevent = 1:length(event)
                            try
                                expression = event(Numofevent).type;
                                expression = strrep(expression,'(','=');
                                expression = strrep(expression,')','');
                                tokave = regexpi(expression, '[n]*b[in]*(\d+)\s*=', 'tokens','ignorecase');
                                if ~isempty(tokave)
                                    expression  = strrep(expression,strcat('B',tokave{1,1},'='),'');
                                    if iscell(expression)
                                        expression = expression{1,1};
                                    end
                                    EEG.event(Numofevent).type=expression;
                                    EEG.event(Numofevent).urevent=expression;
                                end
                            catch

                            end
                        end
                    end
                else
                    EEG = [];
                end
            end
            %%for epoched EEG
            if EEG.trials >1
                if ~isempty(app.winrej)
                    if isempty(EEG.reject.rejmanual)
                        EEG.reject.rejmanual = zeros(1,EEG.trials);
                    end
                    if isempty(EEG.reject.rejmanualE)
                        EEG.reject.rejmanualE  = zeros(EEG.nbchan,EEG.trials);
                    end
                    trialAr =  ceil(app.winrej(:,2)/EEG.pnts);
                    trialAr = reshape(trialAr,1,numel(trialAr));
                    [xpos,ypos] = find(trialAr>EEG.trials);
                    if ~isempty(ypos)
                        trialAr(ypos) = [];
                    end
                    try rejmanual = EEG.reject.rejmanual; catch rejmanual = zeros(1,EEG.trials); end
                    [xpos1,ypos1] = find(rejmanual==1);
                    trials_rm = [];
                    trials_new = [];
                    if ~isempty(ypos1)
                        trial_common = intersect( ypos1,trialAr );
                        if ~isempty(trial_common)
                            trials_rm = setdiff(ypos1,trial_common);
                            trials_new = setdiff(trialAr,trial_common);
                        else
                            trials_rm = ypos1;
                            trials_new = trialAr;
                        end
                    else
                        trials_new = trialAr;
                    end
                    for numofevent = 1:length(EEG.EVENTLIST.eventinfo)
                        bepoch(1,numofevent) =  EEG.EVENTLIST.eventinfo(numofevent).bepoch;
                    end

                    if ~isempty(trials_rm)%%empty the marks
                        for Numofrm = 1:numel(trials_rm)
                            EEG.reject.rejmanual(trials_rm(Numofrm)) = 0;
                            EEG.reject.rejmanualE(:,trials_rm(Numofrm)) = zeros(numel(EEG.reject.rejmanualE(:,1)),1);
                            epochcell =  EEG.epoch(trials_rm(Numofrm)).eventflag;
                            if iscell(epochcell)
                                for Numofcell = 1:length(epochcell)
                                    EEG.epoch(trials_rm(Numofrm)).eventflag{Numofcell} = 0;
                                end
                            else
                                EEG.epoch(trials_rm(Numofrm)).eventflag = zeros(1,length(epochcell));
                            end
                            [xpos2,ypos2]=  find(bepoch==trials_rm(Numofrm));
                            if ~isempty(ypos2)
                                EEG.EVENTLIST.eventinfo(ypos2).flag = 0;
                            end
                        end
                    end

                    %%add marks for new epochs
                    if ~isempty(trials_new)
                        for Numofnew = 1:numel(trials_new)
                            EEG.reject.rejmanual(trials_new(Numofnew))=1;
                            isRT=1;
                            for Numofchan = 1:EEG.nbchan
                                [EEG errorm]= markartifacts(EEG, app.flag, 1:EEG.nbchan, Numofchan, trials_new(Numofnew), isRT);
                            end
                        end
                    end
                else
                    EEG = [];
                end
            end
            app.Finishbutton = 1;
            app.Output = EEG;
        end

        % Size changed function: UIFigure
        function figure_resize(app, event)
            position = app.UIFigure.Position;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @figure_resize, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @defdowncom, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @defupcom, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @defmotion, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @start_wid, true);
            app.Button.Text = '|<';
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @left_large, true);
            app.XButton.Text = '-5X';
            app.XButton_2 = uibutton(app.UIFigure, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @left_small, true);
            app.XButton_2.Text = '-X';
            app.XButton_5 = uibutton(app.UIFigure, 'push');
            app.XButton_5.ButtonPushedFcn = createCallbackFcn(app, @right_small, true);
            app.XButton_5.Text = '+X';
            app.XButton_3 = uibutton(app.UIFigure, 'push');
            app.XButton_3.ButtonPushedFcn = createCallbackFcn(app, @right_large, true);
            app.XButton_3.Text = '+5X';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @right_end, true);
            app.Button_2.Text = '>|';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @edit_time, true);
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel_botton, true);
            app.CancelButton.Text = 'Cancel';
            app.RejectButton = uibutton(app.UIFigure, 'push');
            app.RejectButton.ButtonPushedFcn = createCallbackFcn(app, @reject_botton, true);
            app.RejectButton.Text = 'Reject';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEG_select_segement_artifact_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end