classdef EEG_trial_rejection_sumr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ExporttrialinformationButton  matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        CloseButton                   matlab.ui.control.Button
        Edit_pos_width_4              matlab.ui.control.EditField
        Button_2                      matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        TrialInformationLabel         matlab.ui.control.Label
        oftrialswererejectedLabel     matlab.ui.control.Label
        Edit_pos_width_3              matlab.ui.control.EditField
        UITable                       matlab.ui.control.Table
        Edit_pos_width                matlab.ui.control.EditField
        EEGNameLabel                  matlab.ui.control.Label
    end


    properties (Access = public)
        Finishbutton % Description
        Output % Description
        EEGname_indx % Description
    end

    properties (Access = private)
        ALLEEG % Description
        ALLERP % Description
        ERPset % Description
    end

    methods (Access = private)

        function results = func_updated(app)
            ALLEEG = app.ALLEEG;
            if app.ERPset> length(ALLEEG)
                app.ERPset=length(ALLEEG);
            end

            try EEG = ALLEEG(app.ERPset); catch  end
            [EEG, tprej, acce, rej, histoflags,  com] = pop_summary_AR_eeg_detection(EEG, 'none');
            ERP = pop_averager(EEG, 'Criterion', 'good', 'DSindex', 1, 'SEM', 'off', 'DQ_custom_wins', 1, 'DQ_flag', 0, 'DQ_preavg_txt', 0);
            for ii = 1:length(ERP.ntrials.accepted)
                data{ii,1} = [num2str(ii),'.',ERP.bindescr{ii}];
                data{ii,2} =  [num2str(ERP.ntrials.accepted(ii)+ERP.ntrials.rejected(ii)+ERP.ntrials.invalid(ii))];
                data{ii,3} =      [num2str(ERP.ntrials.accepted(ii)),32,'(',num2str(roundn(100*ERP.ntrials.accepted(ii)/(ERP.ntrials.accepted(ii)+ERP.ntrials.rejected(ii)+ERP.ntrials.invalid(ii)),-1)),32,'%)'];
                data{ii,4} =     [num2str(ERP.ntrials.rejected(ii)),32,'(',num2str(roundn(100*ERP.ntrials.rejected(ii)/(ERP.ntrials.accepted(ii)+ERP.ntrials.rejected(ii)+ERP.ntrials.invalid(ii)),-1)),32,'%)'];
                data{ii,5} =     [num2str(ERP.ntrials.invalid(ii)),32,'(',num2str(roundn(100*ERP.ntrials.invalid(ii)/(ERP.ntrials.accepted(ii)+ERP.ntrials.rejected(ii)+ERP.ntrials.invalid(ii)),-1)),32,'%)'];
                data{ii,6} = [num2str(histoflags(ii,2))];%%F2
                data{ii,7} = [num2str(histoflags(ii,3))];%%F3
                data{ii,8} = [num2str(histoflags(ii,4))];
                data{ii,9} = [num2str(histoflags(ii,5))];
                data{ii,10} = [num2str(histoflags(ii,6))];
                data{ii,11} = [num2str(histoflags(ii,7))];
                data{ii,12} = [num2str(histoflags(ii,8))];
            end
            app.UITable.Data = data;
            rejectionpec = sum(ERP.ntrials.rejected(:))/(sum(ERP.ntrials.accepted(:))+ sum(ERP.ntrials.rejected(:))+sum(ERP.ntrials.invalid(:)));
            app.Edit_pos_width_3.Value = num2str(roundn(100*rejectionpec,-2));
            try app.Edit_pos_width.Value = EEG.setname; catch app.Edit_pos_width.Value = '';end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            painterplabapp(app);
            setfonterplab(app);
            % Name & version
            %
            app.Edit_pos_width_3.BackgroundColor = [1 1 1];
            version = geterplabversion;
            set(app.UIFigure,'Name', ['Estudio ' version '   - trial information for epoched EEG GUI']);
            try
                app.ALLEEG = varargin{1};
            catch
                app.ALLEEG = [];
            end
            try
                app.ERPset=varargin{2};
            catch
                app.ERPset =1;
            end
            app.Edit_pos_width.BackgroundColor = [1 1 1];
            if  ~isempty(app.ALLEEG)
                app.Edit_pos_width_4.Value='1';
                app.ERPset=1;
                func_updated(app);
            end
            app.Finishbutton = 0;
            app.Output = [];
        end

        % Value changed function: Edit_pos_width
        function eeg_name(app, event)


        end

        % Display data changed function: UITable
        function trial_rejection_table(app, event)
            newDisplayData = app.UITable.DisplayData;
        end

        % Value changed function: Edit_pos_width_3
        function rejected_trials(app, event)

        end

        % Button pushed function: Button
        function pre_erpset(app, event)

            ERPset = str2num(app.Edit_pos_width_4.Value);
            ERPset = ERPset-1;
            if isempty(ERPset)|| any(ERPset<=0) || numel(ERPset)~=1 || any(ERPset>length(app.ALLEEG))
                ERPset=1;
                msgboxText = ['The EEGset should be between 1 and ',32,num2str(length(app.ALLEEG))];
                app.TextArea.Value = msgboxText;
            end
            app.ERPset =ERPset;
            app.Edit_pos_width_4.Value =num2str(ERPset);
            func_updated(app);
        end

        % Button pushed function: Button_2
        function next_erpset(app, event)
            ERPset = str2num(app.Edit_pos_width_4.Value);
            ERPset = ERPset+1;
            if isempty(ERPset)|| any(ERPset<=0) || numel(ERPset)~=1 || any(ERPset>length(app.ALLEEG))
                ERPset=length(app.ALLEEG);
                msgboxText = ['The EEGset should be between 1 and ',32,num2str(length(app.ALLEEG))];
                app.TextArea.Value = msgboxText;
            end
            app.ERPset =ERPset;
            app.Edit_pos_width_4.Value =num2str(ERPset);
            func_updated(app);
        end

        % Value changed function: Edit_pos_width_4
        function erpset_edit(app, event)
            ERPset = str2num(app.Edit_pos_width_4.Value);
            ERPset = ERPset+1;
            if isempty(ERPset)|| any(ERPset<=0) || numel(ERPset)~=1 || any(ERPset>length(app.ALLEEG))
                ERPset=1;
                msgboxText = ['The EEGset should be between 1 and ',32,num2str(length(app.ALLEEG))];
                app.TextArea.Value = msgboxText;
            end
            app.ERPset =ERPset;
            app.Edit_pos_width_4.Value =num2str(ERPset);
            func_updated(app);
        end

        % Button pushed function: CloseButton
        function exit(app, event)
            app.Finishbutton = 1;
            app.Output = [];
            app.delete;
        end

        % Value changed function: TextArea
        function error_message(app, event)
            value = app.TextArea.Value;
        end

        % Button pushed function: ExporttrialinformationButton
        function export_reject(app, event)
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            ALLEEG = app.ALLEEG;
            LASTCOM =  pop_eeg_save_trial_information(ALLEEG);
            msgboxText = ['It is done for exporting trial information'];
            app.TextArea.Value = msgboxText;
            eegh(LASTCOM);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EEGNameLabel = uilabel(app.UIFigure);
            app.EEGNameLabel.Text = 'EEG Name';
            app.Edit_pos_width = uieditfield(app.UIFigure, 'text');
            app.Edit_pos_width.ValueChangedFcn = createCallbackFcn(app, @eeg_name, true);
            app.Edit_pos_width.Editable = 'off';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Bin'; 'Total trials'; 'Included trials'; 'Rejected trials'; 'Invalid trials'; 'F2'; 'F3'; 'F4'; 'F5'; 'F6'; 'F7'; 'F8'};
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @trial_rejection_table, true);
            app.Edit_pos_width_3 = uieditfield(app.UIFigure, 'text');
            app.Edit_pos_width_3.ValueChangedFcn = createCallbackFcn(app, @rejected_trials, true);
            app.Edit_pos_width_3.Editable = 'off';
            app.oftrialswererejectedLabel = uilabel(app.UIFigure);
            app.oftrialswererejectedLabel.Text = '% of  trials were rejected';
            app.TrialInformationLabel = uilabel(app.UIFigure);
            app.TrialInformationLabel.Text = 'Trial Information';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @pre_erpset, true);
            app.Button.Text = '<';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @next_erpset, true);
            app.Button_2.Text = '>';
            app.Edit_pos_width_4 = uieditfield(app.UIFigure, 'text');
            app.Edit_pos_width_4.ValueChangedFcn = createCallbackFcn(app, @erpset_edit, true);
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @exit, true);
            app.CloseButton.Text = 'Close';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @error_message, true);
            app.ExporttrialinformationButton = uibutton(app.UIFigure, 'push');
            app.ExporttrialinformationButton.ButtonPushedFcn = createCallbackFcn(app, @export_reject, true);
            app.ExporttrialinformationButton.Text = 'Export trial information';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEG_trial_rejection_sumr(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end