classdef ERP_Tab_rename_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        TextArea      matlab.ui.control.TextArea
        ApplyButton   matlab.ui.control.Button
        CancelButton  matlab.ui.control.Button
        ResetButton   matlab.ui.control.Button
        UITable       matlab.ui.control.Table
    end


    properties (Access = private)
    end

    properties (Access = public)
        Output % Description
        Finishbutton % Description
        ALLERP % Description
        ERPArray % Description
    end

    methods (Access = private)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            painterplabapp(app);
            setfonterplab(app);
            % Name & version
            %
            version = geterplabversion;
            set(app.UIFigure,'Name', ['Estudio ' version '   -   Rename ERP files GUI']);
            
            try ALLERP = varargin{1}; catch ALLERP = [];end
            if isempty(ALLERP)
                app.TextArea.Value = 'Please input validate ERP sets';
                return;
            end
            app.ALLERP = ALLERP;

            try ERPArray = varargin{2}; catch ERPArray = [];end
            if isempty(ERPArray) || numel(ERPArray)~=length(ALLERP) ||  any(ERPArray<1)
                ERPArray= [1:length(ALLERP)];
            end
            app.ERPArray = ERPArray;

            for Numoferp = 1:numel(ALLERP)
                rowNames{Numoferp} = ERPArray(Numoferp);
                data{Numoferp,1} = ALLERP(Numoferp).erpname;
                data{Numoferp,2} = ALLERP(Numoferp).erpname;
            end
            app.UITable.RowName = rowNames;
            app.UITable.Data = data;

            app.Finishbutton = 0;
            app.Output = [];
        end

        % Button pushed function: ResetButton
        function reset_erpname(app, event)
            ALLERP = app.ALLERP;
            ERPArray=app.ERPArray ;
            for Numoferp = 1:numel(ALLERP)
                rowNames{Numoferp} = ERPArray(Numoferp);
                data{Numoferp,1} = ALLERP(Numoferp).erpname;
                data{Numoferp,2} = ALLERP(Numoferp).erpname;
            end
            app.UITable.RowName = rowNames;
            app.UITable.Data = data;
        end

        % Button pushed function: CancelButton
        function cancel(app, event)
            app.Finishbutton = 1;
            app.Output = [];
        end

        % Button pushed function: ApplyButton
        function apply(app, event)
            data = app.UITable.Data;
            ALLERP = app.ALLERP;
            ERPArray=app.ERPArray ;
            for Numoferp = 1:numel(ALLERP)
                newName = data{Numoferp,2};
                [~, newName, ~] = fileparts(newName) ;
                if ~isempty(newName)
                    ALLERP(Numoferp).erpname = newName;
                else
                    app.TextArea.Value = ['ERPset',32,num2str(ERPArray(Numoferp)),32,': You didnot name it and we threfore used original name'];
                end
            end
            
            app.Finishbutton = 1;
            app.Output = ALLERP;
        end

        % Value changed function: TextArea
        function errormessage(app, event)
            value = app.TextArea.Value;
        end

        % Cell edit callback: UITable
        function cell_edit_changes(app, event)
            indices = event.Indices;
            newData = event.NewData;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Original erpname'; 'New erpname'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @cell_edit_changes, true);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @reset_erpname, true);
            app.ResetButton.Text = 'Reset';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @apply, true);
            app.ApplyButton.Text = 'Apply';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @errormessage, true);
            app.TextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ERP_Tab_rename_gui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end