classdef ERP_spectral_analysis_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BinsEditField                  matlab.ui.control.EditField
        BinsEditFieldLabel             matlab.ui.control.Label
        BrowseButton_2                 matlab.ui.control.Button
        BrowseButton                   matlab.ui.control.Button
        ChannelsEditField              matlab.ui.control.EditField
        ChannelsEditFieldLabel         matlab.ui.control.Label
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        FreqrangeminmaxEditField       matlab.ui.control.EditField
        FreqrangeminmaxEditFieldLabel  matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        ButtonGroup                    matlab.ui.container.ButtonGroup
        OffButton                      matlab.ui.control.RadioButton
        OnButton                       matlab.ui.control.RadioButton
        OkayButton                     matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        DisplayinButtonGroup           matlab.ui.container.ButtonGroup
        dBButton                       matlab.ui.control.RadioButton
        PhaseButton                    matlab.ui.control.RadioButton
        PowerButton                    matlab.ui.control.RadioButton
        AmplitudeButton                matlab.ui.control.RadioButton
    end


    properties (Access = public)
        output % Description
        Finishbutton % Description
    end

    properties (Access = private)
        srate % Description
        ERP % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            painterplabapp(app);
            setfonterplab(app);
            % Name & version
            %
            try
                plotset = varargin{1};
            catch
                plotset = 2;
            end

            try
                hamwin = varargin{2};
            catch
                hamwin = 0;
            end

            try
                freqrange = varargin{3};
            catch
                freqrange = [0 100];
            end

            try
                app.srate = varargin{4};
            catch
                app.srate = 256;
            end
            try
                app.ERP = varargin{5};
            catch
                app.ERP =[];
            end
            if isempty(freqrange) || numel(freqrange)~=2 || any(freqrange(:)>128)
                freqrange = [0 128];
            end
            version = geterplabversion;
            set(app.UIFigure,'Name', ['ERPLAB EStudio' version '   -  Spectral analysis GUI']);

            app.DisplayinButtonGroup.Title = 'Display in:';
            if plotset==2
                app.AmplitudeButton.Value = 0;
                app.PhaseButton.Value = 1;
                app.PowerButton.Value = 0;
                app.dBButton.Value = 0;
            elseif plotset==3
                app.AmplitudeButton.Value = 0;
                app.PhaseButton.Value = 0;
                app.PowerButton.Value = 1;
                app.dBButton.Value = 0;
            elseif plotset==4
                app.AmplitudeButton.Value = 0;
                app.PhaseButton.Value = 0;
                app.PowerButton.Value = 0;
                app.dBButton.Value = 1;
            else
                app.AmplitudeButton.Value = 1;
                app.PhaseButton.Value = 0;
                app.PowerButton.Value = 0;
                app.dBButton.Value = 0;
            end
            if hamwin==1
                app.OffButton.Value=0;
                app.OnButton.Value=1;
            else
                app.OffButton.Value=1;
                app.OnButton.Value=0;
            end
            app.FreqrangeminmaxEditField.Value = num2str(freqrange);

            app.output =[];
            app.Finishbutton = 0;
            if ~isempty(app.ERP)
                %%chan array
                chan_label_select = [1:app.ERP.nchan];
                chanset = vect2colon(chan_label_select,'Sort', 'on');
                chanset = erase(chanset,{'[',']'});
                app.ChannelsEditField.Value = chanset;
                %%bin array
                binarray = [1:app.ERP.nbin];
                binarray = vect2colon(binarray,'Sort', 'on');
                binarray = erase(binarray,{'[',']'});
                app.BinsEditField.Value = binarray;
            end
        end

        % Button pushed function: CancelButton
        function cancel(app, event)
            app.output =[];
            app.Finishbutton = 1;
        end

        % Button pushed function: OkayButton
        function okay(app, event)

            if  app.AmplitudeButton.Value == 1
                displayValue=1;
            elseif app.PhaseButton.Value ==1
                displayValue=2;
            elseif app.PowerButton.Value ==1
                displayValue=3;
            elseif app.dBButton.Value ==1
                displayValue=4;
            else
                displayValue=1;
            end
            if app.OffButton.Value==1
                hamwind = 0;
            else
                hamwind = 1;
            end

            freqrange = str2num(app.FreqrangeminmaxEditField.Value);
            if isempty(freqrange) || numel(freqrange)~=2 || any(freqrange(:)>128)
                freqrange = [0 floor(app.srate/2)];
            end

            binArray  = str2num(app.BinsEditField.Value);
            if isempty(binArray) || any(binArray(:)>app.ERP.nbin) || any(binArray(:)<1)
                binArray = 1:app.ERP.nbin;
            end

            chanArray =  str2num(app.ChannelsEditField.Value);
            if isempty(chanArray) || any(chanArray(:)>app.ERP.nchan) || any(chanArray(:)<1)
                chanArray = [1:app.ERP.nchan];
            end
            
            app.output = {displayValue,hamwind,freqrange,app.srate,binArray,chanArray};
            app.Finishbutton = 1;
        end

        % Value changed function: FreqrangeminmaxEditField
        function freq_range(app, event)
            freqrange = str2num(app.FreqrangeminmaxEditField.Value);
            if isempty(freqrange) || numel(freqrange)~=2 || any(freqrange(:)>128) || any(freqrange(:)<0)
                freqrange = [0 floor(app.srate/2)];
            end
            app.FreqrangeminmaxEditField.Value = num2str(freqrange);
        end

        % Value changed function: ChannelsEditField
        function chan_edit(app, event)
            chanrray = str2num(app.ChannelsEditField.Value);
            if isempty(chanrray) ||  any(chanrray(:)>app.ERP.nchan) || any(chanrray(:)<=0)
                chan_label_select = [1:app.ERP.nchan];
                chanset = vect2colon(chan_label_select,'Sort', 'on');
                chanset = erase(chanset,{'[',']'});
                app.ChannelsEditField.Value = chanset;
            end

        end

        % Button pushed function: BrowseButton
        function chan_browse(app, event)
            for Numofchan = 1:app.ERP.nchan
                try
                    listb{Numofchan}= strcat(num2str(Numofchan),'.',app.ERP.chanlocs(Numofchan).labels);
                catch
                    listb{Numofchan}= strcat(num2str(Numofchan),'.','chan',num2str(Numofchan));
                end
            end
            chanArray =  str2num(app.ChannelsEditField.Value);
            if isempty(chanArray) || any(chanArray(:)>app.ERP.nchan) || any(chanArray(:)<1)
                chanArray = [1:app.ERP.nchan];
            end
            titlename = 'Select Channel(s):';
            chan_label_select = browsechanbinGUI(listb, chanArray, titlename);
            if ~isempty(chan_label_select)
                chanset = vect2colon(chan_label_select,'Sort', 'on');
                chanset = erase(chanset,{'[',']'});
                app.ChannelsEditField.Value=chanset;
            end
        end

        % Value changed function: BinsEditField
        function bin_edit(app, event)
            binarray = str2num(app.BinsEditField.Value);
            if isempty(binarray) || any(binarray(:)>app.ERP.nbin) || any(binarray(:)<=0)
                binarray = [1:app.ERP.nbin];
                binarray = vect2colon(binarray,'Sort', 'on');
                binarray = erase(binarray,{'[',']'});
                app.BinsEditField.Value = binarray;
            end
        end

        % Button pushed function: BrowseButton_2
        function bin_browse(app, event)

            for Numofbin = 1:length(app.ERP.bindescr)
                listb{Numofbin} = char(strcat(num2str(Numofbin),'.',app.ERP.bindescr{Numofbin}));
            end
            indxlistb  = str2num(app.BinsEditField.Value);
            if isempty(indxlistb) || any(indxlistb(:)>app.ERP.nbin) || any(indxlistb(:)<1)
                indxlistb = 1:app.ERP.nbin;
            end
            titlename = 'Select Bin(s):';
            %----------------judge the number of latency/latencies--------
            bin_label_select = browsechanbinGUI(listb, indxlistb, titlename);
            if ~isempty(bin_label_select)
                binset = vect2colon(bin_label_select,'Sort', 'on');
                binset = erase(binset,{'[',']'});
                app.BinsEditField.Value=binset;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DisplayinButtonGroup = uibuttongroup(app.UIFigure);
            app.DisplayinButtonGroup.Title = 'Display in: ';
            app.AmplitudeButton = uiradiobutton(app.DisplayinButtonGroup);
            app.AmplitudeButton.Text = 'Amplitude';
            app.AmplitudeButton.Value = true;
            app.PowerButton = uiradiobutton(app.DisplayinButtonGroup);
            app.PowerButton.Text = 'Power';
            app.PhaseButton = uiradiobutton(app.DisplayinButtonGroup);
            app.PhaseButton.Text = 'Phase';
            app.dBButton = uiradiobutton(app.DisplayinButtonGroup);
            app.dBButton.Text = 'dB';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.OkayButton = uibutton(app.UIFigure, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @okay, true);
            app.OkayButton.Text = 'Okay';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.OnButton = uiradiobutton(app.ButtonGroup);
            app.OnButton.Text = 'On';
            app.OnButton.Value = true;
            app.OffButton = uiradiobutton(app.ButtonGroup);
            app.OffButton.Text = 'Off';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Hamming window:'};
            app.ButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.FreqrangeminmaxEditFieldLabel = uilabel(app.ButtonGroup_2);
            app.FreqrangeminmaxEditFieldLabel.Text = 'Freq. range [min max]';
            app.FreqrangeminmaxEditField = uieditfield(app.ButtonGroup_2, 'text');
            app.FreqrangeminmaxEditField.ValueChangedFcn = createCallbackFcn(app, @freq_range, true);
            app.ChannelsEditFieldLabel = uilabel(app.UIFigure);
            app.ChannelsEditFieldLabel.Text = 'Channels';
            app.ChannelsEditField = uieditfield(app.UIFigure, 'text');
            app.ChannelsEditField.ValueChangedFcn = createCallbackFcn(app, @chan_edit, true);
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @chan_browse, true);
            app.BrowseButton.Text = 'Browse';
            app.BrowseButton_2 = uibutton(app.UIFigure, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @bin_browse, true);
            app.BrowseButton_2.Text = 'Browse';
            app.BinsEditFieldLabel = uilabel(app.UIFigure);
            app.BinsEditFieldLabel.Text = 'Bins';
            app.BinsEditField = uieditfield(app.UIFigure, 'text');
            app.BinsEditField.ValueChangedFcn = createCallbackFcn(app, @bin_edit, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ERP_spectral_analysis_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end