classdef EStudio_plot_set_waves < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        OkayButton    matlab.ui.control.Button
        CancelButton  matlab.ui.control.Button
        ButtonGroup   matlab.ui.container.ButtonGroup
        OffButton     matlab.ui.control.RadioButton
        OnButton      matlab.ui.control.RadioButton
    end


    properties (Access = public)
        output % Description
        Finishbutton % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            painterplabapp(app);
            setfonterplab(app);
            % Name & version
            %
            try
                plotset = varargin{1};
            catch
                plotset = 1;
            end
            if isempty(plotset) || numel(plotset)~=1 || (plotset~=0 && plotset~=1)
                plotset=1;
            end
            try
                dataindex = varargin{2};
            catch
                dataindex = 1;
            end

            version = geterplabversion;
            set(app.UIFigure,'Name', ['ERPLAB ' version '   -   EStudio wave plot settings GUI']);
            if dataindex==1
                app.ButtonGroup.Title = 'Automatic plotting for EEG sets';
            elseif dataindex==2
                app.ButtonGroup.Title = 'Automatic plotting for ERP sets';
            elseif dataindex==3
                app.ButtonGroup.Title = 'Automatic plotting for MVPC sets';
            end
            app.OffButton.Value = ~plotset;
            app.OnButton.Value = plotset;
            app.output =[];
            app.Finishbutton = 0;
        end

        % Button pushed function: CancelButton
        function cancel(app, event)
            app.output =[];
            app.Finishbutton = 1;
        end

        % Button pushed function: OkayButton
        function okay(app, event)

            app.output = app.OnButton.Value;
            app.Finishbutton = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.OnButton = uiradiobutton(app.ButtonGroup);
            app.OnButton.Text = 'On';
            app.OnButton.Value = true;
            app.OffButton = uiradiobutton(app.ButtonGroup);
            app.OffButton.Text = 'Off';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.OkayButton = uibutton(app.UIFigure, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @okay, true);
            app.OkayButton.Text = 'Okay';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EStudio_plot_set_waves(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end