classdef EStudio_pos_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Label_3                        matlab.ui.control.Label
        Edit_pos_width_5               matlab.ui.control.EditField
        XLabel_2                       matlab.ui.control.Label
        Edit_pos_width_4               matlab.ui.control.EditField
        MinimumwindowsizeLabel         matlab.ui.control.Label
        Edit_pos_width_3               matlab.ui.control.EditField
        XLabel                         matlab.ui.control.Label
        Edit_pos_width_2               matlab.ui.control.EditField
        CurrentmonitorresolutionLabel  matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Edit_pos_height                matlab.ui.control.EditField
        HeightLabel                    matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        WidthLabel                     matlab.ui.control.Label
        OkayButton                     matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        WindowsizeforEStudiowidthheightinLabel  matlab.ui.control.Label
        Edit_pos_width                 matlab.ui.control.EditField
    end


    properties (Access = public)
        output % Description
        Finishbutton % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            painterplabapp(app);
            setfonterplab(app);
            % Name & version
            %
            version = geterplabversion;
            set(app.UIFigure,'Name', ['ERPLAB ' version '   -   EStudio Window Size Editor GUI']);
            try
                Pos = varargin{1};
            catch
                Pos = [75,75];
            end
            if isempty(Pos)|| ~isnumeric(Pos) || numel(Pos)~=2
                Pos = [75,75];
            end
            Pos(2) = abs(Pos(2));
            app.Edit_pos_width.Value = num2str(roundn(Pos(1),-2));
            app.Edit_pos_height.Value = num2str(roundn(Pos(2),-2));
            try
                ScreenPos =  get( groot, 'Screensize' );
            catch
                ScreenPos =  get( 0, 'Screensize' );
            end
            try   app.Edit_pos_width_2.Value = num2str(ScreenPos(3)); catch end
            try   app.Edit_pos_width_3.Value = num2str(ScreenPos(4));catch end
%             app.Edit_pos_width_4.Value = num2str(roundn(Pos(1),-2));
%             app.Edit_pos_width_5.Value = num2str(roundn(Pos(2),-2));
        end

        % Value changed function: Edit_pos_width
        function Postion_edit_width(app, event)
            Pos = str2num(app.Edit_pos_width.Value);
            if isempty(Pos) || numel(Pos)~=1 || any(Pos<=0)
                app.Edit_pos_width.Value = '';
                msgboxText = ['The width for EStudio window size must have only one positive value.'];
                title = 'EStudio_pos_gui()';
                errorfound(sprintf(msgboxText), title);
                Pos = [75];
            end
            app.Edit_pos_width.Value = num2str(roundn(Pos,-2));
        end

        % Button pushed function: CancelButton
        function Cancel(app, event)
            app.output =[];
            app.Finishbutton = 1;
        end

        % Button pushed function: OkayButton
        function Okay(app, event)
            Pos(1) = str2num(app.Edit_pos_width.Value);
            Pos(2) = str2num(app.Edit_pos_height.Value);
            if isempty(Pos(1))|| isempty(Pos(2)) || numel(Pos)~=2
                app.Edit_pos_width.Value = '';
                msgboxText = ['EStudio window size must have two numbers. We therefore used [75 75]'];
                title = 'EStudio_pos_gui()';
                errorfound(sprintf(msgboxText), title);
                Pos = [75 75];
            end
            if Pos(2)<=0
                app.Edit_pos_width.Value = '';
                msgboxText = ['The height of EStudio window size must be a positive value. We therefore used [75 75]'];
                title = 'EStudio_pos_gui()';
                errorfound(sprintf(msgboxText), title);
                Pos = [75 75];
            end
            app.output =roundn(Pos,-2);
            app.Finishbutton = 1;
        end

        % Value changed function: Edit_pos_height
        function Postion_edit_height(app, event)
            Pos = str2num(app.Edit_pos_height.Value);
            if isempty(Pos) || numel(Pos)~=1 || any(Pos<=0)
                app.Edit_pos_width.Value = '';
                msgboxText = ['The height for EStudio window size must have only one positive value.'];
                title = 'EStudio_pos_gui()';
                errorfound(sprintf(msgboxText), title);
                Pos = [75];
            end
            app.Edit_pos_height.Value = num2str(roundn(Pos,-2));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Edit_pos_width = uieditfield(app.UIFigure, 'text');
            app.Edit_pos_width.ValueChangedFcn = createCallbackFcn(app, @Postion_edit_width, true);
            app.WindowsizeforEStudiowidthheightinLabel = uilabel(app.UIFigure);
            app.WindowsizeforEStudiowidthheightinLabel.Text = ' Window size for EStudio (width, height) in %';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @Cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.OkayButton = uibutton(app.UIFigure, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @Okay, true);
            app.OkayButton.Text = 'Okay';
            app.WidthLabel = uilabel(app.UIFigure);
            app.WidthLabel.Text = 'Width:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = ' %,';
            app.HeightLabel = uilabel(app.UIFigure);
            app.HeightLabel.Text = 'Height:';
            app.Edit_pos_height = uieditfield(app.UIFigure, 'text');
            app.Edit_pos_height.ValueChangedFcn = createCallbackFcn(app, @Postion_edit_height, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = ' %';
            app.CurrentmonitorresolutionLabel = uilabel(app.UIFigure);
            app.CurrentmonitorresolutionLabel.Text = 'Current monitor resolution:';
            app.Edit_pos_width_2 = uieditfield(app.UIFigure, 'text');
            app.Edit_pos_width_2.Enable = 'off';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.Edit_pos_width_3 = uieditfield(app.UIFigure, 'text');
            app.Edit_pos_width_3.Enable = 'off';
            app.MinimumwindowsizeLabel = uilabel(app.UIFigure);
            app.MinimumwindowsizeLabel.Text = 'Minimum window size:';
            app.Edit_pos_width_4 = uieditfield(app.UIFigure, 'text');
            app.Edit_pos_width_4.Enable = 'off';
            app.Edit_pos_width_4.Value = '48';
            app.XLabel_2 = uilabel(app.UIFigure);
            app.XLabel_2.Text = '%,   X';
            app.Edit_pos_width_5 = uieditfield(app.UIFigure, 'text');
            app.Edit_pos_width_5.Enable = 'off';
            app.Edit_pos_width_5.Value = '36';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '%';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EStudio_pos_gui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end