classdef dq_trial_rejection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ExporttrialinformationButton  matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        ERPNameLabel                  matlab.ui.control.Label
        CloseButton                   matlab.ui.control.Button
        Edit_pos_width_4              matlab.ui.control.EditField
        Button_2                      matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        TrialInformationLabel         matlab.ui.control.Label
        oftrialswererejectedLabel     matlab.ui.control.Label
        Edit_pos_width_3              matlab.ui.control.EditField
        asme_table1                   matlab.ui.control.Label
        UITable_2                     matlab.ui.control.Table
        UITable                       matlab.ui.control.Table
        EpochedEEGLabel               matlab.ui.control.Label
        Edit_pos_width_2              matlab.ui.control.EditField
        Edit_pos_width                matlab.ui.control.EditField
        OutputLabel                   matlab.ui.control.Label
        InputLabel                    matlab.ui.control.Label
    end


    properties (Access = public)
        Finishbutton % Description
        Output % Description
        EEGname_indx % Description
    end

    properties (Access = private)
        ALLEEG % Description
        ALLERP % Description
        ERPset % Description
    end

    methods (Access = private)

        function results = func_updated(app)
            ALLERP = app.ALLERP;
            ALLEEG = app.ALLEEG;
            if app.ERPset> length(ALLERP)
                app.ERPset=length(ALLERP);
            end
            ERP = ALLERP(app.ERPset);
            try EEG = ALLEEG(app.ERPset); catch  end
            for ii = 1:length(ERP.ntrials.accepted)
                try
                    data{ii,1} = [num2str(ii),'.',ERP.EVENTLIST.bdf(ii).description];
                    data{ii,2} =      [num2str(ERP.ntrials.accepted(ii)),32,'(',num2str(roundn(100*ERP.ntrials.accepted(ii)/(ERP.ntrials.accepted(ii)+ERP.ntrials.rejected(ii)+ERP.ntrials.invalid(ii)),-1)),32,'%)'];
                    data{ii,3} =     [num2str(ERP.ntrials.rejected(ii)),32,'(',num2str(roundn(100*ERP.ntrials.rejected(ii)/(ERP.ntrials.accepted(ii)+ERP.ntrials.rejected(ii)+ERP.ntrials.invalid(ii)),-1)),32,'%)'];
                    data{ii,4} =     [num2str(ERP.ntrials.invalid(ii)),32,'(',num2str(roundn(100*ERP.ntrials.invalid(ii)/(ERP.ntrials.accepted(ii)+ERP.ntrials.rejected(ii)+ERP.ntrials.invalid(ii)),-1)),32,'%)'];
                catch
                    data{ii,1} = '';
                    data{ii,2} =     '';
                    data{ii,3} =      '';
                    data{ii,4} =      '';
                end
            end
            app.UITable.Data = data;
            rejectionpec = sum(ERP.ntrials.rejected(:))/(sum(ERP.ntrials.accepted(:))+ sum(ERP.ntrials.rejected(:))+sum(ERP.ntrials.invalid(:)));
            app.Edit_pos_width_3.Value = num2str(roundn(100*rejectionpec,-2));

            try app.Edit_pos_width.Value = EEG.setname; catch app.Edit_pos_width.Value = '';end
            if app.EEGname_indx==0
                app.Edit_pos_width_2.Value = ERP.erpname;
            else
                app.Edit_pos_width_2.Value ='';
                app.Edit_pos_width.Value =ERP.erpname;
            end

            %%
            ERP_SME_summary = f_dq_summary(ERP);
            try Data1{1,1} = num2str(roundn(ERP_SME_summary{1,1},-2));catch Data1{1,1} = '';end
            try Data1{1,2} = ERP.chanlocs(ERP_SME_summary{2,1}).labels;catch Data1{1,2} = '';end
            try Median_tw =ERP_SME_summary{3,1};catch Median_tw = [0 0]; end
            try Data1{1,3} = [num2str(Median_tw(1)),'-',num2str(Median_tw(2)),32,'ms'];catch Data1{1,3} ='';  end
            try Data1{1,4} = ['Bin',num2str(ERP_SME_summary{4,1})]; catch  Data1{1,4} =''; end
            %%min
            try Data1{2,1} = num2str(roundn(ERP_SME_summary{1,2},-2));catch Data1{2,1} = '';end
            try Data1{2,2} = ERP.chanlocs(ERP_SME_summary{2,2}).labels;catch Data1{2,2} = '';end
            try Median_tw =ERP_SME_summary{3,2};catch Median_tw = [0 0]; end
            try Data1{2,3} = [num2str(Median_tw(1)),'-',num2str(Median_tw(2)),32,'ms'];catch Data1{2,3} ='';  end
            try Data1{2,4} = ['Bin',num2str(ERP_SME_summary{4,2})]; catch  Data1{2,4} =''; end

            %%min
            try Data1{3,1} = num2str(roundn(ERP_SME_summary{1,3},-2));catch Data1{3,1} = '';end
            try Data1{3,2} = ERP.chanlocs(ERP_SME_summary{2,3}).labels;catch Data1{3,2} = '';end
            try Median_tw =ERP_SME_summary{3,3};catch Median_tw = [0 0]; end
            try Data1{3,3} = [num2str(Median_tw(1)),'-',num2str(Median_tw(2)),32,'ms'];catch Data1{3,3} ='';  end
            try Data1{3,4} = ['Bin',num2str(ERP_SME_summary{4,3})]; catch  Data1{3,4} =''; end
            app.UITable_2.Data = Data1;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            painterplabapp(app);
            setfonterplab(app);
            % Name & version
            %
            app.Edit_pos_width_3.BackgroundColor = [1 1 1];
            version = geterplabversion;
            set(app.UIFigure,'Name', ['Estudio ' version '   - trial information and SME GUI']);
            try
                app.ALLERP = varargin{1};
                app.ALLEEG = varargin{2};
            catch
                app.ALLERP = [];
                app.ALLEEG = [];
            end
            try
                app.EEGname_indx =varargin{3};
            catch
                app.EEGname_indx =0;
            end

            if isempty(app.ALLERP)
                app.Edit_pos_width_2.Value = 'Please input validate ERPsets';
                return;
            end
            %             if isempty(app.ALLEEG)
            %                 app.Edit_pos_width.Value = 'Please input validate EEGsets';
            %             end
            app.Edit_pos_width_2.BackgroundColor = [1 1 1];
            app.Edit_pos_width.BackgroundColor = [1 1 1];
            %app.Edit_pos_width.FontColor = [1 0 0];
            if  ~isempty(app.ALLERP)
                app.Edit_pos_width_4.Value='1';
                app.ERPset=1;
                func_updated(app);
            end
            app.Finishbutton = 0;
            app.Output = [];
        end

        % Value changed function: Edit_pos_width
        function eeg_name(app, event)


        end

        % Value changed function: Edit_pos_width_2
        function erp_name(app, event)


        end

        % Display data changed function: UITable
        function trial_rejection_table(app, event)
            newDisplayData = app.UITable.DisplayData;
        end

        % Display data changed function: UITable_2
        function asme_table(app, event)
            newDisplayData = app.UITable_2.DisplayData;
        end

        % Value changed function: Edit_pos_width_3
        function rejected_trials(app, event)

        end

        % Button pushed function: Button
        function pre_erpset(app, event)

            ERPset = str2num(app.Edit_pos_width_4.Value);
            ERPset = ERPset-1;
            if isempty(ERPset)|| any(ERPset<=0) || numel(ERPset)~=1 || any(ERPset>length(app.ALLERP))
                ERPset=1;
                msgboxText = ['The ERPset should be between 1 and ',32,num2str(length(app.ALLERP))];
                app.TextArea.Value = msgboxText;
            end
            app.ERPset =ERPset;
            app.Edit_pos_width_4.Value =num2str(ERPset);
            func_updated(app);
        end

        % Button pushed function: Button_2
        function next_erpset(app, event)
            ERPset = str2num(app.Edit_pos_width_4.Value);
            ERPset = ERPset+1;
            if isempty(ERPset)|| any(ERPset<=0) || numel(ERPset)~=1 || any(ERPset>length(app.ALLERP))
                ERPset=length(app.ALLERP);
                msgboxText = ['The ERPset should be between 1 and ',32,num2str(length(app.ALLERP))];
                app.TextArea.Value = msgboxText;
            end
            app.ERPset =ERPset;
            app.Edit_pos_width_4.Value =num2str(ERPset);
            func_updated(app);
        end

        % Value changed function: Edit_pos_width_4
        function erpset_edit(app, event)
            ERPset = str2num(app.Edit_pos_width_4.Value);
            ERPset = ERPset+1;
            if isempty(ERPset)|| any(ERPset<=0) || numel(ERPset)~=1 || any(ERPset>length(app.ALLERP))
                ERPset=1;
                msgboxText = ['The ERPset should be between 1 and ',32,num2str(length(app.ALLERP))];
                app.TextArea.Value = msgboxText;
            end
            app.ERPset =ERPset;
            app.Edit_pos_width_4.Value =num2str(ERPset);
            func_updated(app);
        end

        % Button pushed function: CloseButton
        function exit(app, event)
            app.Finishbutton = 1;
            app.Output = [];
            app.delete;
        end

        % Value changed function: TextArea
        function error_message(app, event)
            value = app.TextArea.Value;

        end

        % Button pushed function: ExporttrialinformationButton
        function export_reject(app, event)
            pathstr =  estudioworkingmemory('EEG_save_folder');
            if isempty(pathstr)
                pathstr =cd;
            end
            namedef ='Trial_information';
            [erpfilename, erppathname, indxs] = uiputfile({'*.txt'}, ...
                ['Export EEG trial informatio'],...
                fullfile(pathstr,namedef));
            if isequal(erpfilename,0)
                return
            end

            [pathstr, erpfilename, ext] = fileparts(erpfilename) ;
            ext = '.txt';
            ALLERP = app.ALLERP;
            erpFilename = char(strcat(erppathname,erpfilename,ext));
            fileID = fopen(erpFilename,'w+');
            for Numoferp = 1:length(ALLERP)
                ERP = ALLERP(Numoferp);
                %%
                formatSpec1 =['%s\n\n\n'];
                columName1{1,1} = ['ERP name:',32,ERP.erpname];
                fprintf(fileID,formatSpec1,columName1{1,:});

                %%Table title
                formatSpec2 =['%s\t',32,'%s\t',32,'%s\t',32,'%s\n'];
                columName2 = {'Bin', 'Included trials', 'Rejected trials', 'Invalid trials'};
                fprintf(fileID,formatSpec2,columName2{1,:});
                for ii = 1:length(ERP.ntrials.accepted)
                    try
                        %                         data{1,1} = [num2str(ii),'.',ERP.EVENTLIST.bdf(ii).description];
                        %                         data{1,2} =      [num2str(ERP.ntrials.accepted(ii)),32,'(',num2str(roundn(100*ERP.ntrials.accepted(ii)/(ERP.ntrials.accepted(ii)+ERP.ntrials.rejected(ii)+ERP.ntrials.invalid(ii)),-1)),32,'%)'];
                        %                         data{1,3} =     [num2str(ERP.ntrials.rejected(ii)),32,'(',num2str(roundn(100*ERP.ntrials.rejected(ii)/(ERP.ntrials.accepted(ii)+ERP.ntrials.rejected(ii)+ERP.ntrials.invalid(ii)),-1)),32,'%)'];
                        %                         data{1,4} =     [num2str(ERP.ntrials.invalid(ii)),32,'(',num2str(roundn(100*ERP.ntrials.invalid(ii)/(ERP.ntrials.accepted(ii)+ERP.ntrials.rejected(ii)+ERP.ntrials.invalid(ii)),-1)),32,'%)'];
                        %
                        data{1,1} = [num2str(ii),'.',ERP.EVENTLIST.bdf(ii).description];
                        data{1,2} =      [num2str(ERP.ntrials.accepted(ii)),];
                        data{1,3} =     [num2str(ERP.ntrials.rejected(ii))];
                        data{1,4} =     [num2str(ERP.ntrials.invalid(ii))];

                    catch
                        data{1,1} = '';
                        data{1,2} =     '';
                        data{1,3} =      '';
                        data{1,4} =      '';
                    end
                    fprintf(fileID,formatSpec2,data{1,:});
                end

                if Numoferp~=length(ALLERP)
                    data1{1,1} = '';
                    data1{1,2} =     '';
                    data1{1,3} =      '';
                    data1{1,4} =      '';
                    formatSpec3 =['%s\t',32,'%s\t',32,'%s\t',32,'%s\n\n\n\n\n\n'];
                    fprintf(fileID,formatSpec3,data{1,:});
                end
            end%%end of erpset
            fclose(fileID);
            disp(['A new file for trial information was created at <a href="matlab: open(''' erpFilename ''')">' erpFilename '</a>'])
            msgboxText = ['It is done for exporting trial information'];
            app.TextArea.Value = msgboxText;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = 'Input:';
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output:';
            app.Edit_pos_width = uieditfield(app.UIFigure, 'text');
            app.Edit_pos_width.ValueChangedFcn = createCallbackFcn(app, @eeg_name, true);
            app.Edit_pos_width.Editable = 'off';
            app.Edit_pos_width_2 = uieditfield(app.UIFigure, 'text');
            app.Edit_pos_width_2.ValueChangedFcn = createCallbackFcn(app, @erp_name, true);
            app.Edit_pos_width_2.Editable = 'off';
            app.EpochedEEGLabel = uilabel(app.UIFigure);
            app.EpochedEEGLabel.Text = 'Epoched EEG';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Bin'; 'Included trials'; 'Rejected trials'; 'Invalid trials'};
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @trial_rejection_table, true);
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'aSME'; 'Channel'; 'Time window'; 'Bin'};
            app.UITable_2.RowName = {'Median'; 'Min'; 'Max'};
            app.UITable_2.DisplayDataChangedFcn = createCallbackFcn(app, @asme_table, true);
            app.asme_table1 = uilabel(app.UIFigure);
            app.asme_table1.Text = 'Analytic Standardized Measurement Error (aSME)';
            app.Edit_pos_width_3 = uieditfield(app.UIFigure, 'text');
            app.Edit_pos_width_3.ValueChangedFcn = createCallbackFcn(app, @rejected_trials, true);
            app.Edit_pos_width_3.Editable = 'off';
            app.oftrialswererejectedLabel = uilabel(app.UIFigure);
            app.oftrialswererejectedLabel.Text = '% of  trials were rejected';
            app.TrialInformationLabel = uilabel(app.UIFigure);
            app.TrialInformationLabel.Text = 'Trial Information';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @pre_erpset, true);
            app.Button.Text = '<';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @next_erpset, true);
            app.Button_2.Text = '>';
            app.Edit_pos_width_4 = uieditfield(app.UIFigure, 'text');
            app.Edit_pos_width_4.ValueChangedFcn = createCallbackFcn(app, @erpset_edit, true);
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @exit, true);
            app.CloseButton.Text = 'Close';
            app.ERPNameLabel = uilabel(app.UIFigure);
            app.ERPNameLabel.Text = 'ERP Name';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @error_message, true);
            app.ExporttrialinformationButton = uibutton(app.UIFigure, 'push');
            app.ExporttrialinformationButton.ButtonPushedFcn = createCallbackFcn(app, @export_reject, true);
            app.ExporttrialinformationButton.Text = 'Export trial information';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dq_trial_rejection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end