classdef eeg2best_freqops < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        HighEdgeFrequencyinPassBandLabel  matlab.ui.control.Label
        Edit_pos_height  matlab.ui.control.EditField
        LowEdgeFrequencyinPassBandLabel  matlab.ui.control.Label
        OkayButton       matlab.ui.control.Button
        CancelButton     matlab.ui.control.Button
        TransformtrialsintophaseindependentfrequencybandEEGpowerLabel  matlab.ui.control.Label
        Edit_pos_width   matlab.ui.control.EditField
    end


    properties (Access = public)
        output % Description
        Finishbutton % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            painterplabapp(app);
            setfonterplab(app);
            % Name & version
            %
            version = geterplabversion;
            set(app.UIFigure,'Name', ['ERPLAB ' version '   -   Frequency Transform for Extract BESTSET GUI']);
            try
                freqband = varargin{1};
            catch
                freqband = {'',''};
            end
            freqband = cell2mat(freqband);
            
            try
                app.Edit_pos_width.Value = num2str(freqband(1));
            catch 
              app.Edit_pos_width.Value  = '';  
            end
            try
                app.Edit_pos_height.Value = num2str(freqband(2));
            catch
               app.Edit_pos_height.Value = ''; 
            end
            
        end

        % Value changed function: Edit_pos_width
        function Postion_edit_width(app, event)
            value = app.Edit_pos_width.Value;
            low_bp = str2num(value);
            if isempty(low_bp)
                msgboxText = 'Cannot leave Low Edge field empty';
                title_msg  = 'Estudio: eeg2best_freqops() error:';
                errorfound(msgboxText, title_msg);
                return
            end
        end

        % Button pushed function: CancelButton
        function Cancel(app, event)
            app.output ={'',''};
            app.Finishbutton = 1;
        end

        % Button pushed function: OkayButton
        function Okay(app, event)

            high_bp = str2num(app.Edit_pos_height.Value);
            low_bp = str2num(app.Edit_pos_width.Value);
            if isempty(low_bp)
                msgboxText = 'Cannot leave Low Edge Frequency field empty';
                title_msg  = 'Estudio: eeg2best_freqops() error:';
                errorfound(msgboxText, title_msg);
                return
            end

            if isempty(high_bp)
                msgboxText = 'Cannot leave High Edge Frequency field empty';
                title_msg  = 'Estudio: eeg2best_freqops() error:';
                errorfound(msgboxText, title_msg);
                return
            end
            
            bp{1} = low_bp;  bp{2} = high_bp;
            app.output =bp;
            app.Finishbutton = 1;
        end

        % Value changed function: Edit_pos_height
        function Postion_edit_height(app, event)
            value = app.Edit_pos_height.Value;
            high_bp = str2num(value);
            if isempty(high_bp)
                msgboxText = 'Cannot leave High Edge field empty';
                title_msg  = 'ERPLAB: eeg2best_freqops() error:';
                errorfound(msgboxText, title_msg);
                return
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Edit_pos_width = uieditfield(app.UIFigure, 'text');
            app.Edit_pos_width.ValueChangedFcn = createCallbackFcn(app, @Postion_edit_width, true);
            app.TransformtrialsintophaseindependentfrequencybandEEGpowerLabel = uilabel(app.UIFigure);
            app.TransformtrialsintophaseindependentfrequencybandEEGpowerLabel.Text = 'Transform trials into phase-independent frequency-band EEG power';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @Cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.OkayButton = uibutton(app.UIFigure, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @Okay, true);
            app.OkayButton.Text = 'Okay';
            app.LowEdgeFrequencyinPassBandLabel = uilabel(app.UIFigure);
            app.LowEdgeFrequencyinPassBandLabel.Text = ' Low Edge Frequency in Pass Band';
            app.Edit_pos_height = uieditfield(app.UIFigure, 'text');
            app.Edit_pos_height.ValueChangedFcn = createCallbackFcn(app, @Postion_edit_height, true);
            app.HighEdgeFrequencyinPassBandLabel = uilabel(app.UIFigure);
            app.HighEdgeFrequencyinPassBandLabel.Text = 'High Edge Frequency in Pass Band';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eeg2best_freqops(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end