classdef estudio_classic_ar_summary_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        ButtonGroup   matlab.ui.container.ButtonGroup
        SummarizeEEGartifactinagraphicButton  matlab.ui.control.RadioButton
        SummarizeEEGartifactinatableButton  matlab.ui.control.RadioButton
        SummarizeEEGartifactinonevalueCommandwindowButton  matlab.ui.control.RadioButton
        OkayButton    matlab.ui.control.Button
        CancelButton  matlab.ui.control.Button
    end


    properties (Access = public)
        output % Description
        Finishbutton % Description
    end

    methods (Access = private)


    end

    methods (Access = public)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            painterplabapp(app);
            setfonterplab(app);
            % Name & version
            %
            version = geterplabversion;
            set(app.UIFigure,'Name', ['EStudio ' version '   -  Classic Artifact Summary GUI']);
            try
                resetparas = varargin{1};
            catch
                resetparas = [1 0 0];
            end
            if isempty(resetparas) || numel(resetparas)~=3 || any(resetparas(:)>1) || any(resetparas(:)<0)
                resetparas = [1 0 0];
            end
            app.SummarizeEEGartifactinagraphicButton.Value = resetparas(3);
            app.SummarizeEEGartifactinatableButton.Value = resetparas(2);
            app.SummarizeEEGartifactinonevalueCommandwindowButton.Value = resetparas(1);

            app.output =[];
            app.Finishbutton = 0;
        end

        % Button pushed function: CancelButton
        function cancel_button(app, event)
            app.output =[];
            app.Finishbutton = 1;
        end

        % Button pushed function: OkayButton
        function button_continue(app, event)
            resetparas(3)  = app.SummarizeEEGartifactinagraphicButton.Value;
            resetparas(2)=  app.SummarizeEEGartifactinatableButton.Value;
            resetparas(1)= app.SummarizeEEGartifactinonevalueCommandwindowButton.Value;
            
            app.output =resetparas;
            app.Finishbutton = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel_button, true);
            app.CancelButton.Text = 'Cancel';
            app.OkayButton = uibutton(app.UIFigure, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @button_continue, true);
            app.OkayButton.Text = 'Okay';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.SummarizeEEGartifactinonevalueCommandwindowButton = uiradiobutton(app.ButtonGroup);
            app.SummarizeEEGartifactinonevalueCommandwindowButton.Text = 'Summarize EEG artifact in one value (Command window)';
            app.SummarizeEEGartifactinonevalueCommandwindowButton.Value = true;
            app.SummarizeEEGartifactinatableButton = uiradiobutton(app.ButtonGroup);
            app.SummarizeEEGartifactinatableButton.Text = 'Summarize EEG artifact in a table';
            app.SummarizeEEGartifactinagraphicButton = uiradiobutton(app.ButtonGroup);
            app.SummarizeEEGartifactinagraphicButton.Text = 'Summarize EEG artifact in a graphic';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = estudio_classic_ar_summary_gui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end